classdef ALab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ALabUIFigure                   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        CenterPanel                    matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        LoadprojectMenu                matlab.ui.container.Menu
        SaveprojectMenu                matlab.ui.container.Menu
        MeasurementMenu                matlab.ui.container.Menu
        GeneralMenu                    matlab.ui.container.Menu
        TubemeasurementsMenu           matlab.ui.container.Menu
        ImpedanceTubeISO105342Menu     matlab.ui.container.Menu
        TransmissionTubeASTME2611Menu  matlab.ui.container.Menu
        UniversitatPolitcnicadeValnciaMenu  matlab.ui.container.Menu
        AnechoicchamberXYZRobotMenu    matlab.ui.container.Menu
        AnechoicchamberXYZRobotPostprocessingMenu  matlab.ui.container.Menu
        ConfigurationMenu              matlab.ui.container.Menu
        PreferencesandcalibrationMenu  matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        %
        
    end
    
    properties (Access = public)
        % App version
        Version
        SystemInfo
        
        % PAth folder
        RootPath
        
        % External UI
        ExtUI
        
        % External Variables
        ExtVar
        
        % Measurement
        MeasureType
        
        % Hardware
        SampleRate
        SamplesPerFrame
        Driver
        Device
        BitDepth
        
        % Air Properties
        c
        rho
        
        % Calibrate Sensitivity
        Sensitivity
        SensitivityIsMeasured
        
        % Calibrate Latency
        Latency
        LatencyIsMeasured
        
        % Signal
        TypeSignal
        OutSignal % Emitted
        InputSignal % Recorded
        SignalParameter % Struct with signals and its parameters
        Averages % Number of measures for average
        
    end
    
    
    methods (Access = private)
        
        function myUpdateAppLayout(app, ~)
            
            % Between original width and new panel width
            app.onePanelWidth = 650; % Like Mobile if width < onePanelWidth
            app.twoPanelWidth = 1080; % Like Tablet if twoPanelWidth > width > onePanelWidth
            
            currentFigureWidth = app.ALabUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {720, 720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {330, '1x', 330};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
        
        function initializeStructs(app)
            
            % UI External
            app.ExtUI = struct();
            % External Vars
            app.ExtVar = struct();
            % Signals
            app.SignalParameter = struct();
            
            % Default variables
            devs = getAvailableDevices();
            app.Device = devs(1);
            app.SampleRate = 48000;
            app.SamplesPerFrame = 2048;
            app.BitDepth = '24-bit integer';
            drivers = getAvailableDrivers();
            app.Driver = drivers(1);
            app.MeasureType = [];
            app.Sensitivity = [];
            app.Latency = app.SamplesPerFrame/app.SampleRate*2;
            app.LatencyIsMeasured = 0;
            app.TypeSignal = [];
            app.OutSignal = [];
            app.InputSignal = [];
            app.SignalParameter = [];

            app.c = 343;
            app.rho = 1.2;
            
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Copyright (C) Jose M. Requena Plens
            % joreple@upv.es - jmrplens.github.io

            d = uiprogressdlg(app.ALabUIFigure,'Title','A|Lab',...
                'Message','Opening','Indeterminate','on');

            % Add include
            app.RootPath = fileparts(mfilename('fullpath'));
            addpath(genpath([app.RootPath,filesep,'include']))

            % System information
            app.Version = 0.5; % App version
            app.SystemInfo = struct('General',evalc('ver'),'ToolBoxTable',ver);
            
            % Figure name
            app.ALabUIFigure.Name = sprintf('A|Lab %.2f',app.Version);
            
            % Initialize Struct properties and variables
            initializeStructs(app)
            
            % Update Auto-Reflow
            app.ALabUIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            
            % Set columns
            column = rightGrid(app,{'1x'},{'fit', 'fit', '1x', 'fit'});
            leftGrid(app,{'1x'},{'fit'});
            
            % Menu Bar Style
            menus = findobj(app.ALabUIFigure,'type','uimenu');
            for ii = 1:numel(menus)
                menus(ii).Separator = 1; % Add separator between elements
            end

            % Set default audio hardware
            try
                aPR = audioPlayerRecorder('SampleRate',app.SampleRate,...
                    'BitDepth',app.BitDepth,...
                    'SupportVariableSize',true,...
                    'BufferSize',app.SamplesPerFrame);
                setup(aPR,0);
                release(aPR);
                audiodevreset;
                [Nin,~] = getAvailableChannels(app);
                app.Sensitivity = zeros(Nin,1);
                app.SensitivityIsMeasured = false(Nin,1);
            catch
                close(d)
                msg = {'No full-duplex audio device detected';
                    ' ';
                    'Check that the audio device has been recognized by the operating system.';
                    ' ';
                    'When you connect the audio device, restart the application so that it can be recognized by A|Lab.';
                    '';
                    '-----------------------------------------------------------------------';
                    '* If you are not going to perform a measurement you can ignore this message.'};
                uialert(app.ALabUIFigure,msg,'Audio error');
                app.Device = 'No detected';
                app.SampleRate = 0;
                app.SamplesPerFrame = 0;
                app.BitDepth = '-';
                app.Latency = 0;
            end
            % Information panel
            Row = 4; Col = 1;
            createPanelInformation(app,column,Row,Col);
            drawnow
            pause(1)
            if exist("d","var")
                close(d)
            end
            
        end

        % Menu selected function: PreferencesandcalibrationMenu
        function PreferencesandcalibrationMenuSelected(app, event)
            % Loading info
            d = uiprogressdlg(app.ALabUIFigure,'Title','Loading...',...
                'Indeterminate','on');
            createPanelsDeviceSetup(app)
            drawnow
            pause(1)
            close(d)
        end

        % Menu selected function: ImpedanceTubeISO105342Menu
        function ImpedanceTubeISO105342MenuSelected(app, event)
            % Loading info
            d = uiprogressdlg(app.ALabUIFigure,'Title','Loading...',...
                'Indeterminate','on');
            buildUITwoMicISO105342(app)
            drawnow
            pause(1)
            close(d)
        end

        % Menu selected function: SaveprojectMenu
        function SaveprojectMenuSelected(app, event)
            saveProject(app);
        end

        % Menu selected function: LoadprojectMenu
        function LoadprojectMenuSelected(app, event)
            loadProject(app);
        end

        % Menu selected function: AnechoicchamberXYZRobotMenu
        function AnechoicchamberXYZRobotMenuSelected(app, event)
            % Loading info
            d = uiprogressdlg(app.ALabUIFigure,'Title','Loading...',...
                'Indeterminate','on');
            buildUPVRobot(app)
            drawnow
            pause(1)
            close(d)
        end

        % Menu selected function: GeneralMenu
        function GeneralMenuSelected(app, event)
            % Loading info
            d = uiprogressdlg(app.ALabUIFigure,'Title','Loading...',...
                'Indeterminate','on');
            buildUIAeroacoustics(app)
            drawnow
            pause(1)
            close(d)
        end

        % Callback function
        function ISO105342modifiedTwomicrophoneMenuSelected(app, event)
            % Loading info
            d = uiprogressdlg(app.ALabUIFigure,'Title','Loading...',...
                'Indeterminate','on');
            buildUIAnechoicISO105342(app)
            drawnow
            pause(1)
            close(d)
        end

        % Menu selected function: TransmissionTubeASTME2611Menu
        function TransmissionTubeASTME2611MenuSelected(app, event)
            % Loading info
            d = uiprogressdlg(app.ALabUIFigure,'Title','Loading...',...
                'Indeterminate','on');
            buildUIFourMicASTM2611(app)
            drawnow
            pause(1)
            close(d)
        end

        % Menu selected function: 
        % AnechoicchamberXYZRobotPostprocessingMenu
        function AnechoicchamberXYZRobotPostprocessingMenuSelected(app, event)
            % Loading info
            d = uiprogressdlg(app.ALabUIFigure,'Title','Loading...',...
                'Indeterminate','on');
            buildUPVRobotPostProcess(app)
            drawnow
            pause(1)
            close(d)
        end

        % Close request function: ALabUIFigure
        function ALabUIFigureCloseRequest(app, event)
            % Check if parallel pools exist
            p = gcp('nocreate');
            if ~isempty(p)
                delete(p)
            end
            % delete app
            delete(app)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
             msg = {[app.ALabUIFigure.Name,' (2021) - GPLv3'];
                    ' ';
                    'This software has been developed by Jose M. Requena Plens.';
                    ' ';
                    'Contact:';
                    'joreple@upv.es - jmrplens.github.io';};
                uialert(app.ALabUIFigure,msg,'About','Icon','info');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ALabUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {720, 720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {328, '1x', 330};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ALabUIFigure = uifigure('Visible', 'off');
            app.ALabUIFigure.Name = 'A|Lab';
            app.ALabUIFigure.CloseRequestFcn = createCallbackFcn(app, @ALabUIFigureCloseRequest, true);
            app.ALabUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ALabUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.FileMenu = uimenu(app.ALabUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.LoadprojectMenu = uimenu(app.FileMenu);
            app.LoadprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadprojectMenuSelected, true);
            app.LoadprojectMenu.Text = 'Load project...';
            app.SaveprojectMenu = uimenu(app.FileMenu);
            app.SaveprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveprojectMenuSelected, true);
            app.SaveprojectMenu.Text = 'Save project...';
            app.MeasurementMenu = uimenu(app.ALabUIFigure);
            app.MeasurementMenu.Text = 'Measurement';
            app.GeneralMenu = uimenu(app.MeasurementMenu);
            app.GeneralMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneralMenuSelected, true);
            app.GeneralMenu.Text = 'General';
            app.TubemeasurementsMenu = uimenu(app.MeasurementMenu);
            app.TubemeasurementsMenu.Text = 'Tube measurements';
            app.ImpedanceTubeISO105342Menu = uimenu(app.TubemeasurementsMenu);
            app.ImpedanceTubeISO105342Menu.MenuSelectedFcn = createCallbackFcn(app, @ImpedanceTubeISO105342MenuSelected, true);
            app.ImpedanceTubeISO105342Menu.Text = 'Impedance Tube (ISO 10534-2)';
            app.TransmissionTubeASTME2611Menu = uimenu(app.TubemeasurementsMenu);
            app.TransmissionTubeASTME2611Menu.MenuSelectedFcn = createCallbackFcn(app, @TransmissionTubeASTME2611MenuSelected, true);
            app.TransmissionTubeASTME2611Menu.Text = 'Transmission Tube (ASTM E2611)';
            app.UniversitatPolitcnicadeValnciaMenu = uimenu(app.MeasurementMenu);
            app.UniversitatPolitcnicadeValnciaMenu.Text = 'Universitat Politècnica de València';
            app.AnechoicchamberXYZRobotMenu = uimenu(app.UniversitatPolitcnicadeValnciaMenu);
            app.AnechoicchamberXYZRobotMenu.MenuSelectedFcn = createCallbackFcn(app, @AnechoicchamberXYZRobotMenuSelected, true);
            app.AnechoicchamberXYZRobotMenu.Text = 'Anechoic chamber - (X,Y,Z) Robot';
            app.AnechoicchamberXYZRobotPostprocessingMenu = uimenu(app.UniversitatPolitcnicadeValnciaMenu);
            app.AnechoicchamberXYZRobotPostprocessingMenu.MenuSelectedFcn = createCallbackFcn(app, @AnechoicchamberXYZRobotPostprocessingMenuSelected, true);
            app.AnechoicchamberXYZRobotPostprocessingMenu.Text = 'Anechoic chamber - (X,Y,Z) Robot - Postprocessing';
            app.ConfigurationMenu = uimenu(app.ALabUIFigure);
            app.ConfigurationMenu.Text = 'Configuration';
            app.PreferencesandcalibrationMenu = uimenu(app.ConfigurationMenu);
            app.PreferencesandcalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesandcalibrationMenuSelected, true);
            app.PreferencesandcalibrationMenu.Text = 'Preferences and calibration';
            app.HelpMenu = uimenu(app.ALabUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            % Show the figure after all components are created
            app.ALabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ALab

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ALabUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ALabUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ALabUIFigure)
        end
    end
end