classdef signalpanelcopia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLeftMain                   matlab.ui.container.GridLayout
        AeroacousticsLeftPanel         matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        FrequencyResponsePanel         matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        WindowDropDownLabel            matlab.ui.control.Label
        WindowDropDown                 matlab.ui.control.DropDown
        NumberofDFTpointsEditFieldLabel  matlab.ui.control.Label
        NumberofDFTpointsEditField     matlab.ui.control.NumericEditField
        WindowsizeEditFieldLabel       matlab.ui.control.Label
        WindowsizeEditField            matlab.ui.control.NumericEditField
        WindowOverlapEditFieldLabel    matlab.ui.control.Label
        WindowOverlapEditField         matlab.ui.control.NumericEditField
        SoundPressureLevelPanel        matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        BandwidthDropDownLabel         matlab.ui.control.Label
        BandwidthDropDown              matlab.ui.control.DropDown
        OctaveFilterOrderSpinnerLabel  matlab.ui.control.Label
        OctaveFilterOrderSpinner       matlab.ui.control.Spinner
        FrequencyWeightingDropDownLabel  matlab.ui.control.Label
        FrequencyWeightingDropDown     matlab.ui.control.DropDown
        TimeWeightingDropDownLabel     matlab.ui.control.Label
        TimeWeightingDropDown          matlab.ui.control.DropDown
        TimeIntervalsEditFieldLabel    matlab.ui.control.Label
        TimeIntervalsEditField         matlab.ui.control.NumericEditField
        FrequencyRangetoevaluateLabel  matlab.ui.control.Label
        MinfrequencyHzEditFieldLabel   matlab.ui.control.Label
        MinfrequencyHzEditField        matlab.ui.control.NumericEditField
        MaxfrequencyHzEditFieldLabel   matlab.ui.control.Label
        MaxfrequencyHzEditField        matlab.ui.control.NumericEditField
        ChannelsPanel                  matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        OutputChannelDropDownLabel     matlab.ui.control.Label
        OutputChannelDropDown          matlab.ui.control.DropDown
        InputChannelsLabel             matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        CenterPanel                    matlab.ui.container.Panel
        GridCenterMain                 matlab.ui.container.GridLayout
        AeroacousticsImpulseResponsePanel  matlab.ui.container.Panel
        GridAeroacousticsImpulseResponse  matlab.ui.container.GridLayout
        AeroacousticsImpulseResponseAxes  matlab.ui.control.UIAxes
        AeroacousticsFrequencyResponsePanel  matlab.ui.container.Panel
        GridAeroacousticsFrequencyResponsePanel  matlab.ui.container.GridLayout
        AeroacousticsFrequencyResponseAxes  matlab.ui.control.UIAxes
        AeroacousticsSoundPressureLevelPanel  matlab.ui.container.Panel
        GridAeroacousticsSoundPressureLevelPanel  matlab.ui.container.GridLayout
        AeroacousticsSoundPressureLevelAxes  matlab.ui.control.UIAxes
        GridAeroacousticsButtons       matlab.ui.container.GridLayout
        AeroacousticsExportmeasuretxtButton  matlab.ui.control.Button
        AeroacousticsExportgraphicsButton  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridRightMain                  matlab.ui.container.GridLayout
        SignalparametersPanel          matlab.ui.container.Panel
        GridSignalParameters           matlab.ui.container.GridLayout
        GridSignalType                 matlab.ui.container.GridLayout
        SignaltypeDropDownLabel        matlab.ui.control.Label
        SignaltypeDropDown             matlab.ui.control.DropDown
        GridLayout7                    matlab.ui.container.GridLayout
        TonePanel                      matlab.ui.container.Panel
        GridTone                       matlab.ui.container.GridLayout
        DurationsLabel                 matlab.ui.control.Label
        ToneDurationEditField          matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        ToneFrequencyHzEditField       matlab.ui.control.NumericEditField
        PanelMeasure                   matlab.ui.container.Panel
        GridMeasure                    matlab.ui.container.GridLayout
        StartmeasureButton             matlab.ui.control.Button
        InformationPanel               matlab.ui.container.Panel
        GridInformation                matlab.ui.container.GridLayout
        InfoDeviceLabel                matlab.ui.control.Label
        InfoSampleRateLabel            matlab.ui.control.Label
        InfoSamplesPerFrameLabel       matlab.ui.control.Label
        InfoInputsCalibratedLabel      matlab.ui.control.Label
        InfoLatencyLabel               matlab.ui.control.Label
        InfoDevice                     matlab.ui.control.Label
        InfoSampleRate                 matlab.ui.control.Label
        InfoSamplesPerFrame            matlab.ui.control.Label
        InfoInputsCalibrated           matlab.ui.control.Label
        InfoLatency                    matlab.ui.control.Label
        InfoEmptyLabel1                matlab.ui.control.Label
        MeasureTypeGrid                matlab.ui.container.GridLayout
        MeasurementtypePanel           matlab.ui.container.Panel
        GridMeasurementtypePanel       matlab.ui.container.GridLayout
        ContinuousLabel                matlab.ui.control.Label
        ContinuousCheckBox             matlab.ui.control.CheckBox
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        LoadprojectMenu                matlab.ui.container.Menu
        SaveprojectMenu                matlab.ui.container.Menu
        MeasurementMenu                matlab.ui.container.Menu
        IndividualchannelsgeneralMenu  matlab.ui.container.Menu
        ImpedancetubeMenu              matlab.ui.container.Menu
        TwomicrophoneISO105342Menu     matlab.ui.container.Menu
        OnemicrophoneISO105342Menu     matlab.ui.container.Menu
        FixedlocationsMenu             matlab.ui.container.Menu
        VariablelocationsMenu          matlab.ui.container.Menu
        FourmicrophoneASTME26119Menu   matlab.ui.container.Menu
        AnechoicchamberMenu            matlab.ui.container.Menu
        ReflectionabsorptionandimpedanceISO105342modifiedMenu  matlab.ui.container.Menu
        DeviceMenu                     matlab.ui.container.Menu
        HardwareinputoutputMenu        matlab.ui.container.Menu
        CalibrateinputsMenu            matlab.ui.container.Menu
        CalibratedevicelatencyMenu     matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        % Hardware Panel Objects
        PanelHardware                   matlab.ui.container.Panel
        GridHardware                    matlab.ui.container.GridLayout
        HardwareGeneralPanel            matlab.ui.container.Panel
        GridHardwareGeneral             matlab.ui.container.GridLayout
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        SamplesperframeEditFieldLabel   matlab.ui.control.Label
        SamplesperframeEditField        matlab.ui.control.NumericEditField
        HardwareDevicePanel             matlab.ui.container.Panel
        GridHardwareDevice              matlab.ui.container.GridLayout
        DriverDropDownLabel             matlab.ui.control.Label
        DeviceDriverDropDown            matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        BitdepthDropDownLabel           matlab.ui.control.Label
        BitdepthDevice                  matlab.ui.control.DropDown
        HardwareOutputPanel             matlab.ui.container.Panel
        GridHardwareOutput              matlab.ui.container.GridLayout
        DriveOutputLabel                matlab.ui.control.Label
        DriverOutput                    matlab.ui.control.DropDown
        DeviceOutputLabel               matlab.ui.control.Label
        DeviceOutput                    matlab.ui.control.DropDown
        BitdepthOutputLabel             matlab.ui.control.Label
        BitdepthOutput                  matlab.ui.control.DropDown
        RefreshavailabledevicesButton   matlab.ui.control.Button
        
    end
    
    properties (Access = public)
        % Hardware
        SampleRate
        SamplesPerFrame
        Driver
        Device
        BitDepth
        
        % Calibrate sensibility
        Sensibility
        
        % Calibrate Latency
        Latency
        
        
        % Signal 
        TypeSignal
        OutSignal
        InputSignal
        % (each signal variable is a structure with its parameters)
        Sweep
        MLS
        WhiteNoise
        PinkNoise
        Tone
        
    end
    
    methods (Access = private)
        
        function myUpdateAppLayout(app, ~)
            
            % Between original width and new panel width
            app.onePanelWidth = 576; % Like Mobile if width < onePanelWidth
            app.twoPanelWidth = 1080; % Like Tablet if twoPanelWidth > width > onePanelWidth
            
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid (Like mobile)
                app.GridLayout.RowHeight = {720, 720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid (Like tablet)
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid (normal)
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {330, '1x', 330};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
        
        function createPanelHardware(app)
            
            % Main panel
            app.PanelHardware = uipanel(app.GridLeftMain);
            app.PanelHardware.TitlePosition = 'centertop';
            app.PanelHardware.Title = 'Hardware configuration';
            app.PanelHardware.Layout.Row = 1;
            app.PanelHardware.Layout.Column = 1;
            app.PanelHardware.BorderType = 'none';
            app.PanelHardware.FontWeight = 'bold';
            app.PanelHardware.FontSize = 14;
            app.PanelHardware.BackgroundColor = [1 1 1];
            
            % Create GridHardware
            app.GridHardware = uigridlayout(app.PanelHardware);
            app.GridHardware.ColumnWidth = {'1x'};
            app.GridHardware.RowHeight = {'fit', 'fit'};
            app.GridHardware.BackgroundColor = [1 1 1];
            
            % Create HardwareGeneralPanel
            app.HardwareGeneralPanel = uipanel(app.GridHardware);
            app.HardwareGeneralPanel.ForegroundColor = [1 1 1];
            app.HardwareGeneralPanel.BorderType = 'none';
            app.HardwareGeneralPanel.Title = 'General';
            app.HardwareGeneralPanel.BackgroundColor = [1 0.4118 0.1608];
            app.HardwareGeneralPanel.Layout.Row = 1;
            app.HardwareGeneralPanel.Layout.Column = 1;
            app.HardwareGeneralPanel.FontAngle = 'italic';
            app.HardwareGeneralPanel.FontWeight = 'bold';
            
            % Create GridHardwareGeneral
            app.GridHardwareGeneral = uigridlayout(app.HardwareGeneralPanel);
            app.GridHardwareGeneral.ColumnWidth = {'1x', '0.4x'};
            app.GridHardwareGeneral.RowHeight = {'fit', 'fit'};
            app.GridHardwareGeneral.BackgroundColor = [1 1 1];
            
            % Create SamplerateHzEditFieldLabel
            app.SamplerateHzEditFieldLabel = uilabel(app.GridHardwareGeneral);
            app.SamplerateHzEditFieldLabel.Layout.Row = 1;
            app.SamplerateHzEditFieldLabel.Layout.Column = 1;
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            
            % Create SamplerateHzEditField
            app.SamplerateHzEditField = uieditfield(app.GridHardwareGeneral, 'numeric');
            app.SamplerateHzEditField.Limits = [1 Inf];
            app.SamplerateHzEditField.RoundFractionalValues = 'on';
            app.SamplerateHzEditField.ValueDisplayFormat = '%d';
            app.SamplerateHzEditField.Layout.Row = 1;
            app.SamplerateHzEditField.Layout.Column = 2;
            if isempty(app.SampleRate)
            app.SamplerateHzEditField.Value = 48000;
            app.SampleRate = app.SamplerateHzEditField.Value;
            else
                app.SamplerateHzEditField.Value = app.SampleRate;
            end
            app.SamplerateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplerateHzEditFieldValueChanged, true);
            
            % Create SamplesperframeEditFieldLabel
            app.SamplesperframeEditFieldLabel = uilabel(app.GridHardwareGeneral);
            app.SamplesperframeEditFieldLabel.Layout.Row = 2;
            app.SamplesperframeEditFieldLabel.Layout.Column = 1;
            app.SamplesperframeEditFieldLabel.Text = 'Samples per frame';
            
            % Create SamplesperframeEditField
            app.SamplesperframeEditField = uieditfield(app.GridHardwareGeneral, 'numeric');
            app.SamplesperframeEditField.Limits = [1 Inf];
            app.SamplesperframeEditField.RoundFractionalValues = 'on';
            app.SamplesperframeEditField.ValueDisplayFormat = '%d';
            app.SamplesperframeEditField.Layout.Row = 2;
            app.SamplesperframeEditField.Layout.Column = 2;
            if isempty(app.SamplesPerFrame)
            app.SamplesperframeEditField.Value = 1024;
            app.SamplesPerFrame = app.SamplesperframeEditField.Value;
            else
                app.SamplesperframeEditField.Value = app.SamplesPerFrame;
            end
            app.SamplesperframeEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesperframeEditFieldValueChanged, true);
            
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
            
            % Create HardwareDevicePanel
            app.HardwareDevicePanel = uipanel(app.GridHardware);
            app.HardwareDevicePanel.ForegroundColor = [1 1 1];
            app.HardwareDevicePanel.BorderType = 'none';
            app.HardwareDevicePanel.Title = 'Device';
            app.HardwareDevicePanel.BackgroundColor = [0 0 0];
            app.HardwareDevicePanel.Layout.Row = 2;
            app.HardwareDevicePanel.Layout.Column = 1;
            app.HardwareDevicePanel.FontAngle = 'italic';
            app.HardwareDevicePanel.FontWeight = 'bold';
            
            % Create GridHardwareDevice
            app.GridHardwareDevice = uigridlayout(app.HardwareDevicePanel);
            app.GridHardwareDevice.RowHeight = {'fit', 'fit', 'fit','fit'};
            app.GridHardwareDevice.BackgroundColor = [1 1 1];
            
            % Create DriverDropDownLabel
            app.DriverDropDownLabel = uilabel(app.GridHardwareDevice);
            app.DriverDropDownLabel.Layout.Row = 1;
            app.DriverDropDownLabel.Layout.Column = 1;
            app.DriverDropDownLabel.Text = 'Driver';
            
            
            % Create DeviceDriverDropDown
            app.DeviceDriverDropDown = uidropdown(app.GridHardwareDevice);
            app.DeviceDriverDropDown.Layout.Row = 1;
            app.DeviceDriverDropDown.Layout.Column = 2;
            app.DeviceDriverDropDown.BackgroundColor = [1 1 1];
            app.DeviceDriverDropDown.Items = getAvailableDrivers();
            if isempty(app.Driver)
                app.Driver = app.DeviceDriverDropDown.Value;
            else
                app.DeviceDriverDropDown.Value = app.Driver;
            end
            app.DeviceDriverDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDriverDropDownValueChanged, true);
            
            % Create DeviceDropDownLabel
            app.DeviceDropDownLabel = uilabel(app.GridHardwareDevice);
            app.DeviceDropDownLabel.Layout.Row = 2;
            app.DeviceDropDownLabel.Layout.Column = 1;
            app.DeviceDropDownLabel.Text = 'Device';
            
            % Create Device
            app.DeviceDropDown = uidropdown(app.GridHardwareDevice);
            app.DeviceDropDown.Layout.Row = 2;
            app.DeviceDropDown.Layout.Column = 2;
            app.DeviceDropDown.BackgroundColor = [1 1 1];
            app.DeviceDropDown.Items = getAvailableDevices();
            if isempty(app.Device)
                app.Device = app.DeviceDropDown.Value;
            else
                app.DeviceDropDown.Value = app.Device;
            end
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            
            % Create BitdepthDropDownLabel
            app.BitdepthDropDownLabel = uilabel(app.GridHardwareDevice);
            app.BitdepthDropDownLabel.Layout.Row = 3;
            app.BitdepthDropDownLabel.Layout.Column = 1;
            app.BitdepthDropDownLabel.Text = 'Bit depth';
            
            % Create BitdepthDevice
            app.BitdepthDevice = uidropdown(app.GridHardwareDevice);
            app.BitdepthDevice.Layout.Row = 3;
            app.BitdepthDevice.Layout.Column = 2;
            app.BitdepthDevice.BackgroundColor = [1 1 1];
            app.BitdepthDevice.Items = {'24-bit integer','16-bit integer','32-bit float'};
            if isempty(app.BitDepth)
                app.BitDepth = app.BitdepthDevice.Value;
            else
                app.BitdepthDevice.Value = app.BitDepth;
            end
            app.BitdepthDevice.ValueChangedFcn = createCallbackFcn(app, @BitdepthDeviceValueChanged, true);
            
            % Create RefreshButton
            app.RefreshavailabledevicesButton = uibutton(app.GridHardwareDevice, 'push');
            app.RefreshavailabledevicesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshavailabledevicesButtonPushed, true);
            app.RefreshavailabledevicesButton.Text = 'Refresh available devices';
            app.RefreshavailabledevicesButton.BackgroundColor = [1 1 1];
            app.RefreshavailabledevicesButton.Layout.Row = 4;
            app.RefreshavailabledevicesButton.Layout.Column = [1,2];
            
            
        end
        function RefreshavailabledevicesButtonPushed(app, ~)
            app.DeviceDropDown.Items = getAvailableDevices();
            app.DeviceDriverDropDown.Items = getAvailableDrivers();
        end
        function SamplerateHzEditFieldValueChanged(app, ~)
            app.SampleRate = app.SamplerateHzEditField.Value;
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
        end
        function SamplesperframeEditFieldValueChanged(app, ~)
            app.SamplesPerFrame = app.SamplesperframeEditField.Value;
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
        end
        function DeviceDriverDropDownValueChanged(app, ~)
            app.Driver = app.DeviceDriverDropDown.Value;
        end
        function DeviceDropDownValueChanged(app,~)
            app.Device = app.DeviceDropDown.Value;
        end
        function BitdepthDeviceValueChanged(app,~)
            app.BitDepth = app.BitdepthDevice.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add include
            rootpath = fileparts(mfilename('fullpath'));
            addpath(genpath([rootpath,filesep,'include']))
            
            % Update Auto-Reflow
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            
            % Set columns
            % Menu Bar Style
            menus = findobj(app.UIFigure,'type','uimenu');
            for ii = 1:numel(menus)
                menus(ii).Separator = 1; % Add separator between elements
            end
            
        end

        % Menu selected function: HardwareinputoutputMenu
        function HardwareinputoutputMenuSelected(app, event)
            % Prevents re-creating the panel. Control when the same menu option is pressed twice.
            if ~sum(ishandle(app.PanelHardware))
                createPanelHardware(app)
            end
        end

        % Menu selected function: CalibrateinputsMenu
        function CalibrateinputsMenuSelected(app, event)
            delete(app.PanelHardware)
        end

        % Callback function
        function OutputchannelDropDownCalibrateLatencyValueChanged(app, event)
            value = app.OutputchannelDropDownCalibrateLatency.Value;
            
        end

        % Callback function
        function UPVRobotRestrictCheckBoxValueChanged(app, event)
            value = app.UPVRobotRestrictCheckBox.Value
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {720, 720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {328, '1x', 330};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLeftMain = uigridlayout(app.LeftPanel);
            app.AeroacousticsLeftPanel = uipanel(app.GridLeftMain);
            app.AeroacousticsLeftPanel.Title = 'Aeroacoustics';
            app.GridLayout8 = uigridlayout(app.AeroacousticsLeftPanel);
            app.FrequencyResponsePanel = uipanel(app.GridLayout8);
            app.FrequencyResponsePanel.Title = 'Frequency Response ';
            app.GridLayout9 = uigridlayout(app.FrequencyResponsePanel);
            app.WindowDropDownLabel = uilabel(app.GridLayout9);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.GridLayout9);
            app.WindowDropDown.Items = {'Bartlett-Hann', 'Bartlett', 'Blackman', 'Blackman-Harris', 'Bohman', 'Chebyshev', 'Flat Top', 'Gaussian', 'Hamming', 'Hann', 'Kaiser', 'Nuttall''s Blackman-Harris', 'Parzen (de la Vallée-Poussin)', 'Rectangular', 'Tapered cosine', 'Triangular'};
            app.WindowDropDown.Value = 'Kaiser';
            app.NumberofDFTpointsEditFieldLabel = uilabel(app.GridLayout9);
            app.NumberofDFTpointsEditFieldLabel.Text = 'Number of DFT points';
            app.NumberofDFTpointsEditField = uieditfield(app.GridLayout9, 'numeric');
            app.NumberofDFTpointsEditField.Limits = [256 Inf];
            app.NumberofDFTpointsEditField.RoundFractionalValues = 'on';
            app.NumberofDFTpointsEditField.Value = 256;
            app.WindowsizeEditFieldLabel = uilabel(app.GridLayout9);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.GridLayout9, 'numeric');
            app.WindowsizeEditField.Limits = [64 Inf];
            app.WindowsizeEditField.Value = 64;
            app.WindowOverlapEditFieldLabel = uilabel(app.GridLayout9);
            app.WindowOverlapEditFieldLabel.Text = 'Window Overlap (%)';
            app.WindowOverlapEditField = uieditfield(app.GridLayout9, 'numeric');
            app.WindowOverlapEditField.Limits = [0 99];
            app.WindowOverlapEditField.Value = 50;
            app.SoundPressureLevelPanel = uipanel(app.GridLayout8);
            app.SoundPressureLevelPanel.Title = 'Sound Pressure Level';
            app.GridLayout10 = uigridlayout(app.SoundPressureLevelPanel);
            app.BandwidthDropDownLabel = uilabel(app.GridLayout10);
            app.BandwidthDropDownLabel.Text = 'Bandwidth';
            app.BandwidthDropDown = uidropdown(app.GridLayout10);
            app.BandwidthDropDown.Items = {'Full band', '1 octave', '2/3 octave', '1/3 octave'};
            app.BandwidthDropDown.Value = 'Full band';
            app.OctaveFilterOrderSpinnerLabel = uilabel(app.GridLayout10);
            app.OctaveFilterOrderSpinnerLabel.Text = 'Octave Filter Order';
            app.OctaveFilterOrderSpinner = uispinner(app.GridLayout10);
            app.OctaveFilterOrderSpinner.Step = 2;
            app.OctaveFilterOrderSpinner.Limits = [2 Inf];
            app.OctaveFilterOrderSpinner.RoundFractionalValues = 'on';
            app.OctaveFilterOrderSpinner.Tooltip = {'Order of the octave filter, specified as an even integer.'};
            app.OctaveFilterOrderSpinner.Value = 2;
            app.FrequencyWeightingDropDownLabel = uilabel(app.GridLayout10);
            app.FrequencyWeightingDropDownLabel.Text = 'Frequency Weighting';
            app.FrequencyWeightingDropDown = uidropdown(app.GridLayout10);
            app.FrequencyWeightingDropDown.Items = {'A-weighting', 'C-weighting', 'Z-weighting'};
            app.FrequencyWeightingDropDown.Tooltip = {'Frequency weighting applied to input, specified as ''A-weighting'', ''C-weighting'', or ''Z-weighting'', where Z-weighting corresponds to no weighting.'};
            app.FrequencyWeightingDropDown.Value = 'Z-weighting';
            app.TimeWeightingDropDownLabel = uilabel(app.GridLayout10);
            app.TimeWeightingDropDownLabel.Text = 'Time Weighting';
            app.TimeWeightingDropDown = uidropdown(app.GridLayout10);
            app.TimeWeightingDropDown.Items = {'Fast', 'Slow'};
            app.TimeWeightingDropDown.Tooltip = {'Time weighting, in seconds, for calculation of time-weighted sound level and maximum time-weighted sound level, specified as ''Fast'' (0.125 s) or ''Slow'' (1 s)'};
            app.TimeWeightingDropDown.Value = 'Fast';
            app.TimeIntervalsEditFieldLabel = uilabel(app.GridLayout10);
            app.TimeIntervalsEditFieldLabel.Text = 'Time Interval (s)';
            app.TimeIntervalsEditField = uieditfield(app.GridLayout10, 'numeric');
            app.TimeIntervalsEditField.Limits = [0.01 Inf];
            app.TimeIntervalsEditField.Tooltip = {'Time interval, in seconds, to report equivalent-continuous, peak, and maximum time-weighted sound levels, specified as a positive scalar integer.'};
            app.TimeIntervalsEditField.Value = 1;
            app.FrequencyRangetoevaluateLabel = uilabel(app.GridLayout10);
            app.FrequencyRangetoevaluateLabel.Text = 'Frequency Range to evaluate';
            app.MinfrequencyHzEditFieldLabel = uilabel(app.GridLayout10);
            app.MinfrequencyHzEditFieldLabel.Text = 'Min frequency (Hz)';
            app.MinfrequencyHzEditField = uieditfield(app.GridLayout10, 'numeric');
            app.MinfrequencyHzEditField.Limits = [0 Inf];
            app.MinfrequencyHzEditField.RoundFractionalValues = 'on';
            app.MinfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MinfrequencyHzEditField.Tooltip = {'Frequency range of the filter bank in Hz, specified as a two-element row vector of positive monotonically increasing values. Frequency bands centered above SampleRate/2 are excluded.'};
            app.MinfrequencyHzEditField.Value = 22;
            app.MaxfrequencyHzEditFieldLabel = uilabel(app.GridLayout10);
            app.MaxfrequencyHzEditFieldLabel.Text = 'Max frequency (Hz)';
            app.MaxfrequencyHzEditField = uieditfield(app.GridLayout10, 'numeric');
            app.MaxfrequencyHzEditField.Limits = [0 Inf];
            app.MaxfrequencyHzEditField.RoundFractionalValues = 'on';
            app.MaxfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MaxfrequencyHzEditField.Tooltip = {'Frequency range of the filter bank in Hz, specified as a two-element row vector of positive monotonically increasing values. Frequency bands centered above SampleRate/2 are excluded.'};
            app.MaxfrequencyHzEditField.Value = 22050;
            app.ChannelsPanel = uipanel(app.GridLayout8);
            app.ChannelsPanel.Title = 'Channels';
            app.GridLayout11 = uigridlayout(app.ChannelsPanel);
            app.OutputChannelDropDownLabel = uilabel(app.GridLayout11);
            app.OutputChannelDropDownLabel.Text = 'Output Channel';
            app.OutputChannelDropDown = uidropdown(app.GridLayout11);
            app.OutputChannelDropDown.Items = {'1', '2'};
            app.OutputChannelDropDown.Value = '1';
            app.InputChannelsLabel = uilabel(app.GridLayout11);
            app.InputChannelsLabel.Text = 'Input Channels';
            app.UITable = uitable(app.GridLayout11);
            app.UITable.ColumnName = {'Select'; 'Channel'};
            app.UITable.RowName = {};
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridCenterMain = uigridlayout(app.CenterPanel);
            app.AeroacousticsImpulseResponsePanel = uipanel(app.GridCenterMain);
            app.AeroacousticsImpulseResponsePanel.Title = 'Impulse Response';
            app.GridAeroacousticsImpulseResponse = uigridlayout(app.AeroacousticsImpulseResponsePanel);
            app.AeroacousticsImpulseResponseAxes = uiaxes(app.GridAeroacousticsImpulseResponse);
            xlabel(app.AeroacousticsImpulseResponseAxes, 'X')
            ylabel(app.AeroacousticsImpulseResponseAxes, 'Y')
            zlabel(app.AeroacousticsImpulseResponseAxes, 'Z')
            app.AeroacousticsFrequencyResponsePanel = uipanel(app.GridCenterMain);
            app.AeroacousticsFrequencyResponsePanel.Title = 'Frequency Response';
            app.GridAeroacousticsFrequencyResponsePanel = uigridlayout(app.AeroacousticsFrequencyResponsePanel);
            app.AeroacousticsFrequencyResponseAxes = uiaxes(app.GridAeroacousticsFrequencyResponsePanel);
            xlabel(app.AeroacousticsFrequencyResponseAxes, 'X')
            ylabel(app.AeroacousticsFrequencyResponseAxes, 'Y')
            zlabel(app.AeroacousticsFrequencyResponseAxes, 'Z')
            app.AeroacousticsSoundPressureLevelPanel = uipanel(app.GridCenterMain);
            app.AeroacousticsSoundPressureLevelPanel.Title = 'Sound Pressure Level';
            app.GridAeroacousticsSoundPressureLevelPanel = uigridlayout(app.AeroacousticsSoundPressureLevelPanel);
            app.AeroacousticsSoundPressureLevelAxes = uiaxes(app.GridAeroacousticsSoundPressureLevelPanel);
            xlabel(app.AeroacousticsSoundPressureLevelAxes, 'X')
            ylabel(app.AeroacousticsSoundPressureLevelAxes, 'Y')
            zlabel(app.AeroacousticsSoundPressureLevelAxes, 'Z')
            app.GridAeroacousticsButtons = uigridlayout(app.GridCenterMain);
            app.AeroacousticsExportmeasuretxtButton = uibutton(app.GridAeroacousticsButtons, 'push');
            app.AeroacousticsExportmeasuretxtButton.Text = 'Export measure (.txt)';
            app.AeroacousticsExportgraphicsButton = uibutton(app.GridAeroacousticsButtons, 'push');
            app.AeroacousticsExportgraphicsButton.Text = 'Export graphics';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridRightMain = uigridlayout(app.RightPanel);
            app.SignalparametersPanel = uipanel(app.GridRightMain);
            app.SignalparametersPanel.Title = 'Signal parameters';
            app.GridSignalParameters = uigridlayout(app.SignalparametersPanel);
            app.GridSignalType = uigridlayout(app.GridSignalParameters);
            app.SignaltypeDropDownLabel = uilabel(app.GridSignalType);
            app.SignaltypeDropDownLabel.Text = 'Signal type';
            app.SignaltypeDropDown = uidropdown(app.GridSignalType);
            app.SignaltypeDropDown.Items = {'Sweep', 'MLS', 'White Noise', 'Pink Noise', 'Tone'};
            app.SignaltypeDropDown.Value = 'Sweep';
            app.GridLayout7 = uigridlayout(app.GridSignalParameters);
            app.TonePanel = uipanel(app.GridLayout7);
            app.TonePanel.Title = 'Tone';
            app.GridTone = uigridlayout(app.TonePanel);
            app.DurationsLabel = uilabel(app.GridTone);
            app.DurationsLabel.Text = 'Duration (s)';
            app.ToneDurationEditField = uieditfield(app.GridTone, 'numeric');
            app.ToneDurationEditField.Limits = [0.1 Inf];
            app.ToneDurationEditField.Value = 1;
            app.FrequencyHzEditFieldLabel = uilabel(app.GridTone);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.ToneFrequencyHzEditField = uieditfield(app.GridTone, 'numeric');
            app.ToneFrequencyHzEditField.Limits = [1 Inf];
            app.ToneFrequencyHzEditField.RoundFractionalValues = 'on';
            app.ToneFrequencyHzEditField.ValueDisplayFormat = '%d';
            app.ToneFrequencyHzEditField.Value = 1000;
            app.PanelMeasure = uipanel(app.GridRightMain);
            app.GridMeasure = uigridlayout(app.PanelMeasure);
            app.StartmeasureButton = uibutton(app.GridMeasure, 'push');
            app.StartmeasureButton.Text = 'Start measure';
            app.InformationPanel = uipanel(app.GridRightMain);
            app.InformationPanel.Title = 'Information';
            app.GridInformation = uigridlayout(app.InformationPanel);
            app.InfoDeviceLabel = uilabel(app.GridInformation);
            app.InfoDeviceLabel.Text = 'Device';
            app.InfoSampleRateLabel = uilabel(app.GridInformation);
            app.InfoSampleRateLabel.Text = 'Sample rate';
            app.InfoSamplesPerFrameLabel = uilabel(app.GridInformation);
            app.InfoSamplesPerFrameLabel.Text = 'Samples per frame';
            app.InfoInputsCalibratedLabel = uilabel(app.GridInformation);
            app.InfoInputsCalibratedLabel.Text = 'Inputs calibrated';
            app.InfoLatencyLabel = uilabel(app.GridInformation);
            app.InfoLatencyLabel.Text = 'Latency calibrated';
            app.InfoDevice = uilabel(app.GridInformation);
            app.InfoDevice.Text = 'Default';
            app.InfoSampleRate = uilabel(app.GridInformation);
            app.InfoSampleRate.Text = '48000 Hz';
            app.InfoSamplesPerFrame = uilabel(app.GridInformation);
            app.InfoSamplesPerFrame.Text = '1024';
            app.InfoInputsCalibrated = uilabel(app.GridInformation);
            app.InfoInputsCalibrated.Text = 'No';
            app.InfoLatency = uilabel(app.GridInformation);
            app.InfoLatency.Text = 'No (Estimated: 100 ms)';
            app.InfoEmptyLabel1 = uilabel(app.GridInformation);
            app.InfoEmptyLabel1.Text = '';
            app.MeasureTypeGrid = uigridlayout(app.GridRightMain);
            app.MeasurementtypePanel = uipanel(app.MeasureTypeGrid);
            app.MeasurementtypePanel.Title = 'Measurement type';
            app.GridMeasurementtypePanel = uigridlayout(app.MeasurementtypePanel);
            app.ContinuousLabel = uilabel(app.GridMeasurementtypePanel);
            app.ContinuousLabel.Text = 'Continuous?';
            app.ContinuousCheckBox = uicheckbox(app.GridMeasurementtypePanel);
            app.ContinuousCheckBox.Text = '';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.LoadprojectMenu = uimenu(app.FileMenu);
            app.LoadprojectMenu.Text = 'Load project...';
            app.SaveprojectMenu = uimenu(app.FileMenu);
            app.SaveprojectMenu.Text = 'Save project...';
            app.MeasurementMenu = uimenu(app.UIFigure);
            app.MeasurementMenu.Text = 'Measurement';
            app.IndividualchannelsgeneralMenu = uimenu(app.MeasurementMenu);
            app.IndividualchannelsgeneralMenu.Text = 'Individual channels (general)';
            app.ImpedancetubeMenu = uimenu(app.MeasurementMenu);
            app.ImpedancetubeMenu.Text = 'Impedance tube';
            app.TwomicrophoneISO105342Menu = uimenu(app.ImpedancetubeMenu);
            app.TwomicrophoneISO105342Menu.Text = 'Two-microphone (ISO 10534-2)';
            app.OnemicrophoneISO105342Menu = uimenu(app.ImpedancetubeMenu);
            app.OnemicrophoneISO105342Menu.Text = 'One-microphone (ISO 10534-2)';
            app.FixedlocationsMenu = uimenu(app.OnemicrophoneISO105342Menu);
            app.FixedlocationsMenu.Text = 'Fixed locations';
            app.VariablelocationsMenu = uimenu(app.OnemicrophoneISO105342Menu);
            app.VariablelocationsMenu.Text = 'Variable locations';
            app.FourmicrophoneASTME26119Menu = uimenu(app.ImpedancetubeMenu);
            app.FourmicrophoneASTME26119Menu.Text = 'Four-microphone (ASTM E2611-9)';
            app.AnechoicchamberMenu = uimenu(app.MeasurementMenu);
            app.AnechoicchamberMenu.Text = 'Anechoic chamber';
            app.ReflectionabsorptionandimpedanceISO105342modifiedMenu = uimenu(app.AnechoicchamberMenu);
            app.ReflectionabsorptionandimpedanceISO105342modifiedMenu.Text = 'Reflection, absorption and impedance (ISO 10534-2 modified)';
            app.DeviceMenu = uimenu(app.UIFigure);
            app.DeviceMenu.Text = 'Device';
            app.HardwareinputoutputMenu = uimenu(app.DeviceMenu);
            app.HardwareinputoutputMenu.MenuSelectedFcn = createCallbackFcn(app, @HardwareinputoutputMenuSelected, true);
            app.HardwareinputoutputMenu.Text = 'Hardware (input/output)';
            app.CalibrateinputsMenu = uimenu(app.DeviceMenu);
            app.CalibrateinputsMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrateinputsMenuSelected, true);
            app.CalibrateinputsMenu.Text = 'Calibrate inputs';
            app.CalibratedevicelatencyMenu = uimenu(app.DeviceMenu);
            app.CalibratedevicelatencyMenu.Text = 'Calibrate device latency';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signalpanelcopia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end