classdef TwoMic_CalibrateTwoMic_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLeftMain                    matlab.ui.container.GridLayout
        TwoMicISO10534Panel             matlab.ui.container.Panel
        GridTwoMicISO10534              matlab.ui.container.GridLayout
        TwoMicISO10534Info              matlab.ui.control.Label
        TwoMicISO10534TubeParamsPanel   matlab.ui.container.Panel
        TwoMicISO10534TubeParamsGrid    matlab.ui.container.GridLayout
        TwoMicISO10534SettubeparametersButton  matlab.ui.control.Button
        TwoMicISO10534InformationPanel  matlab.ui.container.Panel
        GridTwoMicISO10534Information   matlab.ui.container.GridLayout
        TwoMicISO10534ViewinformationButton  matlab.ui.control.Button
        TwoMicISO10534MeasureTypePanel  matlab.ui.container.Panel
        GridTwoMicISO10534MeasureType   matlab.ui.container.GridLayout
        TwoMicISO10534TwomicrophoneButton  matlab.ui.control.Button
        TwoMicISO10534OnemicrophoneButton  matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        GridCenterMain                  matlab.ui.container.GridLayout
        CenterMainPanelTwoMicCalib      matlab.ui.container.Panel
        GridCenterPanelTwoMicCalib      matlab.ui.container.GridLayout
        TwoMicTwoMicConfigIICalibPanel  matlab.ui.container.Panel
        TwoMicTwoMicConfigIICalibGrid   matlab.ui.container.GridLayout
        TwoMicTwoMicConfigIICalibClearMeasure  matlab.ui.control.Button
        TwoMicTwoMicConfigIICalibMic2DropDown  matlab.ui.control.DropDown
        Channelformicrophone2Label_2    matlab.ui.control.Label
        TwoMicTwoMicConfigIICalibMic1DropDown  matlab.ui.control.DropDown
        Channelformicrophone1Label_2    matlab.ui.control.Label
        TwoMicTwoMicConfigIICalibMeasure  matlab.ui.control.Button
        TwoMicTwoMicConfigIICalibAxes   matlab.ui.control.UIAxes
        TwoMicCalibInfoAndOutputGrid    matlab.ui.container.GridLayout
        TwoMicCalibrateCancelButtonGrid  matlab.ui.container.GridLayout
        TwoMicCalibrateCancelButton     matlab.ui.control.Button
        TwoMicCalibMeasureInfo          matlab.ui.control.Label
        TwoMicCalibMeasureOutputChannelDropDown  matlab.ui.control.DropDown
        OutputChannelDropDownLabel      matlab.ui.control.Label
        TwoMicTwoMicConfigICalibPanel   matlab.ui.container.Panel
        TwoMicTwoMicConfigICalibGrid    matlab.ui.container.GridLayout
        TwoMicTwoMicConfigICalibClearMeasure  matlab.ui.control.Button
        TwoMicTwoMicConfigICalibMic2DropDown  matlab.ui.control.DropDown
        Channelformicrophone2Label      matlab.ui.control.Label
        TwoMicTwoMicConfigICalibMic1DropDown  matlab.ui.control.DropDown
        Channelformicrophone1Label      matlab.ui.control.Label
        TwoMicTwoMicConfigICalibMeasure  matlab.ui.control.Button
        TwoMicTwoMicConfigICalibAxes    matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridRightMain                   matlab.ui.container.GridLayout
        GridTubeTwoMicRightInfo         matlab.ui.container.GridLayout
        PanelTubeTwoMicRightInfo        matlab.ui.container.Panel
        PanelTubeTwoMicRightInfoGrid    matlab.ui.container.GridLayout
        PanelTubeTwoMicRightInfoTubeCalLabel  matlab.ui.control.Label
        PanelTubeTwoMicRightInfoTubeCal  matlab.ui.control.Label
        PanelTubeTwoMicRightInfoUFreqlabel  matlab.ui.control.Label
        PanelTubeTwoMicRightInfoLFreqlabel  matlab.ui.control.Label
        PanelTubeTwoMicRightInfoUFreq   matlab.ui.control.Label
        PanelTubeTwoMicRightInfoLFreq   matlab.ui.control.Label
        InformationPanel                matlab.ui.container.Panel
        GridInformation                 matlab.ui.container.GridLayout
        InfoEmptyLabel1                 matlab.ui.control.Label
        InfoLatency                     matlab.ui.control.Label
        InfoInputsCalibrated            matlab.ui.control.Label
        InfoSamplesPerFrame             matlab.ui.control.Label
        InfoSampleRate                  matlab.ui.control.Label
        InfoDevice                      matlab.ui.control.Label
        InfoLatencyLabel                matlab.ui.control.Label
        InfoInputsCalibratedLabel       matlab.ui.control.Label
        InfoSamplesPerFrameLabel        matlab.ui.control.Label
        InfoSampleRateLabel             matlab.ui.control.Label
        InfoDeviceLabel                 matlab.ui.control.Label
        PanelMeasure                    matlab.ui.container.Panel
        GridMeasure                     matlab.ui.container.GridLayout
        StartmeasureButton              matlab.ui.control.Button
        SignalparametersPanel           matlab.ui.container.Panel
        GridSignalParameters            matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        TonePanel                       matlab.ui.container.Panel
        GridTone                        matlab.ui.container.GridLayout
        ToneFrequencyHzEditField        matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        ToneDurationEditField           matlab.ui.control.NumericEditField
        DurationsLabel                  matlab.ui.control.Label
        GridSignalType                  matlab.ui.container.GridLayout
        SignaltypeDropDown              matlab.ui.control.DropDown
        SignaltypeDropDownLabel         matlab.ui.control.Label
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        LoadprojectMenu                 matlab.ui.container.Menu
        SaveprojectMenu                 matlab.ui.container.Menu
        MeasurementMenu                 matlab.ui.container.Menu
        IndividualchannelsgeneralMenu   matlab.ui.container.Menu
        ImpedancetubeMenu               matlab.ui.container.Menu
        TwomicrophoneISO105342Menu      matlab.ui.container.Menu
        OnemicrophoneISO105342Menu      matlab.ui.container.Menu
        FixedlocationsMenu              matlab.ui.container.Menu
        VariablelocationsMenu           matlab.ui.container.Menu
        FourmicrophoneASTME26119Menu    matlab.ui.container.Menu
        AnechoicchamberMenu             matlab.ui.container.Menu
        ReflectionabsorptionandimpedanceISO105342modifiedMenu  matlab.ui.container.Menu
        DeviceMenu                      matlab.ui.container.Menu
        HardwareinputoutputMenu         matlab.ui.container.Menu
        CalibrateinputsMenu             matlab.ui.container.Menu
        CalibratedevicelatencyMenu      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        % Hardware Panel Objects
        PanelHardware                   matlab.ui.container.Panel
        GridHardware                    matlab.ui.container.GridLayout
        HardwareGeneralPanel            matlab.ui.container.Panel
        GridHardwareGeneral             matlab.ui.container.GridLayout
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        SamplesperframeEditFieldLabel   matlab.ui.control.Label
        SamplesperframeEditField        matlab.ui.control.NumericEditField
        HardwareDevicePanel             matlab.ui.container.Panel
        GridHardwareDevice              matlab.ui.container.GridLayout
        DriverDropDownLabel             matlab.ui.control.Label
        DeviceDriverDropDown            matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        BitdepthDropDownLabel           matlab.ui.control.Label
        BitdepthDevice                  matlab.ui.control.DropDown
        HardwareOutputPanel             matlab.ui.container.Panel
        GridHardwareOutput              matlab.ui.container.GridLayout
        DriveOutputLabel                matlab.ui.control.Label
        DriverOutput                    matlab.ui.control.DropDown
        DeviceOutputLabel               matlab.ui.control.Label
        DeviceOutput                    matlab.ui.control.DropDown
        BitdepthOutputLabel             matlab.ui.control.Label
        BitdepthOutput                  matlab.ui.control.DropDown
        RefreshavailabledevicesButton   matlab.ui.control.Button
        
    end
    
    properties (Access = public)
        % Hardware
        SampleRate
        SamplesPerFrame
        Driver
        Device
        BitDepth
        
        % Calibrate sensibility
        Sensibility
        
        % Calibrate Latency
        Latency
        
        
        % Signal 
        TypeSignal
        OutSignal
        InputSignal
        % (each signal variable is a structure with its parameters)
        Sweep
        MLS
        WhiteNoise
        PinkNoise
        Tone
        
    end
    
    methods (Access = private)
        
        function myUpdateAppLayout(app, ~)
            
            % Between original width and new panel width
            app.onePanelWidth = 576; % Like Mobile if width < onePanelWidth
            app.twoPanelWidth = 768; % Like Tablet if twoPanelWidth > width > onePanelWidth
            
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid (Like mobile)
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid (Like tablet)
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid (normal)
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
        
        function createPanelHardware(app)
            
            % Main panel
            app.PanelHardware = uipanel(app.GridLeftMain);
            app.PanelHardware.TitlePosition = 'centertop';
            app.PanelHardware.Title = 'Hardware configuration';
            app.PanelHardware.Layout.Row = 1;
            app.PanelHardware.Layout.Column = 1;
            app.PanelHardware.BorderType = 'none';
            app.PanelHardware.FontWeight = 'bold';
            app.PanelHardware.FontSize = 14;
            app.PanelHardware.BackgroundColor = [1 1 1];
            
            % Create GridHardware
            app.GridHardware = uigridlayout(app.PanelHardware);
            app.GridHardware.ColumnWidth = {'1x'};
            app.GridHardware.RowHeight = {'fit', 'fit'};
            app.GridHardware.BackgroundColor = [1 1 1];
            
            % Create HardwareGeneralPanel
            app.HardwareGeneralPanel = uipanel(app.GridHardware);
            app.HardwareGeneralPanel.ForegroundColor = [1 1 1];
            app.HardwareGeneralPanel.BorderType = 'none';
            app.HardwareGeneralPanel.Title = 'General';
            app.HardwareGeneralPanel.BackgroundColor = [1 0.4118 0.1608];
            app.HardwareGeneralPanel.Layout.Row = 1;
            app.HardwareGeneralPanel.Layout.Column = 1;
            app.HardwareGeneralPanel.FontAngle = 'italic';
            app.HardwareGeneralPanel.FontWeight = 'bold';
            
            % Create GridHardwareGeneral
            app.GridHardwareGeneral = uigridlayout(app.HardwareGeneralPanel);
            app.GridHardwareGeneral.ColumnWidth = {'1x', '0.4x'};
            app.GridHardwareGeneral.RowHeight = {'fit', 'fit'};
            app.GridHardwareGeneral.BackgroundColor = [1 1 1];
            
            % Create SamplerateHzEditFieldLabel
            app.SamplerateHzEditFieldLabel = uilabel(app.GridHardwareGeneral);
            app.SamplerateHzEditFieldLabel.Layout.Row = 1;
            app.SamplerateHzEditFieldLabel.Layout.Column = 1;
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            
            % Create SamplerateHzEditField
            app.SamplerateHzEditField = uieditfield(app.GridHardwareGeneral, 'numeric');
            app.SamplerateHzEditField.Limits = [1 Inf];
            app.SamplerateHzEditField.RoundFractionalValues = 'on';
            app.SamplerateHzEditField.ValueDisplayFormat = '%d';
            app.SamplerateHzEditField.Layout.Row = 1;
            app.SamplerateHzEditField.Layout.Column = 2;
            if isempty(app.SampleRate)
            app.SamplerateHzEditField.Value = 48000;
            app.SampleRate = app.SamplerateHzEditField.Value;
            else
                app.SamplerateHzEditField.Value = app.SampleRate;
            end
            app.SamplerateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplerateHzEditFieldValueChanged, true);
            
            % Create SamplesperframeEditFieldLabel
            app.SamplesperframeEditFieldLabel = uilabel(app.GridHardwareGeneral);
            app.SamplesperframeEditFieldLabel.Layout.Row = 2;
            app.SamplesperframeEditFieldLabel.Layout.Column = 1;
            app.SamplesperframeEditFieldLabel.Text = 'Samples per frame';
            
            % Create SamplesperframeEditField
            app.SamplesperframeEditField = uieditfield(app.GridHardwareGeneral, 'numeric');
            app.SamplesperframeEditField.Limits = [1 Inf];
            app.SamplesperframeEditField.RoundFractionalValues = 'on';
            app.SamplesperframeEditField.ValueDisplayFormat = '%d';
            app.SamplesperframeEditField.Layout.Row = 2;
            app.SamplesperframeEditField.Layout.Column = 2;
            if isempty(app.SamplesPerFrame)
            app.SamplesperframeEditField.Value = 1024;
            app.SamplesPerFrame = app.SamplesperframeEditField.Value;
            else
                app.SamplesperframeEditField.Value = app.SamplesPerFrame;
            end
            app.SamplesperframeEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesperframeEditFieldValueChanged, true);
            
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
            
            % Create HardwareDevicePanel
            app.HardwareDevicePanel = uipanel(app.GridHardware);
            app.HardwareDevicePanel.ForegroundColor = [1 1 1];
            app.HardwareDevicePanel.BorderType = 'none';
            app.HardwareDevicePanel.Title = 'Device';
            app.HardwareDevicePanel.BackgroundColor = [0 0 0];
            app.HardwareDevicePanel.Layout.Row = 2;
            app.HardwareDevicePanel.Layout.Column = 1;
            app.HardwareDevicePanel.FontAngle = 'italic';
            app.HardwareDevicePanel.FontWeight = 'bold';
            
            % Create GridHardwareDevice
            app.GridHardwareDevice = uigridlayout(app.HardwareDevicePanel);
            app.GridHardwareDevice.RowHeight = {'fit', 'fit', 'fit','fit'};
            app.GridHardwareDevice.BackgroundColor = [1 1 1];
            
            % Create DriverDropDownLabel
            app.DriverDropDownLabel = uilabel(app.GridHardwareDevice);
            app.DriverDropDownLabel.Layout.Row = 1;
            app.DriverDropDownLabel.Layout.Column = 1;
            app.DriverDropDownLabel.Text = 'Driver';
            
            
            % Create DeviceDriverDropDown
            app.DeviceDriverDropDown = uidropdown(app.GridHardwareDevice);
            app.DeviceDriverDropDown.Layout.Row = 1;
            app.DeviceDriverDropDown.Layout.Column = 2;
            app.DeviceDriverDropDown.BackgroundColor = [1 1 1];
            app.DeviceDriverDropDown.Items = getAvailableDrivers();
            if isempty(app.Driver)
                app.Driver = app.DeviceDriverDropDown.Value;
            else
                app.DeviceDriverDropDown.Value = app.Driver;
            end
            app.DeviceDriverDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDriverDropDownValueChanged, true);
            
            % Create DeviceDropDownLabel
            app.DeviceDropDownLabel = uilabel(app.GridHardwareDevice);
            app.DeviceDropDownLabel.Layout.Row = 2;
            app.DeviceDropDownLabel.Layout.Column = 1;
            app.DeviceDropDownLabel.Text = 'Device';
            
            % Create Device
            app.DeviceDropDown = uidropdown(app.GridHardwareDevice);
            app.DeviceDropDown.Layout.Row = 2;
            app.DeviceDropDown.Layout.Column = 2;
            app.DeviceDropDown.BackgroundColor = [1 1 1];
            app.DeviceDropDown.Items = getAvailableDevices();
            if isempty(app.Device)
                app.Device = app.DeviceDropDown.Value;
            else
                app.DeviceDropDown.Value = app.Device;
            end
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            
            % Create BitdepthDropDownLabel
            app.BitdepthDropDownLabel = uilabel(app.GridHardwareDevice);
            app.BitdepthDropDownLabel.Layout.Row = 3;
            app.BitdepthDropDownLabel.Layout.Column = 1;
            app.BitdepthDropDownLabel.Text = 'Bit depth';
            
            % Create BitdepthDevice
            app.BitdepthDevice = uidropdown(app.GridHardwareDevice);
            app.BitdepthDevice.Layout.Row = 3;
            app.BitdepthDevice.Layout.Column = 2;
            app.BitdepthDevice.BackgroundColor = [1 1 1];
            app.BitdepthDevice.Items = {'24-bit integer','16-bit integer','32-bit float'};
            if isempty(app.BitDepth)
                app.BitDepth = app.BitdepthDevice.Value;
            else
                app.BitdepthDevice.Value = app.BitDepth;
            end
            app.BitdepthDevice.ValueChangedFcn = createCallbackFcn(app, @BitdepthDeviceValueChanged, true);
            
            % Create RefreshButton
            app.RefreshavailabledevicesButton = uibutton(app.GridHardwareDevice, 'push');
            app.RefreshavailabledevicesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshavailabledevicesButtonPushed, true);
            app.RefreshavailabledevicesButton.Text = 'Refresh available devices';
            app.RefreshavailabledevicesButton.BackgroundColor = [1 1 1];
            app.RefreshavailabledevicesButton.Layout.Row = 4;
            app.RefreshavailabledevicesButton.Layout.Column = [1,2];
            
            
        end
        function RefreshavailabledevicesButtonPushed(app, ~)
            app.DeviceDropDown.Items = getAvailableDevices();
            app.DeviceDriverDropDown.Items = getAvailableDrivers();
        end
        function SamplerateHzEditFieldValueChanged(app, ~)
            app.SampleRate = app.SamplerateHzEditField.Value;
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
        end
        function SamplesperframeEditFieldValueChanged(app, ~)
            app.SamplesPerFrame = app.SamplesperframeEditField.Value;
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
        end
        function DeviceDriverDropDownValueChanged(app, ~)
            app.Driver = app.DeviceDriverDropDown.Value;
        end
        function DeviceDropDownValueChanged(app,~)
            app.Device = app.DeviceDropDown.Value;
        end
        function BitdepthDeviceValueChanged(app,~)
            app.BitDepth = app.BitdepthDevice.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add include
            rootpath = fileparts(mfilename('fullpath'));
            addpath(genpath([rootpath,filesep,'include']))
            
            % Update Auto-Reflow
            %app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            
            % Set columns
            
            % Menu Bar Style
            menus = findobj(app.UIFigure,'type','uimenu');
            for ii = 1:numel(menus)
                menus(ii).Separator = 1; % Add separator between elements
            end
            
        end

        % Menu selected function: HardwareinputoutputMenu
        function HardwareinputoutputMenuSelected(app, event)
            % Prevents re-creating the panel. Control when the same menu option is pressed twice.
            if ~sum(ishandle(app.PanelHardware))
                createPanelHardware(app)
            end
        end

        % Menu selected function: CalibrateinputsMenu
        function CalibrateinputsMenuSelected(app, event)
            delete(app.PanelHardware)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {720, 720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {328, '1x', 330};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLeftMain = uigridlayout(app.LeftPanel);
            app.TwoMicISO10534Panel = uipanel(app.GridLeftMain);
            app.TwoMicISO10534Panel.Title = 'Two-microphone (ISO 10534-2)';
            app.GridTwoMicISO10534 = uigridlayout(app.TwoMicISO10534Panel);
            app.TwoMicISO10534MeasureTypePanel = uipanel(app.GridTwoMicISO10534);
            app.TwoMicISO10534MeasureTypePanel.Title = 'Measurement';
            app.GridTwoMicISO10534MeasureType = uigridlayout(app.TwoMicISO10534MeasureTypePanel);
            app.TwoMicISO10534OnemicrophoneButton = uibutton(app.GridTwoMicISO10534MeasureType, 'push');
            app.TwoMicISO10534OnemicrophoneButton.Text = 'One microphone';
            app.TwoMicISO10534TwomicrophoneButton = uibutton(app.GridTwoMicISO10534MeasureType, 'push');
            app.TwoMicISO10534TwomicrophoneButton.Text = 'Two microphone';
            app.TwoMicISO10534InformationPanel = uipanel(app.GridTwoMicISO10534);
            app.TwoMicISO10534InformationPanel.Title = 'Measure information';
            app.GridTwoMicISO10534Information = uigridlayout(app.TwoMicISO10534InformationPanel);
            app.TwoMicISO10534ViewinformationButton = uibutton(app.GridTwoMicISO10534Information, 'push');
            app.TwoMicISO10534ViewinformationButton.Text = 'View information';
            app.TwoMicISO10534TubeParamsPanel = uipanel(app.GridTwoMicISO10534);
            app.TwoMicISO10534TubeParamsPanel.Title = 'Tube parameters';
            app.TwoMicISO10534TubeParamsGrid = uigridlayout(app.TwoMicISO10534TubeParamsPanel);
            app.TwoMicISO10534SettubeparametersButton = uibutton(app.TwoMicISO10534TubeParamsGrid, 'push');
            app.TwoMicISO10534SettubeparametersButton.Text = 'Set tube parameters';
            app.TwoMicISO10534Info = uilabel(app.GridTwoMicISO10534);
            app.TwoMicISO10534Info.Text = {'· This method calculates the reflection, absorption and acoustic impedance according to the standard ISO 10534-2.'; '· The measurement can be performed with one or two microphones.'; '· The signals available for this method are MLS and Sweep.'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridCenterMain = uigridlayout(app.CenterPanel);
            app.CenterMainPanelTwoMicCalib = uipanel(app.GridCenterMain);
            app.CenterMainPanelTwoMicCalib.Title = 'Calibration ISO 10534-2';
            app.GridCenterPanelTwoMicCalib = uigridlayout(app.CenterMainPanelTwoMicCalib);
            app.TwoMicTwoMicConfigICalibPanel = uipanel(app.GridCenterPanelTwoMicCalib);
            app.TwoMicTwoMicConfigICalibPanel.Title = 'Standard configuration (configuration I)';
            app.TwoMicTwoMicConfigICalibGrid = uigridlayout(app.TwoMicTwoMicConfigICalibPanel);
            app.TwoMicTwoMicConfigICalibAxes = uiaxes(app.TwoMicTwoMicConfigICalibGrid);
            app.TwoMicTwoMicConfigICalibMeasure = uibutton(app.TwoMicTwoMicConfigICalibGrid, 'push');
            app.TwoMicTwoMicConfigICalibMeasure.Text = 'Measure';
            app.Channelformicrophone1Label = uilabel(app.TwoMicTwoMicConfigICalibGrid);
            app.Channelformicrophone1Label.Text = 'Channel for microphone 1';
            app.TwoMicTwoMicConfigICalibMic1DropDown = uidropdown(app.TwoMicTwoMicConfigICalibGrid);
            app.TwoMicTwoMicConfigICalibMic1DropDown.Items = {'1', '2', '3'};
            app.TwoMicTwoMicConfigICalibMic1DropDown.Value = '1';
            app.Channelformicrophone2Label = uilabel(app.TwoMicTwoMicConfigICalibGrid);
            app.Channelformicrophone2Label.Text = 'Channel for microphone 2';
            app.TwoMicTwoMicConfigICalibMic2DropDown = uidropdown(app.TwoMicTwoMicConfigICalibGrid);
            app.TwoMicTwoMicConfigICalibMic2DropDown.Items = {'1', '2', '3'};
            app.TwoMicTwoMicConfigICalibMic2DropDown.Value = '1';
            app.TwoMicTwoMicConfigICalibClearMeasure = uibutton(app.TwoMicTwoMicConfigICalibGrid, 'push');
            app.TwoMicTwoMicConfigICalibClearMeasure.Text = 'Clear measure';
            app.TwoMicCalibInfoAndOutputGrid = uigridlayout(app.GridCenterPanelTwoMicCalib);
            app.OutputChannelDropDownLabel = uilabel(app.TwoMicCalibInfoAndOutputGrid);
            app.OutputChannelDropDownLabel.Text = 'Output Channel';
            app.TwoMicCalibMeasureOutputChannelDropDown = uidropdown(app.TwoMicCalibInfoAndOutputGrid);
            app.TwoMicCalibMeasureOutputChannelDropDown.Items = {'1', '2', '3', '4'};
            app.TwoMicCalibMeasureOutputChannelDropDown.Value = '1';
            app.TwoMicCalibMeasureInfo = uilabel(app.TwoMicCalibInfoAndOutputGrid);
            app.TwoMicCalibMeasureInfo.Text = 'Place an absorptive specimen in the tube to prevent strong acoustic reflections and measure with the following two settings. Remember to use the same channels as the ones you are going to use in the measurements.';
            app.TwoMicCalibrateCancelButtonGrid = uigridlayout(app.TwoMicCalibInfoAndOutputGrid);
            app.TwoMicCalibrateCancelButton = uibutton(app.TwoMicCalibrateCancelButtonGrid, 'push');
            app.TwoMicCalibrateCancelButton.Text = 'Cancel';
            app.TwoMicTwoMicConfigIICalibPanel = uipanel(app.GridCenterPanelTwoMicCalib);
            app.TwoMicTwoMicConfigIICalibPanel.Title = 'Configuration with microphones interchanged (configuration II)';
            app.TwoMicTwoMicConfigIICalibGrid = uigridlayout(app.TwoMicTwoMicConfigIICalibPanel);
            app.TwoMicTwoMicConfigIICalibAxes = uiaxes(app.TwoMicTwoMicConfigIICalibGrid);
            app.TwoMicTwoMicConfigIICalibMeasure = uibutton(app.TwoMicTwoMicConfigIICalibGrid, 'push');
            app.TwoMicTwoMicConfigIICalibMeasure.Text = 'Measure';
            app.Channelformicrophone1Label_2 = uilabel(app.TwoMicTwoMicConfigIICalibGrid);
            app.Channelformicrophone1Label_2.Text = 'Channel for microphone 1';
            app.TwoMicTwoMicConfigIICalibMic1DropDown = uidropdown(app.TwoMicTwoMicConfigIICalibGrid);
            app.TwoMicTwoMicConfigIICalibMic1DropDown.Items = {'1', '2', '3'};
            app.TwoMicTwoMicConfigIICalibMic1DropDown.Value = '1';
            app.Channelformicrophone2Label_2 = uilabel(app.TwoMicTwoMicConfigIICalibGrid);
            app.Channelformicrophone2Label_2.Text = 'Channel for microphone 2';
            app.TwoMicTwoMicConfigIICalibMic2DropDown = uidropdown(app.TwoMicTwoMicConfigIICalibGrid);
            app.TwoMicTwoMicConfigIICalibMic2DropDown.Items = {'1', '2', '3'};
            app.TwoMicTwoMicConfigIICalibMic2DropDown.Value = '1';
            app.TwoMicTwoMicConfigIICalibClearMeasure = uibutton(app.TwoMicTwoMicConfigIICalibGrid, 'push');
            app.TwoMicTwoMicConfigIICalibClearMeasure.Text = 'Clear measure';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridRightMain = uigridlayout(app.RightPanel);
            app.SignalparametersPanel = uipanel(app.GridRightMain);
            app.SignalparametersPanel.Title = 'Signal parameters';
            app.GridSignalParameters = uigridlayout(app.SignalparametersPanel);
            app.GridSignalType = uigridlayout(app.GridSignalParameters);
            app.SignaltypeDropDownLabel = uilabel(app.GridSignalType);
            app.SignaltypeDropDownLabel.Text = 'Signal type';
            app.SignaltypeDropDown = uidropdown(app.GridSignalType);
            app.SignaltypeDropDown.Items = {'Sweep', 'MLS', 'White Noise', 'Pink Noise', 'Tone'};
            app.SignaltypeDropDown.Value = 'Sweep';
            app.GridLayout7 = uigridlayout(app.GridSignalParameters);
            app.TonePanel = uipanel(app.GridLayout7);
            app.TonePanel.Title = 'Tone';
            app.GridTone = uigridlayout(app.TonePanel);
            app.DurationsLabel = uilabel(app.GridTone);
            app.DurationsLabel.Text = 'Duration (s)';
            app.ToneDurationEditField = uieditfield(app.GridTone, 'numeric');
            app.ToneDurationEditField.Limits = [0.1 Inf];
            app.ToneDurationEditField.Value = 1;
            app.FrequencyHzEditFieldLabel = uilabel(app.GridTone);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.ToneFrequencyHzEditField = uieditfield(app.GridTone, 'numeric');
            app.ToneFrequencyHzEditField.Limits = [1 Inf];
            app.ToneFrequencyHzEditField.RoundFractionalValues = 'on';
            app.ToneFrequencyHzEditField.ValueDisplayFormat = '%d';
            app.ToneFrequencyHzEditField.Value = 1000;
            app.PanelMeasure = uipanel(app.GridRightMain);
            app.GridMeasure = uigridlayout(app.PanelMeasure);
            app.StartmeasureButton = uibutton(app.GridMeasure, 'push');
            app.StartmeasureButton.Text = 'Start measure';
            app.InformationPanel = uipanel(app.GridRightMain);
            app.InformationPanel.Title = 'Information';
            app.GridInformation = uigridlayout(app.InformationPanel);
            app.InfoDeviceLabel = uilabel(app.GridInformation);
            app.InfoDeviceLabel.Text = 'Device';
            app.InfoSampleRateLabel = uilabel(app.GridInformation);
            app.InfoSampleRateLabel.Text = 'Sample rate';
            app.InfoSamplesPerFrameLabel = uilabel(app.GridInformation);
            app.InfoSamplesPerFrameLabel.Text = 'Samples per frame';
            app.InfoInputsCalibratedLabel = uilabel(app.GridInformation);
            app.InfoInputsCalibratedLabel.Text = 'Inputs calibrated';
            app.InfoLatencyLabel = uilabel(app.GridInformation);
            app.InfoLatencyLabel.Text = 'Latency calibrated';
            app.InfoDevice = uilabel(app.GridInformation);
            app.InfoDevice.Text = 'Default';
            app.InfoSampleRate = uilabel(app.GridInformation);
            app.InfoSampleRate.Text = '48000 Hz';
            app.InfoSamplesPerFrame = uilabel(app.GridInformation);
            app.InfoSamplesPerFrame.Text = '1024';
            app.InfoInputsCalibrated = uilabel(app.GridInformation);
            app.InfoInputsCalibrated.Text = 'No';
            app.InfoLatency = uilabel(app.GridInformation);
            app.InfoLatency.Text = 'No (Estimated: 100 ms)';
            app.InfoEmptyLabel1 = uilabel(app.GridInformation);
            app.InfoEmptyLabel1.Text = '';
            app.GridTubeTwoMicRightInfo = uigridlayout(app.GridRightMain);
            app.PanelTubeTwoMicRightInfo = uipanel(app.GridTubeTwoMicRightInfo);
            app.PanelTubeTwoMicRightInfo.Title = 'Tube information';
            app.PanelTubeTwoMicRightInfoGrid = uigridlayout(app.PanelTubeTwoMicRightInfo);
            app.PanelTubeTwoMicRightInfoLFreq = uilabel(app.PanelTubeTwoMicRightInfoGrid);
            app.PanelTubeTwoMicRightInfoLFreq.Text = 'Lower frequency';
            app.PanelTubeTwoMicRightInfoUFreq = uilabel(app.PanelTubeTwoMicRightInfoGrid);
            app.PanelTubeTwoMicRightInfoUFreq.Text = 'Upper frequency';
            app.PanelTubeTwoMicRightInfoLFreqlabel = uilabel(app.PanelTubeTwoMicRightInfoGrid);
            app.PanelTubeTwoMicRightInfoLFreqlabel.Text = '100 Hz';
            app.PanelTubeTwoMicRightInfoUFreqlabel = uilabel(app.PanelTubeTwoMicRightInfoGrid);
            app.PanelTubeTwoMicRightInfoUFreqlabel.Text = '3000 Hz';
            app.PanelTubeTwoMicRightInfoTubeCal = uilabel(app.PanelTubeTwoMicRightInfoGrid);
            app.PanelTubeTwoMicRightInfoTubeCal.Text = 'Tube calibrated';
            app.PanelTubeTwoMicRightInfoTubeCalLabel = uilabel(app.PanelTubeTwoMicRightInfoGrid);
            app.PanelTubeTwoMicRightInfoTubeCalLabel.Text = 'No';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.LoadprojectMenu = uimenu(app.FileMenu);
            app.LoadprojectMenu.Text = 'Load project...';
            app.SaveprojectMenu = uimenu(app.FileMenu);
            app.SaveprojectMenu.Text = 'Save project...';
            app.MeasurementMenu = uimenu(app.UIFigure);
            app.MeasurementMenu.Text = 'Measurement';
            app.IndividualchannelsgeneralMenu = uimenu(app.MeasurementMenu);
            app.IndividualchannelsgeneralMenu.Text = 'Individual channels (general)';
            app.ImpedancetubeMenu = uimenu(app.MeasurementMenu);
            app.ImpedancetubeMenu.Text = 'Impedance tube';
            app.TwomicrophoneISO105342Menu = uimenu(app.ImpedancetubeMenu);
            app.TwomicrophoneISO105342Menu.Text = 'Two-microphone (ISO 10534-2)';
            app.OnemicrophoneISO105342Menu = uimenu(app.ImpedancetubeMenu);
            app.OnemicrophoneISO105342Menu.Text = 'One-microphone (ISO 10534-2)';
            app.FixedlocationsMenu = uimenu(app.OnemicrophoneISO105342Menu);
            app.FixedlocationsMenu.Text = 'Fixed locations';
            app.VariablelocationsMenu = uimenu(app.OnemicrophoneISO105342Menu);
            app.VariablelocationsMenu.Text = 'Variable locations';
            app.FourmicrophoneASTME26119Menu = uimenu(app.ImpedancetubeMenu);
            app.FourmicrophoneASTME26119Menu.Text = 'Four-microphone (ASTM E2611-9)';
            app.AnechoicchamberMenu = uimenu(app.MeasurementMenu);
            app.AnechoicchamberMenu.Text = 'Anechoic chamber';
            app.ReflectionabsorptionandimpedanceISO105342modifiedMenu = uimenu(app.AnechoicchamberMenu);
            app.ReflectionabsorptionandimpedanceISO105342modifiedMenu.Text = 'Reflection, absorption and impedance (ISO 10534-2 modified)';
            app.DeviceMenu = uimenu(app.UIFigure);
            app.DeviceMenu.Text = 'Device';
            app.HardwareinputoutputMenu = uimenu(app.DeviceMenu);
            app.HardwareinputoutputMenu.MenuSelectedFcn = createCallbackFcn(app, @HardwareinputoutputMenuSelected, true);
            app.HardwareinputoutputMenu.Text = 'Hardware (input/output)';
            app.CalibrateinputsMenu = uimenu(app.DeviceMenu);
            app.CalibrateinputsMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrateinputsMenuSelected, true);
            app.CalibrateinputsMenu.Text = 'Calibrate inputs';
            app.CalibratedevicelatencyMenu = uimenu(app.DeviceMenu);
            app.CalibratedevicelatencyMenu.Text = 'Calibrate device latency';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoMic_CalibrateTwoMic_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end