classdef UPVRobot_PanelGeom_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLeftMain                    matlab.ui.container.GridLayout
        UPVRobotLeftPanel               matlab.ui.container.Panel
        GridUPVRobotLeftPanel           matlab.ui.container.GridLayout
        UPVRobotInfo                    matlab.ui.control.Label
        UPVRobotDevicechannelsPanel     matlab.ui.container.Panel
        GridUPVRobotDevicechannelsPanel  matlab.ui.container.GridLayout
        UPVRobotOutputChPanel           matlab.ui.container.Panel
        UPVRobotOutputChGrid            matlab.ui.container.GridLayout
        UPVRobotSpeakerDropDown         matlab.ui.control.DropDown
        UPVRobotInputChPanel            matlab.ui.container.Panel
        UPVRobotInputChGrid             matlab.ui.container.GridLayout
        UPVRobotMicDropDown             matlab.ui.control.DropDown
        UPVRobotLeftPanelMeasurementPoints  matlab.ui.container.Panel
        GridUPVRobotLeftPanelMeasurementPoints  matlab.ui.container.GridLayout
        TabUPVRobotLeftPanelMeasurementPoints  matlab.ui.container.TabGroup
        TabUPVRobotLeftPanelMeasurementPointsAutomatic  matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        UPVRobotOriginYEditField        matlab.ui.control.NumericEditField
        UPVRobotOriginZEditField        matlab.ui.control.NumericEditField
        UPVRobotOriginXEditField        matlab.ui.control.NumericEditField
        UPVRobotEndXEditField           matlab.ui.control.NumericEditField
        UPVRobotEndYEditField           matlab.ui.control.NumericEditField
        UPVRobotEndZEditField           matlab.ui.control.NumericEditField
        UPVRobotPointsXEditField        matlab.ui.control.NumericEditField
        UPVRobotPointsYEditField        matlab.ui.control.NumericEditField
        UPVRobotPointsZEditField        matlab.ui.control.NumericEditField
        UPVRoboAutomaticPointsInfo      matlab.ui.control.Label
        UPVRoboAutomaticPointsOriginmLabel  matlab.ui.control.Label
        UPVRoboAutomaticPointsEndmLabel  matlab.ui.control.Label
        UPVRoboAutomaticPointsResolutionLabel  matlab.ui.control.Label
        UPVRoboAutomaticPointsXAxisLabel  matlab.ui.control.Label
        UPVRoboAutomaticPointsYAxisLabel  matlab.ui.control.Label
        UPVRoboAutomaticPointsZaxisLabel_2  matlab.ui.control.Label
        TabUPVRobotLeftPanelMeasurementPointsImport  matlab.ui.container.Tab
        UPVRobotImportGrid              matlab.ui.container.GridLayout
        UPVRobotImportInfo              matlab.ui.control.Label
        UPVRobotImportFileButton        matlab.ui.control.Button
        UPVRobotImportFileNameLabel     matlab.ui.control.Label
        UnitsDropDownLabel              matlab.ui.control.Label
        UPVRobotImportUnitsDropDown     matlab.ui.control.DropDown
        UPVRobotImportXAxisLabel        matlab.ui.control.Label
        UPVRobotImportYAxisLabel        matlab.ui.control.Label
        UPVRobotImportZAxisLabel        matlab.ui.control.Label
        UPVRobotImportCheckErrorInfo    matlab.ui.control.Label
        UPVRobotImportminmLabel         matlab.ui.control.Label
        UPVRobotImportmaxmLabel         matlab.ui.control.Label
        UPVRobotImportOffsetmLabel      matlab.ui.control.Label
        UPVRobotImportXmin              matlab.ui.control.NumericEditField
        UPVRobotImportXOffset           matlab.ui.control.NumericEditField
        UPVRobotImportXmax              matlab.ui.control.NumericEditField
        UPVRobotImportYmin              matlab.ui.control.NumericEditField
        UPVRobotImportYmax              matlab.ui.control.NumericEditField
        UPVRobotImportZmin              matlab.ui.control.NumericEditField
        UPVRobotImportZmax              matlab.ui.control.NumericEditField
        UPVRobotImportYOffset           matlab.ui.control.NumericEditField
        UPVRobotImportZOffset           matlab.ui.control.NumericEditField
        TabUPVRobotLeftPanelMeasurementPoinManual  matlab.ui.container.Tab
        UPVRobotLeftPanelMeasurementPoinManualGrid  matlab.ui.container.GridLayout
        NumberofpointsEditFieldLabel    matlab.ui.control.Label
        UPVRobotPointManualNumberofpointsEditField  matlab.ui.control.NumericEditField
        UPVRobotPointManualExporttotextButton  matlab.ui.control.Button
        UPVRobotPointManualTable        matlab.ui.control.Table
        TabUPVRobotLeftPanelMeasurementGeometric  matlab.ui.container.Tab
        UPVRobotLeftPanelMeasurementGeometricGrid  matlab.ui.container.GridLayout
        ShapeDropDownLabel              matlab.ui.control.Label
        UPVRobotGeometricShapeDropDown  matlab.ui.control.DropDown
        UPVRobotGeometricSpherePanel    matlab.ui.container.Panel
        UPVRobotGeometricSphereGrid     matlab.ui.container.GridLayout
        XEditFieldLabel_5               matlab.ui.control.Label
        UPVRobotGeometricSphereXEditField  matlab.ui.control.NumericEditField
        YEditFieldLabel_5               matlab.ui.control.Label
        UPVRobotGeometricSphereYEditField  matlab.ui.control.NumericEditField
        ZEditFieldLabel_5               matlab.ui.control.Label
        UPVRobotGeometricSphereZEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricSphereCentermLabel  matlab.ui.control.Label
        UPVRobotGeometricSphereSeparator2  matlab.ui.control.Label
        UPVRobotGeometricSphereSubGrid  matlab.ui.container.GridLayout
        ResthetaLabel                   matlab.ui.control.Label
        UPVRobotGeometricSphereResThetaEditField  matlab.ui.control.NumericEditField
        RadiusmEditFieldLabel_3         matlab.ui.control.Label
        UPVRobotGeometricSphereRadiusmEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricSphereSeparator3  matlab.ui.control.Label
        ResphiLabel                     matlab.ui.control.Label
        UPVRobotGeometricSphereResPhiEditField  matlab.ui.control.NumericEditField
        UPVRobotPathtosavedataPanel     matlab.ui.container.Panel
        GridUPVRobotPathtosavedataPanel  matlab.ui.container.GridLayout
        UPVRobotPathtosavedataPanelSelectbutton  matlab.ui.control.Button
        UPVRobotPathtosavedataPanelPathlabel  matlab.ui.control.Label
        UPVRobotGeneratemeasurementpointsButton  matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        GridCenterMain                  matlab.ui.container.GridLayout
        CenterMainPanelUPVRobot         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        UPVRobotGeometricCircumferenceGrid  matlab.ui.container.GridLayout
        XEditFieldLabel                 matlab.ui.control.Label
        UPVRobotGeomCircumXEditField    matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        UPVRobotGeomCircumYEditField    matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        UPVRobotGeomCircumZEditField    matlab.ui.control.NumericEditField
        UPVRobotGeomCircumCentermLabel  matlab.ui.control.Label
        ResanglesLabel                  matlab.ui.control.Label
        UPVRobotGeomCircumResolutionEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricCircumferenceSeparator2  matlab.ui.control.Label
        PlaneDropDownLabel              matlab.ui.control.Label
        UPVRobotGeomCircumPlaneDropDown  matlab.ui.control.DropDown
        RadiusmEditFieldLabel           matlab.ui.control.Label
        UPVRobotGeomCircumRadiusmEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricCircumferenceSeparator  matlab.ui.control.Label
        UPVRobotGeometricCircleGrid     matlab.ui.container.GridLayout
        XEditFieldLabel_2               matlab.ui.control.Label
        UPVRobotGeometricCircleXEditField  matlab.ui.control.NumericEditField
        YEditFieldLabel_2               matlab.ui.control.Label
        UPVRobotGeometricCircleYEditField  matlab.ui.control.NumericEditField
        ZEditFieldLabel_2               matlab.ui.control.Label
        UPVRobotGeometricCircleZEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricCircleCentermLabel  matlab.ui.control.Label
        UPVRobotGeometricCircleSeparator2  matlab.ui.control.Label
        PlaneDropDownLabel_2            matlab.ui.control.Label
        UPVRobotGeometricCirclePlaneDropDown  matlab.ui.control.DropDown
        UPVRobotGeometricCircleSeparator  matlab.ui.control.Label
        UPVRobotGeometricCircleSubGrid  matlab.ui.container.GridLayout
        RadiusmEditFieldLabel_2         matlab.ui.control.Label
        UPVRobotGeometricCircleRadiusmEditField  matlab.ui.control.NumericEditField
        ResanglesLabel_2                matlab.ui.control.Label
        UPVRobotGeometricCircleResolutionEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricCircleSeparator3  matlab.ui.control.Label
        CirclesEditFieldLabel           matlab.ui.control.Label
        UPVRobotGeometricCircleCirclesEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricRectanGrid     matlab.ui.container.GridLayout
        XEditFieldLabel_3               matlab.ui.control.Label
        UPVRobotGeometricRectanXEditField  matlab.ui.control.NumericEditField
        YEditFieldLabel_3               matlab.ui.control.Label
        UPVRobotGeometricRectanYEditField  matlab.ui.control.NumericEditField
        ZEditFieldLabel_3               matlab.ui.control.Label
        UPVRobotGeometricRectanZEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricRectanCentermLabel  matlab.ui.control.Label
        UPVRobotGeometricRectanSeparator2  matlab.ui.control.Label
        PlaneDropDownLabel_3            matlab.ui.control.Label
        UPVRobotGeometricRectanPlaneDropDown  matlab.ui.control.DropDown
        UPVRobotGeometricRectanSeparator  matlab.ui.control.Label
        UPVRobotGeometricRectanSubGrid  matlab.ui.container.GridLayout
        HlengthmLabel                   matlab.ui.control.Label
        UPVRobotGeometricRectanHoriLengthEditField  matlab.ui.control.NumericEditField
        HresLabel                       matlab.ui.control.Label
        UPVRobotGeometricRectanHoriResEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricRectanSeparator3  matlab.ui.control.Label
        VlengthmLabel                   matlab.ui.control.Label
        UPVRobotGeometricRectanVertLengthEditField  matlab.ui.control.NumericEditField
        VresLabel                       matlab.ui.control.Label
        UPVRobotGeometricRectanVertResEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricPolyGrid       matlab.ui.container.GridLayout
        XEditFieldLabel_4               matlab.ui.control.Label
        UPVRobotGeometricPolyXEditField  matlab.ui.control.NumericEditField
        YEditFieldLabel_4               matlab.ui.control.Label
        UPVRobotGeometricPolyYEditField  matlab.ui.control.NumericEditField
        ZEditFieldLabel_4               matlab.ui.control.Label
        UPVRobotGeometricPolyZEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricPolyCentermLabel  matlab.ui.control.Label
        UPVRobotGeometricPolySeparator2  matlab.ui.control.Label
        PlaneDropDownLabel_4            matlab.ui.control.Label
        UPVRobotGeometricPolyPlaneDropDown  matlab.ui.control.DropDown
        UPVRobotGeometricPolySeparator  matlab.ui.control.Label
        UPVRobotGeometricPolySubGrid    matlab.ui.container.GridLayout
        SidemLabel                      matlab.ui.control.Label
        UPVRobotGeometricPolySideLenEditField  matlab.ui.control.NumericEditField
        ResolutionLabel                 matlab.ui.control.Label
        UPVRobotGeometricPolyResolutionEditField  matlab.ui.control.NumericEditField
        UPVRobotGeometricPolySeparator3  matlab.ui.control.Label
        SidesLabel                      matlab.ui.control.Label
        UPVRobotGeometricPolySidesEditField  matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        GridRightMain                   matlab.ui.container.GridLayout
        SignalparametersPanel           matlab.ui.container.Panel
        GridSignalParameters            matlab.ui.container.GridLayout
        GridSignalType                  matlab.ui.container.GridLayout
        SignaltypeDropDownLabel         matlab.ui.control.Label
        SignaltypeDropDown              matlab.ui.control.DropDown
        GridLayout7                     matlab.ui.container.GridLayout
        GridAveragesMeas                matlab.ui.container.GridLayout
        MeasureaveragesEditFieldLabel   matlab.ui.control.Label
        MeasureaveragesEditField        matlab.ui.control.NumericEditField
        PanelMeasure                    matlab.ui.container.Panel
        GridMeasure                     matlab.ui.container.GridLayout
        StartmeasureButton              matlab.ui.control.Button
        InformationPanel                matlab.ui.container.Panel
        GridInformation                 matlab.ui.container.GridLayout
        InfoDeviceLabel                 matlab.ui.control.Label
        InfoSampleRateLabel             matlab.ui.control.Label
        InfoSamplesPerFrameLabel        matlab.ui.control.Label
        InfoInputsCalibratedLabel       matlab.ui.control.Label
        InfoLatencyLabel                matlab.ui.control.Label
        InfoDevice                      matlab.ui.control.Label
        InfoSampleRate                  matlab.ui.control.Label
        InfoSamplesPerFrame             matlab.ui.control.Label
        InfoInputsCalibrated            matlab.ui.control.Label
        InfoLatency                     matlab.ui.control.Label
        InfoEmptyLabel1                 matlab.ui.control.Label
        BitDepthLabel                   matlab.ui.control.Label
        bitintegerLabel                 matlab.ui.control.Label
        UPVRobotandmeasuredetailsPanel  matlab.ui.container.Panel
        UPVGridInformation              matlab.ui.container.GridLayout
        UPVRobotMeasureTimeTotalInfoLabel  matlab.ui.control.Label
        UPVRobotMeasureTimeEachInfoLabel  matlab.ui.control.Label
        UPVRobotMeasureTimeEachInfo     matlab.ui.control.Label
        UPVRobotMeasureTimeTotalInfo    matlab.ui.control.Label
        UPVRobotWarningsAndErrors       matlab.ui.control.Label
        UPVRobotButtonPanelInfo         matlab.ui.control.Button
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        LoadprojectMenu                 matlab.ui.container.Menu
        SaveprojectMenu                 matlab.ui.container.Menu
        MeasurementMenu                 matlab.ui.container.Menu
        IndividualchannelsgeneralMenu   matlab.ui.container.Menu
        ImpedancetubeMenu               matlab.ui.container.Menu
        TwomicrophoneISO105342Menu      matlab.ui.container.Menu
        OnemicrophoneISO105342Menu      matlab.ui.container.Menu
        FixedlocationsMenu              matlab.ui.container.Menu
        VariablelocationsMenu           matlab.ui.container.Menu
        FourmicrophoneASTME26119Menu    matlab.ui.container.Menu
        AnechoicchamberMenu             matlab.ui.container.Menu
        ReflectionabsorptionandimpedanceISO105342modifiedMenu  matlab.ui.container.Menu
        DeviceMenu                      matlab.ui.container.Menu
        HardwareinputoutputMenu         matlab.ui.container.Menu
        CalibrateinputsMenu             matlab.ui.container.Menu
        CalibratedevicelatencyMenu      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        % Hardware Panel Objects
        PanelHardware                   matlab.ui.container.Panel
        GridHardware                    matlab.ui.container.GridLayout
        HardwareGeneralPanel            matlab.ui.container.Panel
        GridHardwareGeneral             matlab.ui.container.GridLayout
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        SamplesperframeEditFieldLabel   matlab.ui.control.Label
        SamplesperframeEditField        matlab.ui.control.NumericEditField
        HardwareDevicePanel             matlab.ui.container.Panel
        GridHardwareDevice              matlab.ui.container.GridLayout
        DriverDropDownLabel             matlab.ui.control.Label
        DeviceDriverDropDown            matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        BitdepthDropDownLabel           matlab.ui.control.Label
        BitdepthDevice                  matlab.ui.control.DropDown
        HardwareOutputPanel             matlab.ui.container.Panel
        GridHardwareOutput              matlab.ui.container.GridLayout
        DriveOutputLabel                matlab.ui.control.Label
        DriverOutput                    matlab.ui.control.DropDown
        DeviceOutputLabel               matlab.ui.control.Label
        DeviceOutput                    matlab.ui.control.DropDown
        BitdepthOutputLabel             matlab.ui.control.Label
        BitdepthOutput                  matlab.ui.control.DropDown
        RefreshavailabledevicesButton   matlab.ui.control.Button
        
    end
    
    properties (Access = public)
        % Hardware
        SampleRate
        SamplesPerFrame
        Driver
        Device
        BitDepth
        
        % Calibrate sensibility
        Sensibility
        
        % Calibrate Latency
        Latency
        
        
        % Signal 
        TypeSignal
        OutSignal
        InputSignal
        % (each signal variable is a structure with its parameters)
        Sweep
        MLS
        WhiteNoise
        PinkNoise
        Tone
        
    end
    
    methods (Access = private)
        
        function myUpdateAppLayout(app, ~)
            
            % Between original width and new panel width
            app.onePanelWidth = 576; % Like Mobile if width < onePanelWidth
            app.twoPanelWidth = 768; % Like Tablet if twoPanelWidth > width > onePanelWidth
            
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid (Like mobile)
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid (Like tablet)
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid (normal)
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
        
        function createPanelHardware(app)
            
            % Main panel
            app.PanelHardware = uipanel(app.GridLeftMain);
            app.PanelHardware.TitlePosition = 'centertop';
            app.PanelHardware.Title = 'Hardware configuration';
            app.PanelHardware.Layout.Row = 1;
            app.PanelHardware.Layout.Column = 1;
            app.PanelHardware.BorderType = 'none';
            app.PanelHardware.FontWeight = 'bold';
            app.PanelHardware.FontSize = 14;
            app.PanelHardware.BackgroundColor = [1 1 1];
            
            % Create GridHardware
            app.GridHardware = uigridlayout(app.PanelHardware);
            app.GridHardware.ColumnWidth = {'1x'};
            app.GridHardware.RowHeight = {'fit', 'fit'};
            app.GridHardware.BackgroundColor = [1 1 1];
            
            % Create HardwareGeneralPanel
            app.HardwareGeneralPanel = uipanel(app.GridHardware);
            app.HardwareGeneralPanel.ForegroundColor = [1 1 1];
            app.HardwareGeneralPanel.BorderType = 'none';
            app.HardwareGeneralPanel.Title = 'General';
            app.HardwareGeneralPanel.BackgroundColor = [1 0.4118 0.1608];
            app.HardwareGeneralPanel.Layout.Row = 1;
            app.HardwareGeneralPanel.Layout.Column = 1;
            app.HardwareGeneralPanel.FontAngle = 'italic';
            app.HardwareGeneralPanel.FontWeight = 'bold';
            
            % Create GridHardwareGeneral
            app.GridHardwareGeneral = uigridlayout(app.HardwareGeneralPanel);
            app.GridHardwareGeneral.ColumnWidth = {'1x', '0.4x'};
            app.GridHardwareGeneral.RowHeight = {'fit', 'fit'};
            app.GridHardwareGeneral.BackgroundColor = [1 1 1];
            
            % Create SamplerateHzEditFieldLabel
            app.SamplerateHzEditFieldLabel = uilabel(app.GridHardwareGeneral);
            app.SamplerateHzEditFieldLabel.Layout.Row = 1;
            app.SamplerateHzEditFieldLabel.Layout.Column = 1;
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            
            % Create SamplerateHzEditField
            app.SamplerateHzEditField = uieditfield(app.GridHardwareGeneral, 'numeric');
            app.SamplerateHzEditField.Limits = [1 Inf];
            app.SamplerateHzEditField.RoundFractionalValues = 'on';
            app.SamplerateHzEditField.ValueDisplayFormat = '%d';
            app.SamplerateHzEditField.Layout.Row = 1;
            app.SamplerateHzEditField.Layout.Column = 2;
            if isempty(app.SampleRate)
            app.SamplerateHzEditField.Value = 48000;
            app.SampleRate = app.SamplerateHzEditField.Value;
            else
                app.SamplerateHzEditField.Value = app.SampleRate;
            end
            app.SamplerateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplerateHzEditFieldValueChanged, true);
            
            % Create SamplesperframeEditFieldLabel
            app.SamplesperframeEditFieldLabel = uilabel(app.GridHardwareGeneral);
            app.SamplesperframeEditFieldLabel.Layout.Row = 2;
            app.SamplesperframeEditFieldLabel.Layout.Column = 1;
            app.SamplesperframeEditFieldLabel.Text = 'Samples per frame';
            
            % Create SamplesperframeEditField
            app.SamplesperframeEditField = uieditfield(app.GridHardwareGeneral, 'numeric');
            app.SamplesperframeEditField.Limits = [1 Inf];
            app.SamplesperframeEditField.RoundFractionalValues = 'on';
            app.SamplesperframeEditField.ValueDisplayFormat = '%d';
            app.SamplesperframeEditField.Layout.Row = 2;
            app.SamplesperframeEditField.Layout.Column = 2;
            if isempty(app.SamplesPerFrame)
            app.SamplesperframeEditField.Value = 1024;
            app.SamplesPerFrame = app.SamplesperframeEditField.Value;
            else
                app.SamplesperframeEditField.Value = app.SamplesPerFrame;
            end
            app.SamplesperframeEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesperframeEditFieldValueChanged, true);
            
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
            
            % Create HardwareDevicePanel
            app.HardwareDevicePanel = uipanel(app.GridHardware);
            app.HardwareDevicePanel.ForegroundColor = [1 1 1];
            app.HardwareDevicePanel.BorderType = 'none';
            app.HardwareDevicePanel.Title = 'Device';
            app.HardwareDevicePanel.BackgroundColor = [0 0 0];
            app.HardwareDevicePanel.Layout.Row = 2;
            app.HardwareDevicePanel.Layout.Column = 1;
            app.HardwareDevicePanel.FontAngle = 'italic';
            app.HardwareDevicePanel.FontWeight = 'bold';
            
            % Create GridHardwareDevice
            app.GridHardwareDevice = uigridlayout(app.HardwareDevicePanel);
            app.GridHardwareDevice.RowHeight = {'fit', 'fit', 'fit','fit'};
            app.GridHardwareDevice.BackgroundColor = [1 1 1];
            
            % Create DriverDropDownLabel
            app.DriverDropDownLabel = uilabel(app.GridHardwareDevice);
            app.DriverDropDownLabel.Layout.Row = 1;
            app.DriverDropDownLabel.Layout.Column = 1;
            app.DriverDropDownLabel.Text = 'Driver';
            
            
            % Create DeviceDriverDropDown
            app.DeviceDriverDropDown = uidropdown(app.GridHardwareDevice);
            app.DeviceDriverDropDown.Layout.Row = 1;
            app.DeviceDriverDropDown.Layout.Column = 2;
            app.DeviceDriverDropDown.BackgroundColor = [1 1 1];
            app.DeviceDriverDropDown.Items = getAvailableDrivers();
            if isempty(app.Driver)
                app.Driver = app.DeviceDriverDropDown.Value;
            else
                app.DeviceDriverDropDown.Value = app.Driver;
            end
            app.DeviceDriverDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDriverDropDownValueChanged, true);
            
            % Create DeviceDropDownLabel
            app.DeviceDropDownLabel = uilabel(app.GridHardwareDevice);
            app.DeviceDropDownLabel.Layout.Row = 2;
            app.DeviceDropDownLabel.Layout.Column = 1;
            app.DeviceDropDownLabel.Text = 'Device';
            
            % Create Device
            app.DeviceDropDown = uidropdown(app.GridHardwareDevice);
            app.DeviceDropDown.Layout.Row = 2;
            app.DeviceDropDown.Layout.Column = 2;
            app.DeviceDropDown.BackgroundColor = [1 1 1];
            app.DeviceDropDown.Items = getAvailableDevices();
            if isempty(app.Device)
                app.Device = app.DeviceDropDown.Value;
            else
                app.DeviceDropDown.Value = app.Device;
            end
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            
            % Create BitdepthDropDownLabel
            app.BitdepthDropDownLabel = uilabel(app.GridHardwareDevice);
            app.BitdepthDropDownLabel.Layout.Row = 3;
            app.BitdepthDropDownLabel.Layout.Column = 1;
            app.BitdepthDropDownLabel.Text = 'Bit depth';
            
            % Create BitdepthDevice
            app.BitdepthDevice = uidropdown(app.GridHardwareDevice);
            app.BitdepthDevice.Layout.Row = 3;
            app.BitdepthDevice.Layout.Column = 2;
            app.BitdepthDevice.BackgroundColor = [1 1 1];
            app.BitdepthDevice.Items = {'24-bit integer','16-bit integer','32-bit float'};
            if isempty(app.BitDepth)
                app.BitDepth = app.BitdepthDevice.Value;
            else
                app.BitdepthDevice.Value = app.BitDepth;
            end
            app.BitdepthDevice.ValueChangedFcn = createCallbackFcn(app, @BitdepthDeviceValueChanged, true);
            
            % Create RefreshButton
            app.RefreshavailabledevicesButton = uibutton(app.GridHardwareDevice, 'push');
            app.RefreshavailabledevicesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshavailabledevicesButtonPushed, true);
            app.RefreshavailabledevicesButton.Text = 'Refresh available devices';
            app.RefreshavailabledevicesButton.BackgroundColor = [1 1 1];
            app.RefreshavailabledevicesButton.Layout.Row = 4;
            app.RefreshavailabledevicesButton.Layout.Column = [1,2];
            
            
        end
        function RefreshavailabledevicesButtonPushed(app, ~)
            app.DeviceDropDown.Items = getAvailableDevices();
            app.DeviceDriverDropDown.Items = getAvailableDrivers();
        end
        function SamplerateHzEditFieldValueChanged(app, ~)
            app.SampleRate = app.SamplerateHzEditField.Value;
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
        end
        function SamplesperframeEditFieldValueChanged(app, ~)
            app.SamplesPerFrame = app.SamplesperframeEditField.Value;
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
        end
        function DeviceDriverDropDownValueChanged(app, ~)
            app.Driver = app.DeviceDriverDropDown.Value;
        end
        function DeviceDropDownValueChanged(app,~)
            app.Device = app.DeviceDropDown.Value;
        end
        function BitdepthDeviceValueChanged(app,~)
            app.BitDepth = app.BitdepthDevice.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add include
            rootpath = fileparts(mfilename('fullpath'));
            addpath(genpath([rootpath,filesep,'include']))
            
            % Update Auto-Reflow
            %app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            
            % Set columns
            
            % Menu Bar Style
            menus = findobj(app.UIFigure,'type','uimenu');
            for ii = 1:numel(menus)
                menus(ii).Separator = 1; % Add separator between elements
            end
            
        end

        % Menu selected function: HardwareinputoutputMenu
        function HardwareinputoutputMenuSelected(app, event)
            % Prevents re-creating the panel. Control when the same menu option is pressed twice.
            if ~sum(ishandle(app.PanelHardware))
                createPanelHardware(app)
            end
        end

        % Menu selected function: CalibrateinputsMenu
        function CalibrateinputsMenuSelected(app, event)
            delete(app.PanelHardware)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {720, 720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {328, '1x', 330};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLeftMain = uigridlayout(app.LeftPanel);
            app.UPVRobotLeftPanel = uipanel(app.GridLeftMain);
            app.UPVRobotLeftPanel.Title = 'Anechoic chamber - (X,Y,Z) Robot';
            app.GridUPVRobotLeftPanel = uigridlayout(app.UPVRobotLeftPanel);
            app.UPVRobotInfo = uilabel(app.GridUPVRobotLeftPanel);
            app.UPVRobotInfo.Text = {'· This method uses a 3-axis robot located in the anechoic chamber of the Higher Polytechnic School of Gandia.'; '· A map of measurement points is generated to obtain the impulse response at each point.'; '· Impulse responses and all coordinates are stored in text files at the chosen location.'; '· Limitation of this robot: It cannot measure points in Z above the restricted area.'};
            app.UPVRobotDevicechannelsPanel = uipanel(app.GridUPVRobotLeftPanel);
            app.UPVRobotDevicechannelsPanel.Title = 'Audio device channels';
            app.GridUPVRobotDevicechannelsPanel = uigridlayout(app.UPVRobotDevicechannelsPanel);
            app.UPVRobotOutputChPanel = uipanel(app.GridUPVRobotDevicechannelsPanel);
            app.UPVRobotOutputChPanel.Title = 'Output channel';
            app.UPVRobotOutputChGrid = uigridlayout(app.UPVRobotOutputChPanel);
            app.UPVRobotSpeakerDropDown = uidropdown(app.UPVRobotOutputChGrid);
            app.UPVRobotInputChPanel = uipanel(app.GridUPVRobotDevicechannelsPanel);
            app.UPVRobotInputChPanel.Title = 'Input Channel';
            app.UPVRobotInputChGrid = uigridlayout(app.UPVRobotInputChPanel);
            app.UPVRobotMicDropDown = uidropdown(app.UPVRobotInputChGrid);
            app.UPVRobotLeftPanelMeasurementPoints = uipanel(app.GridUPVRobotLeftPanel);
            app.UPVRobotLeftPanelMeasurementPoints.Title = 'Measurement Points';
            app.GridUPVRobotLeftPanelMeasurementPoints = uigridlayout(app.UPVRobotLeftPanelMeasurementPoints);
            app.TabUPVRobotLeftPanelMeasurementPoints = uitabgroup(app.GridUPVRobotLeftPanelMeasurementPoints);
            app.TabUPVRobotLeftPanelMeasurementPointsAutomatic = uitab(app.TabUPVRobotLeftPanelMeasurementPoints);
            app.TabUPVRobotLeftPanelMeasurementPointsAutomatic.Title = 'Auto';
            app.GridLayout10 = uigridlayout(app.TabUPVRobotLeftPanelMeasurementPointsAutomatic);
            app.UPVRobotOriginYEditField = uieditfield(app.GridLayout10, 'numeric');
            app.UPVRobotOriginYEditField.Limits = [0 Inf];
            app.UPVRobotOriginZEditField = uieditfield(app.GridLayout10, 'numeric');
            app.UPVRobotOriginZEditField.Limits = [0 Inf];
            app.UPVRobotOriginXEditField = uieditfield(app.GridLayout10, 'numeric');
            app.UPVRobotOriginXEditField.Limits = [0 Inf];
            app.UPVRobotEndXEditField = uieditfield(app.GridLayout10, 'numeric');
            app.UPVRobotEndXEditField.Limits = [0 3];
            app.UPVRobotEndXEditField.Value = 3;
            app.UPVRobotEndYEditField = uieditfield(app.GridLayout10, 'numeric');
            app.UPVRobotEndYEditField.Limits = [0 3];
            app.UPVRobotEndYEditField.Value = 3;
            app.UPVRobotEndZEditField = uieditfield(app.GridLayout10, 'numeric');
            app.UPVRobotEndZEditField.Limits = [0 1];
            app.UPVRobotEndZEditField.Value = 1;
            app.UPVRobotPointsXEditField = uieditfield(app.GridLayout10, 'numeric');
            app.UPVRobotPointsXEditField.Limits = [1 Inf];
            app.UPVRobotPointsXEditField.RoundFractionalValues = 'on';
            app.UPVRobotPointsXEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotPointsXEditField.Value = 1;
            app.UPVRobotPointsYEditField = uieditfield(app.GridLayout10, 'numeric');
            app.UPVRobotPointsYEditField.Limits = [1 Inf];
            app.UPVRobotPointsYEditField.RoundFractionalValues = 'on';
            app.UPVRobotPointsYEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotPointsYEditField.Value = 1;
            app.UPVRobotPointsZEditField = uieditfield(app.GridLayout10, 'numeric');
            app.UPVRobotPointsZEditField.Limits = [1 Inf];
            app.UPVRobotPointsZEditField.RoundFractionalValues = 'on';
            app.UPVRobotPointsZEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotPointsZEditField.Value = 1;
            app.UPVRoboAutomaticPointsInfo = uilabel(app.GridLayout10);
            app.UPVRoboAutomaticPointsInfo.Text = {'The origin and end point of echa axis in meters. '; 'Resolution indicates the number of measurement points on an axis.'};
            app.UPVRoboAutomaticPointsOriginmLabel = uilabel(app.GridLayout10);
            app.UPVRoboAutomaticPointsOriginmLabel.Text = 'Origin (m)';
            app.UPVRoboAutomaticPointsEndmLabel = uilabel(app.GridLayout10);
            app.UPVRoboAutomaticPointsEndmLabel.Text = 'End (m)';
            app.UPVRoboAutomaticPointsResolutionLabel = uilabel(app.GridLayout10);
            app.UPVRoboAutomaticPointsResolutionLabel.Text = 'Resolution';
            app.UPVRoboAutomaticPointsXAxisLabel = uilabel(app.GridLayout10);
            app.UPVRoboAutomaticPointsXAxisLabel.Text = 'X Axis';
            app.UPVRoboAutomaticPointsYAxisLabel = uilabel(app.GridLayout10);
            app.UPVRoboAutomaticPointsYAxisLabel.Text = 'Y Axis';
            app.UPVRoboAutomaticPointsZaxisLabel_2 = uilabel(app.GridLayout10);
            app.UPVRoboAutomaticPointsZaxisLabel_2.Text = 'Z Axis';
            app.TabUPVRobotLeftPanelMeasurementPointsImport = uitab(app.TabUPVRobotLeftPanelMeasurementPoints);
            app.TabUPVRobotLeftPanelMeasurementPointsImport.Title = 'Import';
            app.UPVRobotImportGrid = uigridlayout(app.TabUPVRobotLeftPanelMeasurementPointsImport);
            app.UPVRobotImportInfo = uilabel(app.UPVRobotImportGrid);
            app.UPVRobotImportInfo.Text = 'The coordinates must be in a tabulated or comma separated text file, with 3 columns, one for each axis.';
            app.UPVRobotImportFileButton = uibutton(app.UPVRobotImportGrid, 'push');
            app.UPVRobotImportFileButton.Text = 'File';
            app.UPVRobotImportFileNameLabel = uilabel(app.UPVRobotImportGrid);
            app.UPVRobotImportFileNameLabel.Text = 'Label4';
            app.UnitsDropDownLabel = uilabel(app.UPVRobotImportGrid);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UPVRobotImportUnitsDropDown = uidropdown(app.UPVRobotImportGrid);
            app.UPVRobotImportUnitsDropDown.Items = {'m', 'cm', 'dm', 'mm'};
            app.UPVRobotImportUnitsDropDown.Value = 'm';
            app.UPVRobotImportXAxisLabel = uilabel(app.UPVRobotImportGrid);
            app.UPVRobotImportXAxisLabel.Text = 'X Axis';
            app.UPVRobotImportYAxisLabel = uilabel(app.UPVRobotImportGrid);
            app.UPVRobotImportYAxisLabel.Text = 'Y Axis';
            app.UPVRobotImportZAxisLabel = uilabel(app.UPVRobotImportGrid);
            app.UPVRobotImportZAxisLabel.Text = 'Z Axis';
            app.UPVRobotImportCheckErrorInfo = uilabel(app.UPVRobotImportGrid);
            app.UPVRobotImportCheckErrorInfo.Text = {'Imported without errors.'; 'Please, click the button below.'; 'tttt'};
            app.UPVRobotImportminmLabel = uilabel(app.UPVRobotImportGrid);
            app.UPVRobotImportminmLabel.Text = 'min (m)';
            app.UPVRobotImportmaxmLabel = uilabel(app.UPVRobotImportGrid);
            app.UPVRobotImportmaxmLabel.Text = 'max (m)';
            app.UPVRobotImportOffsetmLabel = uilabel(app.UPVRobotImportGrid);
            app.UPVRobotImportOffsetmLabel.Text = 'Offset (m)';
            app.UPVRobotImportXmin = uieditfield(app.UPVRobotImportGrid, 'numeric');
            app.UPVRobotImportXmin.ValueDisplayFormat = '%11.2g';
            app.UPVRobotImportXmin.Editable = 'off';
            app.UPVRobotImportXOffset = uieditfield(app.UPVRobotImportGrid, 'numeric');
            app.UPVRobotImportXmax = uieditfield(app.UPVRobotImportGrid, 'numeric');
            app.UPVRobotImportXmax.ValueDisplayFormat = '%11.2g';
            app.UPVRobotImportXmax.Editable = 'off';
            app.UPVRobotImportYmin = uieditfield(app.UPVRobotImportGrid, 'numeric');
            app.UPVRobotImportYmin.ValueDisplayFormat = '%11.2g';
            app.UPVRobotImportYmin.Editable = 'off';
            app.UPVRobotImportYmax = uieditfield(app.UPVRobotImportGrid, 'numeric');
            app.UPVRobotImportYmax.ValueDisplayFormat = '%11.2g';
            app.UPVRobotImportYmax.Editable = 'off';
            app.UPVRobotImportZmin = uieditfield(app.UPVRobotImportGrid, 'numeric');
            app.UPVRobotImportZmin.ValueDisplayFormat = '%11.2g';
            app.UPVRobotImportZmin.Editable = 'off';
            app.UPVRobotImportZmax = uieditfield(app.UPVRobotImportGrid, 'numeric');
            app.UPVRobotImportZmax.ValueDisplayFormat = '%11.2g';
            app.UPVRobotImportZmax.Editable = 'off';
            app.UPVRobotImportYOffset = uieditfield(app.UPVRobotImportGrid, 'numeric');
            app.UPVRobotImportZOffset = uieditfield(app.UPVRobotImportGrid, 'numeric');
            app.TabUPVRobotLeftPanelMeasurementPoinManual = uitab(app.TabUPVRobotLeftPanelMeasurementPoints);
            app.TabUPVRobotLeftPanelMeasurementPoinManual.Title = 'Manual';
            app.UPVRobotLeftPanelMeasurementPoinManualGrid = uigridlayout(app.TabUPVRobotLeftPanelMeasurementPoinManual);
            app.NumberofpointsEditFieldLabel = uilabel(app.UPVRobotLeftPanelMeasurementPoinManualGrid);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.UPVRobotPointManualNumberofpointsEditField = uieditfield(app.UPVRobotLeftPanelMeasurementPoinManualGrid, 'numeric');
            app.UPVRobotPointManualNumberofpointsEditField.Limits = [1 Inf];
            app.UPVRobotPointManualNumberofpointsEditField.RoundFractionalValues = 'on';
            app.UPVRobotPointManualNumberofpointsEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotPointManualNumberofpointsEditField.Value = 1;
            app.UPVRobotPointManualExporttotextButton = uibutton(app.UPVRobotLeftPanelMeasurementPoinManualGrid, 'push');
            app.UPVRobotPointManualExporttotextButton.Text = 'Export to text';
            app.UPVRobotPointManualTable = uitable(app.UPVRobotLeftPanelMeasurementPoinManualGrid);
            app.UPVRobotPointManualTable.ColumnName = {'X (m)'; 'Y (m)'; 'Z (m)'};
            app.UPVRobotPointManualTable.RowName = {'1,2,3'};
            app.UPVRobotPointManualTable.ColumnEditable = true;
            app.TabUPVRobotLeftPanelMeasurementGeometric = uitab(app.TabUPVRobotLeftPanelMeasurementPoints);
            app.TabUPVRobotLeftPanelMeasurementGeometric.Title = 'Geom';
            app.UPVRobotLeftPanelMeasurementGeometricGrid = uigridlayout(app.TabUPVRobotLeftPanelMeasurementGeometric);
            app.ShapeDropDownLabel = uilabel(app.UPVRobotLeftPanelMeasurementGeometricGrid);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.UPVRobotGeometricShapeDropDown = uidropdown(app.UPVRobotLeftPanelMeasurementGeometricGrid);
            app.UPVRobotGeometricShapeDropDown.Items = {'Circumference', 'Circle', 'Rectangle', 'Polygon', 'Sphere'};
            app.UPVRobotGeometricShapeDropDown.Value = 'Circumference';
            app.UPVRobotGeometricSpherePanel = uipanel(app.UPVRobotLeftPanelMeasurementGeometricGrid);
            app.UPVRobotGeometricSphereGrid = uigridlayout(app.UPVRobotGeometricSpherePanel);
            app.XEditFieldLabel_5 = uilabel(app.UPVRobotGeometricSphereGrid);
            app.XEditFieldLabel_5.Text = 'X';
            app.UPVRobotGeometricSphereXEditField = uieditfield(app.UPVRobotGeometricSphereGrid, 'numeric');
            app.UPVRobotGeometricSphereXEditField.Value = 1.5;
            app.YEditFieldLabel_5 = uilabel(app.UPVRobotGeometricSphereGrid);
            app.YEditFieldLabel_5.Text = 'Y';
            app.UPVRobotGeometricSphereYEditField = uieditfield(app.UPVRobotGeometricSphereGrid, 'numeric');
            app.UPVRobotGeometricSphereYEditField.Value = 1.5;
            app.ZEditFieldLabel_5 = uilabel(app.UPVRobotGeometricSphereGrid);
            app.ZEditFieldLabel_5.Text = 'Z';
            app.UPVRobotGeometricSphereZEditField = uieditfield(app.UPVRobotGeometricSphereGrid, 'numeric');
            app.UPVRobotGeometricSphereZEditField.Value = 0.5;
            app.UPVRobotGeometricSphereCentermLabel = uilabel(app.UPVRobotGeometricSphereGrid);
            app.UPVRobotGeometricSphereCentermLabel.Text = 'Center (m)';
            app.UPVRobotGeometricSphereSeparator2 = uilabel(app.UPVRobotGeometricSphereGrid);
            app.UPVRobotGeometricSphereSeparator2.Text = '';
            app.UPVRobotGeometricSphereSubGrid = uigridlayout(app.UPVRobotGeometricSphereGrid);
            app.ResthetaLabel = uilabel(app.UPVRobotGeometricSphereSubGrid);
            app.ResthetaLabel.Text = 'Res (theta)';
            app.UPVRobotGeometricSphereResThetaEditField = uieditfield(app.UPVRobotGeometricSphereSubGrid, 'numeric');
            app.UPVRobotGeometricSphereResThetaEditField.Limits = [1 Inf];
            app.UPVRobotGeometricSphereResThetaEditField.RoundFractionalValues = 'on';
            app.UPVRobotGeometricSphereResThetaEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotGeometricSphereResThetaEditField.Value = 10;
            app.RadiusmEditFieldLabel_3 = uilabel(app.UPVRobotGeometricSphereSubGrid);
            app.RadiusmEditFieldLabel_3.Text = 'Radius (m)';
            app.UPVRobotGeometricSphereRadiusmEditField = uieditfield(app.UPVRobotGeometricSphereSubGrid, 'numeric');
            app.UPVRobotGeometricSphereRadiusmEditField.LowerLimitInclusive = 'off';
            app.UPVRobotGeometricSphereRadiusmEditField.Limits = [0 Inf];
            app.UPVRobotGeometricSphereRadiusmEditField.Value = 0.5;
            app.UPVRobotGeometricSphereSeparator3 = uilabel(app.UPVRobotGeometricSphereSubGrid);
            app.UPVRobotGeometricSphereSeparator3.Text = '';
            app.ResphiLabel = uilabel(app.UPVRobotGeometricSphereSubGrid);
            app.ResphiLabel.Text = 'Res (phi)';
            app.UPVRobotGeometricSphereResPhiEditField = uieditfield(app.UPVRobotGeometricSphereSubGrid, 'numeric');
            app.UPVRobotGeometricSphereResPhiEditField.Limits = [1 Inf];
            app.UPVRobotGeometricSphereResPhiEditField.RoundFractionalValues = 'on';
            app.UPVRobotGeometricSphereResPhiEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotGeometricSphereResPhiEditField.Value = 10;
            app.UPVRobotPathtosavedataPanel = uipanel(app.GridUPVRobotLeftPanel);
            app.UPVRobotPathtosavedataPanel.Title = 'Path to save data';
            app.GridUPVRobotPathtosavedataPanel = uigridlayout(app.UPVRobotPathtosavedataPanel);
            app.UPVRobotPathtosavedataPanelSelectbutton = uibutton(app.GridUPVRobotPathtosavedataPanel, 'push');
            app.UPVRobotPathtosavedataPanelSelectbutton.Text = 'Select path';
            app.UPVRobotPathtosavedataPanelPathlabel = uilabel(app.GridUPVRobotPathtosavedataPanel);
            app.UPVRobotPathtosavedataPanelPathlabel.Text = 'Please select a folder';
            app.UPVRobotGeneratemeasurementpointsButton = uibutton(app.GridUPVRobotLeftPanel, 'push');
            app.UPVRobotGeneratemeasurementpointsButton.Text = 'Generate measurement points';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridCenterMain = uigridlayout(app.CenterPanel);
            app.CenterMainPanelUPVRobot = uipanel(app.GridCenterMain);
            app.CenterMainPanelUPVRobot.Title = 'Map of measurement points';
            app.GridLayout11 = uigridlayout(app.CenterMainPanelUPVRobot);
            app.UPVRobotGeometricCircumferenceGrid = uigridlayout(app.GridLayout11);
            app.XEditFieldLabel = uilabel(app.UPVRobotGeometricCircumferenceGrid);
            app.XEditFieldLabel.Text = 'X';
            app.UPVRobotGeomCircumXEditField = uieditfield(app.UPVRobotGeometricCircumferenceGrid, 'numeric');
            app.UPVRobotGeomCircumXEditField.Value = 1.5;
            app.YEditFieldLabel = uilabel(app.UPVRobotGeometricCircumferenceGrid);
            app.YEditFieldLabel.Text = 'Y';
            app.UPVRobotGeomCircumYEditField = uieditfield(app.UPVRobotGeometricCircumferenceGrid, 'numeric');
            app.UPVRobotGeomCircumYEditField.Value = 1.5;
            app.ZEditFieldLabel = uilabel(app.UPVRobotGeometricCircumferenceGrid);
            app.ZEditFieldLabel.Text = 'Z';
            app.UPVRobotGeomCircumZEditField = uieditfield(app.UPVRobotGeometricCircumferenceGrid, 'numeric');
            app.UPVRobotGeomCircumCentermLabel = uilabel(app.UPVRobotGeometricCircumferenceGrid);
            app.UPVRobotGeomCircumCentermLabel.Text = 'Center (m)';
            app.ResanglesLabel = uilabel(app.UPVRobotGeometricCircumferenceGrid);
            app.ResanglesLabel.Text = 'Res (angles)';
            app.UPVRobotGeomCircumResolutionEditField = uieditfield(app.UPVRobotGeometricCircumferenceGrid, 'numeric');
            app.UPVRobotGeomCircumResolutionEditField.Limits = [1 Inf];
            app.UPVRobotGeomCircumResolutionEditField.RoundFractionalValues = 'on';
            app.UPVRobotGeomCircumResolutionEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotGeomCircumResolutionEditField.Value = 1;
            app.UPVRobotGeometricCircumferenceSeparator2 = uilabel(app.UPVRobotGeometricCircumferenceGrid);
            app.UPVRobotGeometricCircumferenceSeparator2.Text = '';
            app.PlaneDropDownLabel = uilabel(app.UPVRobotGeometricCircumferenceGrid);
            app.PlaneDropDownLabel.Text = 'Plane';
            app.UPVRobotGeomCircumPlaneDropDown = uidropdown(app.UPVRobotGeometricCircumferenceGrid);
            app.UPVRobotGeomCircumPlaneDropDown.Items = {'XY', 'XZ', 'YZ'};
            app.UPVRobotGeomCircumPlaneDropDown.Value = 'XY';
            app.RadiusmEditFieldLabel = uilabel(app.UPVRobotGeometricCircumferenceGrid);
            app.RadiusmEditFieldLabel.Text = 'Radius (m)';
            app.UPVRobotGeomCircumRadiusmEditField = uieditfield(app.UPVRobotGeometricCircumferenceGrid, 'numeric');
            app.UPVRobotGeomCircumRadiusmEditField.LowerLimitInclusive = 'off';
            app.UPVRobotGeomCircumRadiusmEditField.Limits = [0 Inf];
            app.UPVRobotGeomCircumRadiusmEditField.Value = 1;
            app.UPVRobotGeometricCircumferenceSeparator = uilabel(app.UPVRobotGeometricCircumferenceGrid);
            app.UPVRobotGeometricCircumferenceSeparator.Text = '';
            app.UPVRobotGeometricCircleGrid = uigridlayout(app.GridLayout11);
            app.XEditFieldLabel_2 = uilabel(app.UPVRobotGeometricCircleGrid);
            app.XEditFieldLabel_2.Text = 'X';
            app.UPVRobotGeometricCircleXEditField = uieditfield(app.UPVRobotGeometricCircleGrid, 'numeric');
            app.YEditFieldLabel_2 = uilabel(app.UPVRobotGeometricCircleGrid);
            app.YEditFieldLabel_2.Text = 'Y';
            app.UPVRobotGeometricCircleYEditField = uieditfield(app.UPVRobotGeometricCircleGrid, 'numeric');
            app.ZEditFieldLabel_2 = uilabel(app.UPVRobotGeometricCircleGrid);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.UPVRobotGeometricCircleZEditField = uieditfield(app.UPVRobotGeometricCircleGrid, 'numeric');
            app.UPVRobotGeometricCircleCentermLabel = uilabel(app.UPVRobotGeometricCircleGrid);
            app.UPVRobotGeometricCircleCentermLabel.Text = 'Center (m)';
            app.UPVRobotGeometricCircleSeparator2 = uilabel(app.UPVRobotGeometricCircleGrid);
            app.UPVRobotGeometricCircleSeparator2.Text = '';
            app.PlaneDropDownLabel_2 = uilabel(app.UPVRobotGeometricCircleGrid);
            app.PlaneDropDownLabel_2.Text = 'Plane';
            app.UPVRobotGeometricCirclePlaneDropDown = uidropdown(app.UPVRobotGeometricCircleGrid);
            app.UPVRobotGeometricCirclePlaneDropDown.Items = {'XY', 'XZ', 'YZ'};
            app.UPVRobotGeometricCirclePlaneDropDown.Value = 'XY';
            app.UPVRobotGeometricCircleSeparator = uilabel(app.UPVRobotGeometricCircleGrid);
            app.UPVRobotGeometricCircleSeparator.Text = '';
            app.UPVRobotGeometricCircleSubGrid = uigridlayout(app.UPVRobotGeometricCircleGrid);
            app.RadiusmEditFieldLabel_2 = uilabel(app.UPVRobotGeometricCircleSubGrid);
            app.RadiusmEditFieldLabel_2.Text = 'Radius (m)';
            app.UPVRobotGeometricCircleRadiusmEditField = uieditfield(app.UPVRobotGeometricCircleSubGrid, 'numeric');
            app.UPVRobotGeometricCircleRadiusmEditField.LowerLimitInclusive = 'off';
            app.UPVRobotGeometricCircleRadiusmEditField.Limits = [0 Inf];
            app.UPVRobotGeometricCircleRadiusmEditField.Value = 1;
            app.ResanglesLabel_2 = uilabel(app.UPVRobotGeometricCircleSubGrid);
            app.ResanglesLabel_2.Text = 'Res (angles)';
            app.UPVRobotGeometricCircleResolutionEditField = uieditfield(app.UPVRobotGeometricCircleSubGrid, 'numeric');
            app.UPVRobotGeometricCircleResolutionEditField.Limits = [1 Inf];
            app.UPVRobotGeometricCircleResolutionEditField.RoundFractionalValues = 'on';
            app.UPVRobotGeometricCircleResolutionEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotGeometricCircleResolutionEditField.Value = 1;
            app.UPVRobotGeometricCircleSeparator3 = uilabel(app.UPVRobotGeometricCircleSubGrid);
            app.UPVRobotGeometricCircleSeparator3.Text = '';
            app.CirclesEditFieldLabel = uilabel(app.UPVRobotGeometricCircleSubGrid);
            app.CirclesEditFieldLabel.Text = 'Circles';
            app.UPVRobotGeometricCircleCirclesEditField = uieditfield(app.UPVRobotGeometricCircleSubGrid, 'numeric');
            app.UPVRobotGeometricCircleCirclesEditField.Limits = [1 Inf];
            app.UPVRobotGeometricCircleCirclesEditField.RoundFractionalValues = 'on';
            app.UPVRobotGeometricCircleCirclesEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotGeometricCircleCirclesEditField.Value = 4;
            app.UPVRobotGeometricRectanGrid = uigridlayout(app.GridLayout11);
            app.XEditFieldLabel_3 = uilabel(app.UPVRobotGeometricRectanGrid);
            app.XEditFieldLabel_3.Text = 'X';
            app.UPVRobotGeometricRectanXEditField = uieditfield(app.UPVRobotGeometricRectanGrid, 'numeric');
            app.UPVRobotGeometricRectanXEditField.Value = 0.5;
            app.YEditFieldLabel_3 = uilabel(app.UPVRobotGeometricRectanGrid);
            app.YEditFieldLabel_3.Text = 'Y';
            app.UPVRobotGeometricRectanYEditField = uieditfield(app.UPVRobotGeometricRectanGrid, 'numeric');
            app.UPVRobotGeometricRectanYEditField.Value = 0.5;
            app.ZEditFieldLabel_3 = uilabel(app.UPVRobotGeometricRectanGrid);
            app.ZEditFieldLabel_3.Text = 'Z';
            app.UPVRobotGeometricRectanZEditField = uieditfield(app.UPVRobotGeometricRectanGrid, 'numeric');
            app.UPVRobotGeometricRectanCentermLabel = uilabel(app.UPVRobotGeometricRectanGrid);
            app.UPVRobotGeometricRectanCentermLabel.Text = 'Origin (m)';
            app.UPVRobotGeometricRectanSeparator2 = uilabel(app.UPVRobotGeometricRectanGrid);
            app.UPVRobotGeometricRectanSeparator2.Text = '';
            app.PlaneDropDownLabel_3 = uilabel(app.UPVRobotGeometricRectanGrid);
            app.PlaneDropDownLabel_3.Text = 'Plane';
            app.UPVRobotGeometricRectanPlaneDropDown = uidropdown(app.UPVRobotGeometricRectanGrid);
            app.UPVRobotGeometricRectanPlaneDropDown.Items = {'XY', 'XZ', 'YZ'};
            app.UPVRobotGeometricRectanPlaneDropDown.Value = 'XY';
            app.UPVRobotGeometricRectanSeparator = uilabel(app.UPVRobotGeometricRectanGrid);
            app.UPVRobotGeometricRectanSeparator.Text = '';
            app.UPVRobotGeometricRectanSubGrid = uigridlayout(app.UPVRobotGeometricRectanGrid);
            app.HlengthmLabel = uilabel(app.UPVRobotGeometricRectanSubGrid);
            app.HlengthmLabel.Text = 'H length (m)';
            app.UPVRobotGeometricRectanHoriLengthEditField = uieditfield(app.UPVRobotGeometricRectanSubGrid, 'numeric');
            app.UPVRobotGeometricRectanHoriLengthEditField.LowerLimitInclusive = 'off';
            app.UPVRobotGeometricRectanHoriLengthEditField.Limits = [0 Inf];
            app.UPVRobotGeometricRectanHoriLengthEditField.Value = 2;
            app.HresLabel = uilabel(app.UPVRobotGeometricRectanSubGrid);
            app.HresLabel.Text = 'H res';
            app.UPVRobotGeometricRectanHoriResEditField = uieditfield(app.UPVRobotGeometricRectanSubGrid, 'numeric');
            app.UPVRobotGeometricRectanHoriResEditField.Limits = [1 Inf];
            app.UPVRobotGeometricRectanHoriResEditField.RoundFractionalValues = 'on';
            app.UPVRobotGeometricRectanHoriResEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotGeometricRectanHoriResEditField.Value = 10;
            app.UPVRobotGeometricRectanSeparator3 = uilabel(app.UPVRobotGeometricRectanSubGrid);
            app.UPVRobotGeometricRectanSeparator3.Text = '';
            app.VlengthmLabel = uilabel(app.UPVRobotGeometricRectanSubGrid);
            app.VlengthmLabel.Text = 'V length (m)';
            app.UPVRobotGeometricRectanVertLengthEditField = uieditfield(app.UPVRobotGeometricRectanSubGrid, 'numeric');
            app.UPVRobotGeometricRectanVertLengthEditField.LowerLimitInclusive = 'off';
            app.UPVRobotGeometricRectanVertLengthEditField.Limits = [0 Inf];
            app.UPVRobotGeometricRectanVertLengthEditField.Value = 1.5;
            app.VresLabel = uilabel(app.UPVRobotGeometricRectanSubGrid);
            app.VresLabel.Text = 'V res';
            app.UPVRobotGeometricRectanVertResEditField = uieditfield(app.UPVRobotGeometricRectanSubGrid, 'numeric');
            app.UPVRobotGeometricRectanVertResEditField.Limits = [1 Inf];
            app.UPVRobotGeometricRectanVertResEditField.RoundFractionalValues = 'on';
            app.UPVRobotGeometricRectanVertResEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotGeometricRectanVertResEditField.Value = 5;
            app.UPVRobotGeometricPolyGrid = uigridlayout(app.GridLayout11);
            app.XEditFieldLabel_4 = uilabel(app.UPVRobotGeometricPolyGrid);
            app.XEditFieldLabel_4.Text = 'X';
            app.UPVRobotGeometricPolyXEditField = uieditfield(app.UPVRobotGeometricPolyGrid, 'numeric');
            app.UPVRobotGeometricPolyXEditField.Value = 1.5;
            app.YEditFieldLabel_4 = uilabel(app.UPVRobotGeometricPolyGrid);
            app.YEditFieldLabel_4.Text = 'Y';
            app.UPVRobotGeometricPolyYEditField = uieditfield(app.UPVRobotGeometricPolyGrid, 'numeric');
            app.UPVRobotGeometricPolyYEditField.Value = 1.5;
            app.ZEditFieldLabel_4 = uilabel(app.UPVRobotGeometricPolyGrid);
            app.ZEditFieldLabel_4.Text = 'Z';
            app.UPVRobotGeometricPolyZEditField = uieditfield(app.UPVRobotGeometricPolyGrid, 'numeric');
            app.UPVRobotGeometricPolyCentermLabel = uilabel(app.UPVRobotGeometricPolyGrid);
            app.UPVRobotGeometricPolyCentermLabel.Text = 'Center (m)';
            app.UPVRobotGeometricPolySeparator2 = uilabel(app.UPVRobotGeometricPolyGrid);
            app.UPVRobotGeometricPolySeparator2.Text = '';
            app.PlaneDropDownLabel_4 = uilabel(app.UPVRobotGeometricPolyGrid);
            app.PlaneDropDownLabel_4.Text = 'Plane';
            app.UPVRobotGeometricPolyPlaneDropDown = uidropdown(app.UPVRobotGeometricPolyGrid);
            app.UPVRobotGeometricPolyPlaneDropDown.Items = {'XY', 'XZ', 'YZ'};
            app.UPVRobotGeometricPolyPlaneDropDown.Value = 'XY';
            app.UPVRobotGeometricPolySeparator = uilabel(app.UPVRobotGeometricPolyGrid);
            app.UPVRobotGeometricPolySeparator.Text = '';
            app.UPVRobotGeometricPolySubGrid = uigridlayout(app.UPVRobotGeometricPolyGrid);
            app.SidemLabel = uilabel(app.UPVRobotGeometricPolySubGrid);
            app.SidemLabel.Text = 'Side (m)';
            app.UPVRobotGeometricPolySideLenEditField = uieditfield(app.UPVRobotGeometricPolySubGrid, 'numeric');
            app.UPVRobotGeometricPolySideLenEditField.LowerLimitInclusive = 'off';
            app.UPVRobotGeometricPolySideLenEditField.Limits = [0 Inf];
            app.UPVRobotGeometricPolySideLenEditField.Value = 0.5;
            app.ResolutionLabel = uilabel(app.UPVRobotGeometricPolySubGrid);
            app.ResolutionLabel.Text = 'Resolution';
            app.UPVRobotGeometricPolyResolutionEditField = uieditfield(app.UPVRobotGeometricPolySubGrid, 'numeric');
            app.UPVRobotGeometricPolyResolutionEditField.Limits = [1 Inf];
            app.UPVRobotGeometricPolyResolutionEditField.RoundFractionalValues = 'on';
            app.UPVRobotGeometricPolyResolutionEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotGeometricPolyResolutionEditField.Value = 4;
            app.UPVRobotGeometricPolySeparator3 = uilabel(app.UPVRobotGeometricPolySubGrid);
            app.UPVRobotGeometricPolySeparator3.Text = '';
            app.SidesLabel = uilabel(app.UPVRobotGeometricPolySubGrid);
            app.SidesLabel.Text = 'Sides';
            app.UPVRobotGeometricPolySidesEditField = uieditfield(app.UPVRobotGeometricPolySubGrid, 'numeric');
            app.UPVRobotGeometricPolySidesEditField.Limits = [1 Inf];
            app.UPVRobotGeometricPolySidesEditField.RoundFractionalValues = 'on';
            app.UPVRobotGeometricPolySidesEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotGeometricPolySidesEditField.Value = 6;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridRightMain = uigridlayout(app.RightPanel);
            app.SignalparametersPanel = uipanel(app.GridRightMain);
            app.SignalparametersPanel.Title = 'Signal parameters';
            app.GridSignalParameters = uigridlayout(app.SignalparametersPanel);
            app.GridSignalType = uigridlayout(app.GridSignalParameters);
            app.SignaltypeDropDownLabel = uilabel(app.GridSignalType);
            app.SignaltypeDropDownLabel.Text = 'Signal type';
            app.SignaltypeDropDown = uidropdown(app.GridSignalType);
            app.SignaltypeDropDown.Items = {'Sweep', 'MLS', 'White Noise', 'Pink Noise', 'Tone'};
            app.SignaltypeDropDown.Value = 'Sweep';
            app.GridLayout7 = uigridlayout(app.GridSignalParameters);
            app.GridAveragesMeas = uigridlayout(app.GridSignalParameters);
            app.MeasureaveragesEditFieldLabel = uilabel(app.GridAveragesMeas);
            app.MeasureaveragesEditFieldLabel.Text = 'Measure averages';
            app.MeasureaveragesEditField = uieditfield(app.GridAveragesMeas, 'numeric');
            app.MeasureaveragesEditField.Limits = [1 Inf];
            app.MeasureaveragesEditField.RoundFractionalValues = 'on';
            app.MeasureaveragesEditField.ValueDisplayFormat = '%.0f';
            app.MeasureaveragesEditField.Value = 1;
            app.PanelMeasure = uipanel(app.GridRightMain);
            app.GridMeasure = uigridlayout(app.PanelMeasure);
            app.StartmeasureButton = uibutton(app.GridMeasure, 'push');
            app.StartmeasureButton.Text = 'Start measure';
            app.InformationPanel = uipanel(app.GridRightMain);
            app.InformationPanel.Title = 'Information';
            app.GridInformation = uigridlayout(app.InformationPanel);
            app.InfoDeviceLabel = uilabel(app.GridInformation);
            app.InfoDeviceLabel.Text = 'Device';
            app.InfoSampleRateLabel = uilabel(app.GridInformation);
            app.InfoSampleRateLabel.Text = 'Sample rate';
            app.InfoSamplesPerFrameLabel = uilabel(app.GridInformation);
            app.InfoSamplesPerFrameLabel.Text = 'Samples per frame';
            app.InfoInputsCalibratedLabel = uilabel(app.GridInformation);
            app.InfoInputsCalibratedLabel.Text = 'Inputs calibrated';
            app.InfoLatencyLabel = uilabel(app.GridInformation);
            app.InfoLatencyLabel.Text = 'Latency calibrated';
            app.InfoDevice = uilabel(app.GridInformation);
            app.InfoDevice.Text = 'Default';
            app.InfoSampleRate = uilabel(app.GridInformation);
            app.InfoSampleRate.Text = '48000 Hz';
            app.InfoSamplesPerFrame = uilabel(app.GridInformation);
            app.InfoSamplesPerFrame.Text = '1024';
            app.InfoInputsCalibrated = uilabel(app.GridInformation);
            app.InfoInputsCalibrated.Text = 'No';
            app.InfoLatency = uilabel(app.GridInformation);
            app.InfoLatency.Text = 'No (Estimated: 100 ms)';
            app.InfoEmptyLabel1 = uilabel(app.GridInformation);
            app.InfoEmptyLabel1.Text = '';
            app.BitDepthLabel = uilabel(app.GridInformation);
            app.BitDepthLabel.Text = 'Bit Depth';
            app.bitintegerLabel = uilabel(app.GridInformation);
            app.bitintegerLabel.Text = '24-bit integer';
            app.UPVRobotandmeasuredetailsPanel = uipanel(app.GridRightMain);
            app.UPVRobotandmeasuredetailsPanel.Title = 'Robot and measure details';
            app.UPVGridInformation = uigridlayout(app.UPVRobotandmeasuredetailsPanel);
            app.UPVRobotMeasureTimeTotalInfoLabel = uilabel(app.UPVGridInformation);
            app.UPVRobotMeasureTimeTotalInfoLabel.Text = 'Measurement time (total)';
            app.UPVRobotMeasureTimeEachInfoLabel = uilabel(app.UPVGridInformation);
            app.UPVRobotMeasureTimeEachInfoLabel.Text = 'Measurement time (each)';
            app.UPVRobotMeasureTimeEachInfo = uilabel(app.UPVGridInformation);
            app.UPVRobotMeasureTimeEachInfo.Text = '~';
            app.UPVRobotMeasureTimeTotalInfo = uilabel(app.UPVGridInformation);
            app.UPVRobotMeasureTimeTotalInfo.Text = '~';
            app.UPVRobotWarningsAndErrors = uilabel(app.UPVGridInformation);
            app.UPVRobotWarningsAndErrors.Text = 'Without errors.';
            app.UPVRobotButtonPanelInfo = uibutton(app.UPVGridInformation, 'push');
            app.UPVRobotButtonPanelInfo.Text = 'Connect to robot';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.LoadprojectMenu = uimenu(app.FileMenu);
            app.LoadprojectMenu.Text = 'Load project...';
            app.SaveprojectMenu = uimenu(app.FileMenu);
            app.SaveprojectMenu.Text = 'Save project...';
            app.MeasurementMenu = uimenu(app.UIFigure);
            app.MeasurementMenu.Text = 'Measurement';
            app.IndividualchannelsgeneralMenu = uimenu(app.MeasurementMenu);
            app.IndividualchannelsgeneralMenu.Text = 'Individual channels (general)';
            app.ImpedancetubeMenu = uimenu(app.MeasurementMenu);
            app.ImpedancetubeMenu.Text = 'Impedance tube';
            app.TwomicrophoneISO105342Menu = uimenu(app.ImpedancetubeMenu);
            app.TwomicrophoneISO105342Menu.Text = 'Two-microphone (ISO 10534-2)';
            app.OnemicrophoneISO105342Menu = uimenu(app.ImpedancetubeMenu);
            app.OnemicrophoneISO105342Menu.Text = 'One-microphone (ISO 10534-2)';
            app.FixedlocationsMenu = uimenu(app.OnemicrophoneISO105342Menu);
            app.FixedlocationsMenu.Text = 'Fixed locations';
            app.VariablelocationsMenu = uimenu(app.OnemicrophoneISO105342Menu);
            app.VariablelocationsMenu.Text = 'Variable locations';
            app.FourmicrophoneASTME26119Menu = uimenu(app.ImpedancetubeMenu);
            app.FourmicrophoneASTME26119Menu.Text = 'Four-microphone (ASTM E2611-9)';
            app.AnechoicchamberMenu = uimenu(app.MeasurementMenu);
            app.AnechoicchamberMenu.Text = 'Anechoic chamber';
            app.ReflectionabsorptionandimpedanceISO105342modifiedMenu = uimenu(app.AnechoicchamberMenu);
            app.ReflectionabsorptionandimpedanceISO105342modifiedMenu.Text = 'Reflection, absorption and impedance (ISO 10534-2 modified)';
            app.DeviceMenu = uimenu(app.UIFigure);
            app.DeviceMenu.Text = 'Device';
            app.HardwareinputoutputMenu = uimenu(app.DeviceMenu);
            app.HardwareinputoutputMenu.MenuSelectedFcn = createCallbackFcn(app, @HardwareinputoutputMenuSelected, true);
            app.HardwareinputoutputMenu.Text = 'Hardware (input/output)';
            app.CalibrateinputsMenu = uimenu(app.DeviceMenu);
            app.CalibrateinputsMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrateinputsMenuSelected, true);
            app.CalibrateinputsMenu.Text = 'Calibrate inputs';
            app.CalibratedevicelatencyMenu = uimenu(app.DeviceMenu);
            app.CalibratedevicelatencyMenu.Text = 'Calibrate device latency';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UPVRobot_PanelGeom_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end