classdef UPVRobot_PostProcessFreq1D_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLeftMain                    matlab.ui.container.GridLayout
        UPVRobotPostProcessLeftPanel    matlab.ui.container.Panel
        GridUPVRobotPostProcessLeftPanel  matlab.ui.container.GridLayout
        UPVRobotPostProcessShowResultsPanel  matlab.ui.container.Panel
        UPVRobotPostProcessShowResultsGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessAScanBScanButton  matlab.ui.control.Button
        UPVRobotPostProcessTimedomainButton  matlab.ui.control.Button
        UPVRobotPostProcessFrequencydomainButton  matlab.ui.control.Button
        UPVRobotPostProcessFolderPanel  matlab.ui.container.Panel
        UPVRobotPostProcessFolderGrid   matlab.ui.container.GridLayout
        UPVRobotPostProcessFolderLabel  matlab.ui.control.Label
        UPVRobotPostProcessFolderButton  matlab.ui.control.Button
        UPVRobotPostProcessProcessDataPanel  matlab.ui.container.Panel
        UPVRobotPostProcessProcessDataGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessUsemullticoreparallelCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessRemainingTimeInfo  matlab.ui.control.Label
        UPVRobotPostProcessRemainingTimeLabel  matlab.ui.control.Label
        UPVRobotPostProcessEstimatedTimeInfo  matlab.ui.control.Label
        UPVRobotPostProcessEstimatedTimeLabel  matlab.ui.control.Label
        UPVRobotPostProcessRunInfo      matlab.ui.control.Label
        UPVRobotPostProcessProcessButton  matlab.ui.control.Button
        UPVRoboPostProcesstInfo         matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        GridCenterMain                  matlab.ui.container.GridLayout
        CenterMainPanelUPVRobotPostProcess  matlab.ui.container.Panel
        CenterMainGridUPVRobotPostProcess  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreqTabGroup  matlab.ui.container.TabGroup
        UPVRobotPostProcessFreqTab1D    matlab.ui.container.Tab
        GridCenterMainPanelUPVRobotPostProcess1D  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreqTab1DPlots  matlab.ui.container.TabGroup
        UPVRobotPostProcessFreq1DPointsTab  matlab.ui.container.Tab
        UPVRobotPostProcessFreq1DPointsGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq1DPointsViewGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessButtonAxesFreq1D3D  matlab.ui.control.Button
        UPVRobotPostProcessButtonAxesFreq1DYZ  matlab.ui.control.Button
        UPVRobotPostProcessButtonAxesFreq1DXZ  matlab.ui.control.Button
        UPVRobotPostProcessButtonAxesFreq1DXY  matlab.ui.control.Button
        UPVRobotPostProcessFreq1DPointsSelectionGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq1DPointsOriginGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq1DPointsOriginCoordsGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq1DOriCoorXEditFieldLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq1DOriCoorXEditField  matlab.ui.control.NumericEditField
        UPVRobotPostProcessFreq1DOriCoorYEditFieldLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq1DOriCoorYEditField  matlab.ui.control.NumericEditField
        UPVRobotPostProcessFreq1DOriCoorZEditFieldLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq1DOriCoorZEditField  matlab.ui.control.NumericEditField
        UPVRobotPostProcessFreq1DOriCoorCoordinateCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq1DOriIndexPointEditField  matlab.ui.control.NumericEditField
        UPVRobotPostProcessFreq1DOriIndexpointCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq1DOriOriginfordistanceplotLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq1DOriSeparator  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq1DPointsSelectallCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq1DSelectpointsPanel  matlab.ui.container.Panel
        UPVRobotPostProcessFreq1DSelectPointsGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq1DSelectPointsUITable  matlab.ui.control.Table
        UPVRobotPostProcessFreq1DPointsUIaxes  matlab.ui.control.UIAxes
        UPVRobotPostProcessFreq1DDatagraphicsTab  matlab.ui.container.Tab
        UPVRobotPostProcessFreq1DDataCenterGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq1DSPLUIaxes  matlab.ui.control.UIAxes
        UPVRobotPostProcessFreq1DDistanceUIaxes  matlab.ui.control.UIAxes
        UPVRobotPostProcessFreq1DDatainformationTab  matlab.ui.container.Tab
        UPVRobotPostProcessFreq1DDatainformationGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq1DInformationPanel  matlab.ui.container.Panel
        UPVRobotPostProcessFreq1DInformationGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq1DInformationTable  matlab.ui.control.Table
        UPVRobotPostProcessFreqTab2D    matlab.ui.container.Tab
        UPVRobotPostProcessFreqTab3D    matlab.ui.container.Tab
        GridUPVRobotPostProcessBottomCenterGridFreq1D  matlab.ui.container.GridLayout
        UPVRobotPostProcessDParametersPanelFreq1D  matlab.ui.container.Panel
        UPVRobotPostProcessDParametersPanelFreq1DGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq1DInputsignalCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq1DImpulseResponseCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq1DDatasourceLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq1DClipingsaturatedinputsignalCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq1DOverallSPLCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq1DFlytimeDistancefromsourceCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq1DInformationLabel  matlab.ui.control.Label
        UPVRobotPostProcessExportPanel  matlab.ui.container.Panel
        UPVRobotPostProcessExportGrid   matlab.ui.container.GridLayout
        UPVRobotPostProcessExportDataButton  matlab.ui.control.Button
        UPVRobotPostProcessExportGraphicButton  matlab.ui.control.Button
        UPVRobotPostProcessViewParametersPanel  matlab.ui.container.Panel
        UPVRobotPostProcessViewParametersGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessAllfrequenciesCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFrequencyHzEditField  matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridRightMain                   matlab.ui.container.GridLayout
        UPVRobotPostProcessMeasureinformationPanel  matlab.ui.container.Panel
        UPVRobotPostProcessMeasureinformationGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessMeasureinformationMeasuresPanel  matlab.ui.container.Panel
        UPVRobotPostProcessMeasureinformationMeasuresGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessMeasureTimeInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTypeCoordsInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTotalInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTimeLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTypeCoordsLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTotalLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureinformationSignalPanel  matlab.ui.container.Panel
        UPVRobotPostProcessMeasureinformationSignalGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessMeasureAveragesInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureDurationInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTypeSignalInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureAveragesLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureDurationLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTypeSignalLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureinformationHardwarePanel  matlab.ui.container.Panel
        UPVRobotPostProcessMeasureinformationHardwareGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessMeasureSensivityInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureSensivityLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureLatencyInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureLatencyLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureBitdepthInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureBitDepthLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureOutputChInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureInputChInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureSamplesPerFrameInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureSampleRateInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureDeviceInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureOutputChLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureInputChLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureSamplesPerFrameLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureSampleRateLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureDeviceLabel  matlab.ui.control.Label
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        LoadprojectMenu                 matlab.ui.container.Menu
        SaveprojectMenu                 matlab.ui.container.Menu
        MeasurementMenu                 matlab.ui.container.Menu
        IndividualchannelsgeneralMenu   matlab.ui.container.Menu
        ImpedancetubeMenu               matlab.ui.container.Menu
        TwomicrophoneISO105342Menu      matlab.ui.container.Menu
        OnemicrophoneISO105342Menu      matlab.ui.container.Menu
        FixedlocationsMenu              matlab.ui.container.Menu
        VariablelocationsMenu           matlab.ui.container.Menu
        FourmicrophoneASTME26119Menu    matlab.ui.container.Menu
        AnechoicchamberMenu             matlab.ui.container.Menu
        ReflectionabsorptionandimpedanceISO105342modifiedMenu  matlab.ui.container.Menu
        DeviceMenu                      matlab.ui.container.Menu
        HardwareinputoutputMenu         matlab.ui.container.Menu
        CalibrateinputsMenu             matlab.ui.container.Menu
        CalibratedevicelatencyMenu      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        % Hardware Panel Objects
        PanelHardware                   matlab.ui.container.Panel
        GridHardware                    matlab.ui.container.GridLayout
        HardwareGeneralPanel            matlab.ui.container.Panel
        GridHardwareGeneral             matlab.ui.container.GridLayout
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        SamplesperframeEditFieldLabel   matlab.ui.control.Label
        SamplesperframeEditField        matlab.ui.control.NumericEditField
        HardwareDevicePanel             matlab.ui.container.Panel
        GridHardwareDevice              matlab.ui.container.GridLayout
        DriverDropDownLabel             matlab.ui.control.Label
        DeviceDriverDropDown            matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        BitdepthDropDownLabel           matlab.ui.control.Label
        BitdepthDevice                  matlab.ui.control.DropDown
        HardwareOutputPanel             matlab.ui.container.Panel
        GridHardwareOutput              matlab.ui.container.GridLayout
        DriveOutputLabel                matlab.ui.control.Label
        DriverOutput                    matlab.ui.control.DropDown
        DeviceOutputLabel               matlab.ui.control.Label
        DeviceOutput                    matlab.ui.control.DropDown
        BitdepthOutputLabel             matlab.ui.control.Label
        BitdepthOutput                  matlab.ui.control.DropDown
        RefreshavailabledevicesButton   matlab.ui.control.Button
        
    end
    
    properties (Access = public)
        % Hardware
        SampleRate
        SamplesPerFrame
        Driver
        Device
        BitDepth
        
        % Calibrate sensibility
        Sensibility
        
        % Calibrate Latency
        Latency
        
        
        % Signal 
        TypeSignal
        OutSignal
        InputSignal
        % (each signal variable is a structure with its parameters)
        Sweep
        MLS
        WhiteNoise
        PinkNoise
        Tone
        
    end
    
    methods (Access = private)
        
        function myUpdateAppLayout(app, ~)
            
            % Between original width and new panel width
            app.onePanelWidth = 576; % Like Mobile if width < onePanelWidth
            app.twoPanelWidth = 768; % Like Tablet if twoPanelWidth > width > onePanelWidth
            
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid (Like mobile)
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid (Like tablet)
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid (normal)
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
        
        function createPanelHardware(app)
            
            % Main panel
            app.PanelHardware = uipanel(app.GridLeftMain);
            app.PanelHardware.TitlePosition = 'centertop';
            app.PanelHardware.Title = 'Hardware configuration';
            app.PanelHardware.Layout.Row = 1;
            app.PanelHardware.Layout.Column = 1;
            app.PanelHardware.BorderType = 'none';
            app.PanelHardware.FontWeight = 'bold';
            app.PanelHardware.FontSize = 14;
            app.PanelHardware.BackgroundColor = [1 1 1];
            
            % Create GridHardware
            app.GridHardware = uigridlayout(app.PanelHardware);
            app.GridHardware.ColumnWidth = {'1x'};
            app.GridHardware.RowHeight = {'fit', 'fit'};
            app.GridHardware.BackgroundColor = [1 1 1];
            
            % Create HardwareGeneralPanel
            app.HardwareGeneralPanel = uipanel(app.GridHardware);
            app.HardwareGeneralPanel.ForegroundColor = [1 1 1];
            app.HardwareGeneralPanel.BorderType = 'none';
            app.HardwareGeneralPanel.Title = 'General';
            app.HardwareGeneralPanel.BackgroundColor = [1 0.4118 0.1608];
            app.HardwareGeneralPanel.Layout.Row = 1;
            app.HardwareGeneralPanel.Layout.Column = 1;
            app.HardwareGeneralPanel.FontAngle = 'italic';
            app.HardwareGeneralPanel.FontWeight = 'bold';
            
            % Create GridHardwareGeneral
            app.GridHardwareGeneral = uigridlayout(app.HardwareGeneralPanel);
            app.GridHardwareGeneral.ColumnWidth = {'1x', '0.4x'};
            app.GridHardwareGeneral.RowHeight = {'fit', 'fit'};
            app.GridHardwareGeneral.BackgroundColor = [1 1 1];
            
            % Create SamplerateHzEditFieldLabel
            app.SamplerateHzEditFieldLabel = uilabel(app.GridHardwareGeneral);
            app.SamplerateHzEditFieldLabel.Layout.Row = 1;
            app.SamplerateHzEditFieldLabel.Layout.Column = 1;
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            
            % Create SamplerateHzEditField
            app.SamplerateHzEditField = uieditfield(app.GridHardwareGeneral, 'numeric');
            app.SamplerateHzEditField.Limits = [1 Inf];
            app.SamplerateHzEditField.RoundFractionalValues = 'on';
            app.SamplerateHzEditField.ValueDisplayFormat = '%d';
            app.SamplerateHzEditField.Layout.Row = 1;
            app.SamplerateHzEditField.Layout.Column = 2;
            if isempty(app.SampleRate)
            app.SamplerateHzEditField.Value = 48000;
            app.SampleRate = app.SamplerateHzEditField.Value;
            else
                app.SamplerateHzEditField.Value = app.SampleRate;
            end
            app.SamplerateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplerateHzEditFieldValueChanged, true);
            
            % Create SamplesperframeEditFieldLabel
            app.SamplesperframeEditFieldLabel = uilabel(app.GridHardwareGeneral);
            app.SamplesperframeEditFieldLabel.Layout.Row = 2;
            app.SamplesperframeEditFieldLabel.Layout.Column = 1;
            app.SamplesperframeEditFieldLabel.Text = 'Samples per frame';
            
            % Create SamplesperframeEditField
            app.SamplesperframeEditField = uieditfield(app.GridHardwareGeneral, 'numeric');
            app.SamplesperframeEditField.Limits = [1 Inf];
            app.SamplesperframeEditField.RoundFractionalValues = 'on';
            app.SamplesperframeEditField.ValueDisplayFormat = '%d';
            app.SamplesperframeEditField.Layout.Row = 2;
            app.SamplesperframeEditField.Layout.Column = 2;
            if isempty(app.SamplesPerFrame)
            app.SamplesperframeEditField.Value = 1024;
            app.SamplesPerFrame = app.SamplesperframeEditField.Value;
            else
                app.SamplesperframeEditField.Value = app.SamplesPerFrame;
            end
            app.SamplesperframeEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesperframeEditFieldValueChanged, true);
            
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
            
            % Create HardwareDevicePanel
            app.HardwareDevicePanel = uipanel(app.GridHardware);
            app.HardwareDevicePanel.ForegroundColor = [1 1 1];
            app.HardwareDevicePanel.BorderType = 'none';
            app.HardwareDevicePanel.Title = 'Device';
            app.HardwareDevicePanel.BackgroundColor = [0 0 0];
            app.HardwareDevicePanel.Layout.Row = 2;
            app.HardwareDevicePanel.Layout.Column = 1;
            app.HardwareDevicePanel.FontAngle = 'italic';
            app.HardwareDevicePanel.FontWeight = 'bold';
            
            % Create GridHardwareDevice
            app.GridHardwareDevice = uigridlayout(app.HardwareDevicePanel);
            app.GridHardwareDevice.RowHeight = {'fit', 'fit', 'fit','fit'};
            app.GridHardwareDevice.BackgroundColor = [1 1 1];
            
            % Create DriverDropDownLabel
            app.DriverDropDownLabel = uilabel(app.GridHardwareDevice);
            app.DriverDropDownLabel.Layout.Row = 1;
            app.DriverDropDownLabel.Layout.Column = 1;
            app.DriverDropDownLabel.Text = 'Driver';
            
            
            % Create DeviceDriverDropDown
            app.DeviceDriverDropDown = uidropdown(app.GridHardwareDevice);
            app.DeviceDriverDropDown.Layout.Row = 1;
            app.DeviceDriverDropDown.Layout.Column = 2;
            app.DeviceDriverDropDown.BackgroundColor = [1 1 1];
            app.DeviceDriverDropDown.Items = getAvailableDrivers();
            if isempty(app.Driver)
                app.Driver = app.DeviceDriverDropDown.Value;
            else
                app.DeviceDriverDropDown.Value = app.Driver;
            end
            app.DeviceDriverDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDriverDropDownValueChanged, true);
            
            % Create DeviceDropDownLabel
            app.DeviceDropDownLabel = uilabel(app.GridHardwareDevice);
            app.DeviceDropDownLabel.Layout.Row = 2;
            app.DeviceDropDownLabel.Layout.Column = 1;
            app.DeviceDropDownLabel.Text = 'Device';
            
            % Create Device
            app.DeviceDropDown = uidropdown(app.GridHardwareDevice);
            app.DeviceDropDown.Layout.Row = 2;
            app.DeviceDropDown.Layout.Column = 2;
            app.DeviceDropDown.BackgroundColor = [1 1 1];
            app.DeviceDropDown.Items = getAvailableDevices();
            if isempty(app.Device)
                app.Device = app.DeviceDropDown.Value;
            else
                app.DeviceDropDown.Value = app.Device;
            end
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            
            % Create BitdepthDropDownLabel
            app.BitdepthDropDownLabel = uilabel(app.GridHardwareDevice);
            app.BitdepthDropDownLabel.Layout.Row = 3;
            app.BitdepthDropDownLabel.Layout.Column = 1;
            app.BitdepthDropDownLabel.Text = 'Bit depth';
            
            % Create BitdepthDevice
            app.BitdepthDevice = uidropdown(app.GridHardwareDevice);
            app.BitdepthDevice.Layout.Row = 3;
            app.BitdepthDevice.Layout.Column = 2;
            app.BitdepthDevice.BackgroundColor = [1 1 1];
            app.BitdepthDevice.Items = {'24-bit integer','16-bit integer','32-bit float'};
            if isempty(app.BitDepth)
                app.BitDepth = app.BitdepthDevice.Value;
            else
                app.BitdepthDevice.Value = app.BitDepth;
            end
            app.BitdepthDevice.ValueChangedFcn = createCallbackFcn(app, @BitdepthDeviceValueChanged, true);
            
            % Create RefreshButton
            app.RefreshavailabledevicesButton = uibutton(app.GridHardwareDevice, 'push');
            app.RefreshavailabledevicesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshavailabledevicesButtonPushed, true);
            app.RefreshavailabledevicesButton.Text = 'Refresh available devices';
            app.RefreshavailabledevicesButton.BackgroundColor = [1 1 1];
            app.RefreshavailabledevicesButton.Layout.Row = 4;
            app.RefreshavailabledevicesButton.Layout.Column = [1,2];
            
            
        end
        function RefreshavailabledevicesButtonPushed(app, ~)
            app.DeviceDropDown.Items = getAvailableDevices();
            app.DeviceDriverDropDown.Items = getAvailableDrivers();
        end
        function SamplerateHzEditFieldValueChanged(app, ~)
            app.SampleRate = app.SamplerateHzEditField.Value;
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
        end
        function SamplesperframeEditFieldValueChanged(app, ~)
            app.SamplesPerFrame = app.SamplesperframeEditField.Value;
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
        end
        function DeviceDriverDropDownValueChanged(app, ~)
            app.Driver = app.DeviceDriverDropDown.Value;
        end
        function DeviceDropDownValueChanged(app,~)
            app.Device = app.DeviceDropDown.Value;
        end
        function BitdepthDeviceValueChanged(app,~)
            app.BitDepth = app.BitdepthDevice.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add include
            rootpath = fileparts(mfilename('fullpath'));
            addpath(genpath([rootpath,filesep,'include']))
            
            % Update Auto-Reflow
            %app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            
            % Set columns
            
            % Menu Bar Style
            menus = findobj(app.UIFigure,'type','uimenu');
            for ii = 1:numel(menus)
                menus(ii).Separator = 1; % Add separator between elements
            end
            
        end

        % Menu selected function: HardwareinputoutputMenu
        function HardwareinputoutputMenuSelected(app, event)
            % Prevents re-creating the panel. Control when the same menu option is pressed twice.
            if ~sum(ishandle(app.PanelHardware))
                createPanelHardware(app)
            end
        end

        % Menu selected function: CalibrateinputsMenu
        function CalibrateinputsMenuSelected(app, event)
            delete(app.PanelHardware)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {720, 720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {328, '1x', 330};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLeftMain = uigridlayout(app.LeftPanel);
            app.UPVRobotPostProcessLeftPanel = uipanel(app.GridLeftMain);
            app.UPVRobotPostProcessLeftPanel.Title = '(X,Y,Z) Robot - Postprocessing';
            app.GridUPVRobotPostProcessLeftPanel = uigridlayout(app.UPVRobotPostProcessLeftPanel);
            app.UPVRoboPostProcesstInfo = uilabel(app.GridUPVRobotPostProcessLeftPanel);
            app.UPVRoboPostProcesstInfo.Text = '· Postprocessing robot measure';
            app.UPVRobotPostProcessProcessDataPanel = uipanel(app.GridUPVRobotPostProcessLeftPanel);
            app.UPVRobotPostProcessProcessDataPanel.Title = 'Process data';
            app.UPVRobotPostProcessProcessDataGrid = uigridlayout(app.UPVRobotPostProcessProcessDataPanel);
            app.UPVRobotPostProcessProcessButton = uibutton(app.UPVRobotPostProcessProcessDataGrid, 'push');
            app.UPVRobotPostProcessProcessButton.Text = 'Process';
            app.UPVRobotPostProcessRunInfo = uilabel(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessRunInfo.Text = 'Processing...';
            app.UPVRobotPostProcessEstimatedTimeLabel = uilabel(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessEstimatedTimeLabel.Text = 'Estimated Time';
            app.UPVRobotPostProcessEstimatedTimeInfo = uilabel(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessEstimatedTimeInfo.Text = '01:01:01';
            app.UPVRobotPostProcessRemainingTimeLabel = uilabel(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessRemainingTimeLabel.Text = 'Remaining Time';
            app.UPVRobotPostProcessRemainingTimeInfo = uilabel(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessRemainingTimeInfo.Text = '01:01:01';
            app.UPVRobotPostProcessUsemullticoreparallelCheckBox = uicheckbox(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessUsemullticoreparallelCheckBox.Text = 'Use mullticore / parallel';
            app.UPVRobotPostProcessFolderPanel = uipanel(app.GridUPVRobotPostProcessLeftPanel);
            app.UPVRobotPostProcessFolderPanel.Title = 'Project folder';
            app.UPVRobotPostProcessFolderGrid = uigridlayout(app.UPVRobotPostProcessFolderPanel);
            app.UPVRobotPostProcessFolderButton = uibutton(app.UPVRobotPostProcessFolderGrid, 'push');
            app.UPVRobotPostProcessFolderButton.Text = 'Select folder';
            app.UPVRobotPostProcessFolderLabel = uilabel(app.UPVRobotPostProcessFolderGrid);
            app.UPVRobotPostProcessFolderLabel.Text = 'Please select a folder';
            app.UPVRobotPostProcessShowResultsPanel = uipanel(app.GridUPVRobotPostProcessLeftPanel);
            app.UPVRobotPostProcessShowResultsPanel.Title = 'Show results for:';
            app.UPVRobotPostProcessShowResultsGrid = uigridlayout(app.UPVRobotPostProcessShowResultsPanel);
            app.UPVRobotPostProcessFrequencydomainButton = uibutton(app.UPVRobotPostProcessShowResultsGrid, 'push');
            app.UPVRobotPostProcessFrequencydomainButton.Text = 'Frequency domain';
            app.UPVRobotPostProcessTimedomainButton = uibutton(app.UPVRobotPostProcessShowResultsGrid, 'push');
            app.UPVRobotPostProcessTimedomainButton.Text = 'Time domain';
            app.UPVRobotPostProcessAScanBScanButton = uibutton(app.UPVRobotPostProcessShowResultsGrid, 'push');
            app.UPVRobotPostProcessAScanBScanButton.Text = 'A-Scan / B-Scan';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridCenterMain = uigridlayout(app.CenterPanel);
            app.GridUPVRobotPostProcessBottomCenterGridFreq1D = uigridlayout(app.GridCenterMain);
            app.UPVRobotPostProcessViewParametersPanel = uipanel(app.GridUPVRobotPostProcessBottomCenterGridFreq1D);
            app.UPVRobotPostProcessViewParametersPanel.Title = 'View parameters for distance';
            app.UPVRobotPostProcessViewParametersGrid = uigridlayout(app.UPVRobotPostProcessViewParametersPanel);
            app.FrequencyHzEditFieldLabel = uilabel(app.UPVRobotPostProcessViewParametersGrid);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.UPVRobotPostProcessFrequencyHzEditField = uieditfield(app.UPVRobotPostProcessViewParametersGrid, 'numeric');
            app.UPVRobotPostProcessFrequencyHzEditField.Limits = [1 Inf];
            app.UPVRobotPostProcessFrequencyHzEditField.ValueDisplayFormat = '%g';
            app.UPVRobotPostProcessFrequencyHzEditField.Value = 500;
            app.UPVRobotPostProcessAllfrequenciesCheckBox = uicheckbox(app.UPVRobotPostProcessViewParametersGrid);
            app.UPVRobotPostProcessAllfrequenciesCheckBox.Text = 'All frequencies';
            app.UPVRobotPostProcessExportPanel = uipanel(app.GridUPVRobotPostProcessBottomCenterGridFreq1D);
            app.UPVRobotPostProcessExportPanel.Title = 'Export';
            app.UPVRobotPostProcessExportGrid = uigridlayout(app.UPVRobotPostProcessExportPanel);
            app.UPVRobotPostProcessExportGraphicButton = uibutton(app.UPVRobotPostProcessExportGrid, 'push');
            app.UPVRobotPostProcessExportGraphicButton.Text = 'Graphic';
            app.UPVRobotPostProcessExportDataButton = uibutton(app.UPVRobotPostProcessExportGrid, 'push');
            app.UPVRobotPostProcessExportDataButton.Text = 'Data';
            app.UPVRobotPostProcessDParametersPanelFreq1D = uipanel(app.GridUPVRobotPostProcessBottomCenterGridFreq1D);
            app.UPVRobotPostProcessDParametersPanelFreq1D.Title = '1D Parameters';
            app.UPVRobotPostProcessDParametersPanelFreq1DGrid = uigridlayout(app.UPVRobotPostProcessDParametersPanelFreq1D);
            app.UPVRobotPostProcessFreq1DInformationLabel = uilabel(app.UPVRobotPostProcessDParametersPanelFreq1DGrid);
            app.UPVRobotPostProcessFreq1DInformationLabel.Text = 'Information';
            app.UPVRobotPostProcessFreq1DFlytimeDistancefromsourceCheckBox = uicheckbox(app.UPVRobotPostProcessDParametersPanelFreq1DGrid);
            app.UPVRobotPostProcessFreq1DFlytimeDistancefromsourceCheckBox.Text = 'Fly time / Distance from source';
            app.UPVRobotPostProcessFreq1DOverallSPLCheckBox = uicheckbox(app.UPVRobotPostProcessDParametersPanelFreq1DGrid);
            app.UPVRobotPostProcessFreq1DOverallSPLCheckBox.Text = 'Overall SPL';
            app.UPVRobotPostProcessFreq1DClipingsaturatedinputsignalCheckBox = uicheckbox(app.UPVRobotPostProcessDParametersPanelFreq1DGrid);
            app.UPVRobotPostProcessFreq1DClipingsaturatedinputsignalCheckBox.Text = 'Cliping (saturated input signal?)';
            app.UPVRobotPostProcessFreq1DDatasourceLabel = uilabel(app.UPVRobotPostProcessDParametersPanelFreq1DGrid);
            app.UPVRobotPostProcessFreq1DDatasourceLabel.Text = 'Data source';
            app.UPVRobotPostProcessFreq1DImpulseResponseCheckBox = uicheckbox(app.UPVRobotPostProcessDParametersPanelFreq1DGrid);
            app.UPVRobotPostProcessFreq1DImpulseResponseCheckBox.Text = 'Impulse Response';
            app.UPVRobotPostProcessFreq1DInputsignalCheckBox = uicheckbox(app.UPVRobotPostProcessDParametersPanelFreq1DGrid);
            app.UPVRobotPostProcessFreq1DInputsignalCheckBox.Text = 'Input signal';
            app.CenterMainPanelUPVRobotPostProcess = uipanel(app.GridCenterMain);
            app.CenterMainPanelUPVRobotPostProcess.Title = 'Frequency Domain';
            app.CenterMainGridUPVRobotPostProcess = uigridlayout(app.CenterMainPanelUPVRobotPostProcess);
            app.UPVRobotPostProcessFreqTabGroup = uitabgroup(app.CenterMainGridUPVRobotPostProcess);
            app.UPVRobotPostProcessFreqTab1D = uitab(app.UPVRobotPostProcessFreqTabGroup);
            app.UPVRobotPostProcessFreqTab1D.Title = '1D';
            app.GridCenterMainPanelUPVRobotPostProcess1D = uigridlayout(app.UPVRobotPostProcessFreqTab1D);
            app.UPVRobotPostProcessFreqTab1DPlots = uitabgroup(app.GridCenterMainPanelUPVRobotPostProcess1D);
            app.UPVRobotPostProcessFreq1DPointsTab = uitab(app.UPVRobotPostProcessFreqTab1DPlots);
            app.UPVRobotPostProcessFreq1DPointsTab.Title = 'Points';
            app.UPVRobotPostProcessFreq1DPointsGrid = uigridlayout(app.UPVRobotPostProcessFreq1DPointsTab);
            app.UPVRobotPostProcessFreq1DPointsUIaxes = uiaxes(app.UPVRobotPostProcessFreq1DPointsGrid);
            app.UPVRobotPostProcessFreq1DPointsSelectionGrid = uigridlayout(app.UPVRobotPostProcessFreq1DPointsGrid);
            app.UPVRobotPostProcessFreq1DSelectpointsPanel = uipanel(app.UPVRobotPostProcessFreq1DPointsSelectionGrid);
            app.UPVRobotPostProcessFreq1DSelectpointsPanel.Title = 'Select points';
            app.UPVRobotPostProcessFreq1DSelectPointsGrid = uigridlayout(app.UPVRobotPostProcessFreq1DSelectpointsPanel);
            app.UPVRobotPostProcessFreq1DSelectPointsUITable = uitable(app.UPVRobotPostProcessFreq1DSelectPointsGrid);
            app.UPVRobotPostProcessFreq1DSelectPointsUITable.ColumnName = {'Index point'; 'Select'};
            app.UPVRobotPostProcessFreq1DSelectPointsUITable.RowName = {};
            app.UPVRobotPostProcessFreq1DSelectPointsUITable.ColumnEditable = [false true];
            app.UPVRobotPostProcessFreq1DPointsOriginGrid = uigridlayout(app.UPVRobotPostProcessFreq1DPointsSelectionGrid);
            app.UPVRobotPostProcessFreq1DPointsSelectallCheckBox = uicheckbox(app.UPVRobotPostProcessFreq1DPointsOriginGrid);
            app.UPVRobotPostProcessFreq1DPointsSelectallCheckBox.Text = 'Select all';
            app.UPVRobotPostProcessFreq1DOriSeparator = uigridlayout(app.UPVRobotPostProcessFreq1DPointsOriginGrid);
            app.UPVRobotPostProcessFreq1DOriOriginfordistanceplotLabel = uilabel(app.UPVRobotPostProcessFreq1DPointsOriginGrid);
            app.UPVRobotPostProcessFreq1DOriOriginfordistanceplotLabel.Text = 'Origin for distance plot';
            app.UPVRobotPostProcessFreq1DOriIndexpointCheckBox = uicheckbox(app.UPVRobotPostProcessFreq1DPointsOriginGrid);
            app.UPVRobotPostProcessFreq1DOriIndexpointCheckBox.Text = 'From point (index)';
            app.UPVRobotPostProcessFreq1DOriIndexPointEditField = uieditfield(app.UPVRobotPostProcessFreq1DPointsOriginGrid, 'numeric');
            app.UPVRobotPostProcessFreq1DOriIndexPointEditField.Limits = [1 Inf];
            app.UPVRobotPostProcessFreq1DOriIndexPointEditField.RoundFractionalValues = 'on';
            app.UPVRobotPostProcessFreq1DOriIndexPointEditField.ValueDisplayFormat = '%.0f';
            app.UPVRobotPostProcessFreq1DOriIndexPointEditField.Value = 1;
            app.UPVRobotPostProcessFreq1DOriCoorCoordinateCheckBox = uicheckbox(app.UPVRobotPostProcessFreq1DPointsOriginGrid);
            app.UPVRobotPostProcessFreq1DOriCoorCoordinateCheckBox.Text = 'From coordinate';
            app.UPVRobotPostProcessFreq1DPointsOriginCoordsGrid = uigridlayout(app.UPVRobotPostProcessFreq1DPointsOriginGrid);
            app.UPVRobotPostProcessFreq1DOriCoorZEditField = uieditfield(app.UPVRobotPostProcessFreq1DPointsOriginCoordsGrid, 'numeric');
            app.UPVRobotPostProcessFreq1DOriCoorZEditFieldLabel = uilabel(app.UPVRobotPostProcessFreq1DPointsOriginCoordsGrid);
            app.UPVRobotPostProcessFreq1DOriCoorZEditFieldLabel.Text = 'Z';
            app.UPVRobotPostProcessFreq1DOriCoorYEditField = uieditfield(app.UPVRobotPostProcessFreq1DPointsOriginCoordsGrid, 'numeric');
            app.UPVRobotPostProcessFreq1DOriCoorYEditFieldLabel = uilabel(app.UPVRobotPostProcessFreq1DPointsOriginCoordsGrid);
            app.UPVRobotPostProcessFreq1DOriCoorYEditFieldLabel.Text = 'Y';
            app.UPVRobotPostProcessFreq1DOriCoorXEditField = uieditfield(app.UPVRobotPostProcessFreq1DPointsOriginCoordsGrid, 'numeric');
            app.UPVRobotPostProcessFreq1DOriCoorXEditFieldLabel = uilabel(app.UPVRobotPostProcessFreq1DPointsOriginCoordsGrid);
            app.UPVRobotPostProcessFreq1DOriCoorXEditFieldLabel.Text = 'X';
            app.UPVRobotPostProcessFreq1DPointsViewGrid = uigridlayout(app.UPVRobotPostProcessFreq1DPointsGrid);
            app.UPVRobotPostProcessButtonAxesFreq1DXY = uibutton(app.UPVRobotPostProcessFreq1DPointsViewGrid, 'push');
            app.UPVRobotPostProcessButtonAxesFreq1DXY.Text = 'XY';
            app.UPVRobotPostProcessButtonAxesFreq1DXZ = uibutton(app.UPVRobotPostProcessFreq1DPointsViewGrid, 'push');
            app.UPVRobotPostProcessButtonAxesFreq1DXZ.Text = 'XZ';
            app.UPVRobotPostProcessButtonAxesFreq1DYZ = uibutton(app.UPVRobotPostProcessFreq1DPointsViewGrid, 'push');
            app.UPVRobotPostProcessButtonAxesFreq1DYZ.Text = 'YZ';
            app.UPVRobotPostProcessButtonAxesFreq1D3D = uibutton(app.UPVRobotPostProcessFreq1DPointsViewGrid, 'push');
            app.UPVRobotPostProcessButtonAxesFreq1D3D.Text = '3D';
            app.UPVRobotPostProcessFreq1DDatagraphicsTab = uitab(app.UPVRobotPostProcessFreqTab1DPlots);
            app.UPVRobotPostProcessFreq1DDatagraphicsTab.Title = 'Data graphics';
            app.UPVRobotPostProcessFreq1DDataCenterGrid = uigridlayout(app.UPVRobotPostProcessFreq1DDatagraphicsTab);
            app.UPVRobotPostProcessFreq1DDistanceUIaxes = uiaxes(app.UPVRobotPostProcessFreq1DDataCenterGrid);
            app.UPVRobotPostProcessFreq1DSPLUIaxes = uiaxes(app.UPVRobotPostProcessFreq1DDataCenterGrid);
            app.UPVRobotPostProcessFreq1DDatainformationTab = uitab(app.UPVRobotPostProcessFreqTab1DPlots);
            app.UPVRobotPostProcessFreq1DDatainformationTab.Title = 'Data information';
            app.UPVRobotPostProcessFreq1DDatainformationGrid = uigridlayout(app.UPVRobotPostProcessFreq1DDatainformationTab);
            app.UPVRobotPostProcessFreq1DInformationPanel = uipanel(app.UPVRobotPostProcessFreq1DDatainformationGrid);
            app.UPVRobotPostProcessFreq1DInformationPanel.Title = 'Information';
            app.UPVRobotPostProcessFreq1DInformationGrid = uigridlayout(app.UPVRobotPostProcessFreq1DInformationPanel);
            app.UPVRobotPostProcessFreq1DInformationTable = uitable(app.UPVRobotPostProcessFreq1DInformationGrid);
            app.UPVRobotPostProcessFreq1DInformationTable.ColumnName = {'Fly (ms)'; 'Dist (m)'; 'OSPL (dB)'; 'Clip'};
            app.UPVRobotPostProcessFreq1DInformationTable.RowName = {'1'; '2'; '3'};
            app.UPVRobotPostProcessFreq1DInformationTable.ColumnSortable = true;
            app.UPVRobotPostProcessFreqTab2D = uitab(app.UPVRobotPostProcessFreqTabGroup);
            app.UPVRobotPostProcessFreqTab2D.Title = '2D';
            app.UPVRobotPostProcessFreqTab3D = uitab(app.UPVRobotPostProcessFreqTabGroup);
            app.UPVRobotPostProcessFreqTab3D.Title = '3D';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridRightMain = uigridlayout(app.RightPanel);
            app.UPVRobotPostProcessMeasureinformationPanel = uipanel(app.GridRightMain);
            app.UPVRobotPostProcessMeasureinformationPanel.Title = 'Measure information';
            app.UPVRobotPostProcessMeasureinformationGrid = uigridlayout(app.UPVRobotPostProcessMeasureinformationPanel);
            app.UPVRobotPostProcessMeasureinformationHardwarePanel = uipanel(app.UPVRobotPostProcessMeasureinformationGrid);
            app.UPVRobotPostProcessMeasureinformationHardwarePanel.Title = 'Hardware';
            app.UPVRobotPostProcessMeasureinformationHardwareGrid = uigridlayout(app.UPVRobotPostProcessMeasureinformationHardwarePanel);
            app.UPVRobotPostProcessMeasureDeviceLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureDeviceLabel.Text = 'Device';
            app.UPVRobotPostProcessMeasureSampleRateLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSampleRateLabel.Text = 'Sample rate';
            app.UPVRobotPostProcessMeasureSamplesPerFrameLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSamplesPerFrameLabel.Text = 'Samples per frame';
            app.UPVRobotPostProcessMeasureInputChLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureInputChLabel.Text = 'Input Channel';
            app.UPVRobotPostProcessMeasureOutputChLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureOutputChLabel.Text = 'Output Channel';
            app.UPVRobotPostProcessMeasureDeviceInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureDeviceInfo.Text = 'Default';
            app.UPVRobotPostProcessMeasureSampleRateInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSampleRateInfo.Text = '48000 Hz';
            app.UPVRobotPostProcessMeasureSamplesPerFrameInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSamplesPerFrameInfo.Text = '1024';
            app.UPVRobotPostProcessMeasureInputChInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureInputChInfo.Text = '1';
            app.UPVRobotPostProcessMeasureOutputChInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureOutputChInfo.Text = '1';
            app.UPVRobotPostProcessMeasureBitDepthLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureBitDepthLabel.Text = 'Bit Depth';
            app.UPVRobotPostProcessMeasureBitdepthInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureBitdepthInfo.Text = '24-bit integer';
            app.UPVRobotPostProcessMeasureLatencyLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureLatencyLabel.Text = 'Latency';
            app.UPVRobotPostProcessMeasureLatencyInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureLatencyInfo.Text = '30.34 ms';
            app.UPVRobotPostProcessMeasureSensivityLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSensivityLabel.Text = 'Sensivity';
            app.UPVRobotPostProcessMeasureSensivityInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSensivityInfo.Text = 'Not calibrated';
            app.UPVRobotPostProcessMeasureinformationSignalPanel = uipanel(app.UPVRobotPostProcessMeasureinformationGrid);
            app.UPVRobotPostProcessMeasureinformationSignalPanel.Title = 'Signal';
            app.UPVRobotPostProcessMeasureinformationSignalGrid = uigridlayout(app.UPVRobotPostProcessMeasureinformationSignalPanel);
            app.UPVRobotPostProcessMeasureTypeSignalLabel = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureTypeSignalLabel.Text = 'Type';
            app.UPVRobotPostProcessMeasureDurationLabel = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureDurationLabel.Text = 'Total duration';
            app.UPVRobotPostProcessMeasureAveragesLabel = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureAveragesLabel.Text = 'Averages';
            app.UPVRobotPostProcessMeasureTypeSignalInfo = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureTypeSignalInfo.Text = 'Sweep';
            app.UPVRobotPostProcessMeasureDurationInfo = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureDurationInfo.Text = '1.5 s';
            app.UPVRobotPostProcessMeasureAveragesInfo = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureAveragesInfo.Text = '1';
            app.UPVRobotPostProcessMeasureinformationMeasuresPanel = uipanel(app.UPVRobotPostProcessMeasureinformationGrid);
            app.UPVRobotPostProcessMeasureinformationMeasuresPanel.Title = 'Measures';
            app.UPVRobotPostProcessMeasureinformationMeasuresGrid = uigridlayout(app.UPVRobotPostProcessMeasureinformationMeasuresPanel);
            app.UPVRobotPostProcessMeasureTotalLabel = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTotalLabel.Text = 'Total';
            app.UPVRobotPostProcessMeasureTypeCoordsLabel = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTypeCoordsLabel.Text = 'Type coords';
            app.UPVRobotPostProcessMeasureTimeLabel = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTimeLabel.Text = 'Measure time';
            app.UPVRobotPostProcessMeasureTotalInfo = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTotalInfo.Text = '2500';
            app.UPVRobotPostProcessMeasureTypeCoordsInfo = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTypeCoordsInfo.Text = 'Automatic';
            app.UPVRobotPostProcessMeasureTimeInfo = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTimeInfo.Text = '02:05:45';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.LoadprojectMenu = uimenu(app.FileMenu);
            app.LoadprojectMenu.Text = 'Load project...';
            app.SaveprojectMenu = uimenu(app.FileMenu);
            app.SaveprojectMenu.Text = 'Save project...';
            app.MeasurementMenu = uimenu(app.UIFigure);
            app.MeasurementMenu.Text = 'Measurement';
            app.IndividualchannelsgeneralMenu = uimenu(app.MeasurementMenu);
            app.IndividualchannelsgeneralMenu.Text = 'Individual channels (general)';
            app.ImpedancetubeMenu = uimenu(app.MeasurementMenu);
            app.ImpedancetubeMenu.Text = 'Impedance tube';
            app.TwomicrophoneISO105342Menu = uimenu(app.ImpedancetubeMenu);
            app.TwomicrophoneISO105342Menu.Text = 'Two-microphone (ISO 10534-2)';
            app.OnemicrophoneISO105342Menu = uimenu(app.ImpedancetubeMenu);
            app.OnemicrophoneISO105342Menu.Text = 'One-microphone (ISO 10534-2)';
            app.FixedlocationsMenu = uimenu(app.OnemicrophoneISO105342Menu);
            app.FixedlocationsMenu.Text = 'Fixed locations';
            app.VariablelocationsMenu = uimenu(app.OnemicrophoneISO105342Menu);
            app.VariablelocationsMenu.Text = 'Variable locations';
            app.FourmicrophoneASTME26119Menu = uimenu(app.ImpedancetubeMenu);
            app.FourmicrophoneASTME26119Menu.Text = 'Four-microphone (ASTM E2611-9)';
            app.AnechoicchamberMenu = uimenu(app.MeasurementMenu);
            app.AnechoicchamberMenu.Text = 'Anechoic chamber';
            app.ReflectionabsorptionandimpedanceISO105342modifiedMenu = uimenu(app.AnechoicchamberMenu);
            app.ReflectionabsorptionandimpedanceISO105342modifiedMenu.Text = 'Reflection, absorption and impedance (ISO 10534-2 modified)';
            app.DeviceMenu = uimenu(app.UIFigure);
            app.DeviceMenu.Text = 'Device';
            app.HardwareinputoutputMenu = uimenu(app.DeviceMenu);
            app.HardwareinputoutputMenu.MenuSelectedFcn = createCallbackFcn(app, @HardwareinputoutputMenuSelected, true);
            app.HardwareinputoutputMenu.Text = 'Hardware (input/output)';
            app.CalibrateinputsMenu = uimenu(app.DeviceMenu);
            app.CalibrateinputsMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrateinputsMenuSelected, true);
            app.CalibrateinputsMenu.Text = 'Calibrate inputs';
            app.CalibratedevicelatencyMenu = uimenu(app.DeviceMenu);
            app.CalibratedevicelatencyMenu.Text = 'Calibrate device latency';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UPVRobot_PostProcessFreq1D_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end