classdef UPVRobot_PostProcessFreq3D_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLeftMain                    matlab.ui.container.GridLayout
        UPVRobotPostProcessLeftPanel    matlab.ui.container.Panel
        GridUPVRobotPostProcessLeftPanel  matlab.ui.container.GridLayout
        UPVRobotPostProcessShowResultsPanel  matlab.ui.container.Panel
        UPVRobotPostProcessShowResultsGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessAScanBScanButton  matlab.ui.control.Button
        UPVRobotPostProcessTimedomainButton  matlab.ui.control.Button
        UPVRobotPostProcessFrequencydomainButton  matlab.ui.control.Button
        UPVRobotPostProcessFolderPanel  matlab.ui.container.Panel
        UPVRobotPostProcessFolderGrid   matlab.ui.container.GridLayout
        UPVRobotPostProcessFolderLabel  matlab.ui.control.Label
        UPVRobotPostProcessFolderButton  matlab.ui.control.Button
        UPVRobotPostProcessProcessDataPanel  matlab.ui.container.Panel
        UPVRobotPostProcessProcessDataGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessUsemullticoreparallelCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessRemainingTimeInfo  matlab.ui.control.Label
        UPVRobotPostProcessRemainingTimeLabel  matlab.ui.control.Label
        UPVRobotPostProcessEstimatedTimeInfo  matlab.ui.control.Label
        UPVRobotPostProcessEstimatedTimeLabel  matlab.ui.control.Label
        UPVRobotPostProcessRunInfo      matlab.ui.control.Label
        UPVRobotPostProcessProcessButton  matlab.ui.control.Button
        UPVRoboPostProcesstInfo         matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        GridCenterMain                  matlab.ui.container.GridLayout
        CenterMainPanelUPVRobotPostProcess  matlab.ui.container.Panel
        CenterMainGridUPVRobotPostProcess  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreqTabGroup  matlab.ui.container.TabGroup
        UPVRobotPostProcessFreqTab1D    matlab.ui.container.Tab
        UPVRobotPostProcessFreqTab2D    matlab.ui.container.Tab
        UPVRobotPostProcessFreqTab3D    matlab.ui.container.Tab
        GridCenterMainPanelUPVRobotPostProcessFreq3D  matlab.ui.container.GridLayout
        UPVRobotPostProcessButtonAxes3DFreq3D  matlab.ui.control.Button
        UPVRobotPostProcessButtonAxesYZFreq3D  matlab.ui.control.Button
        UPVRobotPostProcessButtonAxesXZFreq3D  matlab.ui.control.Button
        UPVRobotPostProcessButtonAxesXYFreq3D  matlab.ui.control.Button
        UPVRobotPostProcessUIAxesFreq3D  matlab.ui.control.UIAxes
        GridUPVRobotPostProcessBottomCenterGridFreq3D  matlab.ui.container.GridLayout
        UPVRobotPostProcessDParametersPanelFreq3D  matlab.ui.container.Panel
        UPVRobotPostProcessDParametersPanelFreq3DGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessDParametersPanelFreq3DTabs  matlab.ui.container.TabGroup
        UPVRobotPostProcessDParametersPanelFreq3D3SliceTab  matlab.ui.container.Tab
        UPVRobotPostProcessFreq3D3SliceGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq3D3SliceViewOpsGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq3D3SliceLightCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3D3SliceBoundaryCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3D3SlicePointsCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3D3SliceYZSliceSliderXInfo  matlab.ui.control.Label
        UPVRobotPostProcessFreq3D3SliceXZSliceSliderYInfo  matlab.ui.control.Label
        UPVRobotPostProcessFreq3D3SliceXYSliceSliderZInfo  matlab.ui.control.Label
        UPVRobotPostProcessFreq3D3SliceViewLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq3D3SliceYZSliceSlider  matlab.ui.control.Slider
        UPVRobotPostProcessFreq3D3SliceYZSliceSliderLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq3D3SliceXZSliceSlider  matlab.ui.control.Slider
        UPVRobotPostProcessFreq3D3SliceXZSliceSliderLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq3D3SliceXYSliceSlider  matlab.ui.control.Slider
        UPVRobotPostProcessFreq3D3SliceXYSliceSliderLabel  matlab.ui.control.Label
        UPVRobotPostProcessDParametersPanelFreq3DMultiTab  matlab.ui.container.Tab
        UPVRobotPostProcessFreq3DMultiGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq3DMultiViewOpsGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq3DMultiLightCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3DMultiBoundaryCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3DMultiPointsCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3DMultiViewLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq3DMultiYZSlicesEditField  matlab.ui.control.EditField
        UPVRobotPostProcessFreq3DMultiYZSlicesEditFieldLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq3DMultiXZSlicesEditField  matlab.ui.control.EditField
        UPVRobotPostProcessFreq3DMultiXZSlicesEditFieldLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq3DMultiXYSlicesEditField  matlab.ui.control.EditField
        UPVRobotPostProcessFreq3DMultiXYSlicesEditFieldLabel  matlab.ui.control.Label
        UPVRobotPostProcessDParametersPanelFreq3DIsoTab  matlab.ui.container.Tab
        UPVRobotPostProcessFreq3DIsoGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessFreq3DIsoLightCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3DIsoSmoothCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3DIsoSurfaceLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq3DIsoupperCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3DIsoPointsCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3DIsoViewLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq3DIsoFillLabel  matlab.ui.control.Label
        UPVRobotPostProcessFreq3DIsolowerCheckBox  matlab.ui.control.CheckBox
        UPVRobotPostProcessFreq3DIsoSPLdBEditField  matlab.ui.control.NumericEditField
        UPVRobotPostProcessFreq3DIsoSPLdBEditFieldLabel  matlab.ui.control.Label
        UPVRobotPostProcessExportPanelFreq3D  matlab.ui.container.Panel
        UPVRobotPostProcessExportGridFreq3D  matlab.ui.container.GridLayout
        UPVRobotPostProcessExportDataButtonFreq3D  matlab.ui.control.Button
        UPVRobotPostProcessExportGraphicButtonFreq3D  matlab.ui.control.Button
        UPVRobotPostProcessViewParametersPanelFreq3D  matlab.ui.container.Panel
        UPVRobotPostProcessViewParametersGridFreq3D  matlab.ui.container.GridLayout
        UPVRobotPostProcessMaxrangeEditFieldFreq3D  matlab.ui.control.NumericEditField
        MaxrangeEditFieldLabelFreq3D    matlab.ui.control.Label
        UPVRobotPostProcessMinrangeEditFieldFreq3D  matlab.ui.control.NumericEditField
        MinrangeEditFieldLabelFreq3D    matlab.ui.control.Label
        UPVRobotPostProcessColorrangeautomaticCheckBoxFreq3D  matlab.ui.control.CheckBox
        UPVRobotPostProcessFrequencyHzEditFieldFreq3D  matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabelFreq3D  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridRightMain                   matlab.ui.container.GridLayout
        UPVRobotPostProcessMeasureinformationPanel  matlab.ui.container.Panel
        UPVRobotPostProcessMeasureinformationGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessMeasureinformationMeasuresPanel  matlab.ui.container.Panel
        UPVRobotPostProcessMeasureinformationMeasuresGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessMeasureTimeInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTypeCoordsInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTotalInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTimeLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTypeCoordsLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTotalLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureinformationSignalPanel  matlab.ui.container.Panel
        UPVRobotPostProcessMeasureinformationSignalGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessMeasureAveragesInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureDurationInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTypeSignalInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureAveragesLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureDurationLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureTypeSignalLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureinformationHardwarePanel  matlab.ui.container.Panel
        UPVRobotPostProcessMeasureinformationHardwareGrid  matlab.ui.container.GridLayout
        UPVRobotPostProcessMeasureSensivityInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureSensivityLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureLatencyInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureLatencyLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureBitdepthInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureBitDepthLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureOutputChInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureInputChInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureSamplesPerFrameInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureSampleRateInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureDeviceInfo  matlab.ui.control.Label
        UPVRobotPostProcessMeasureOutputChLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureInputChLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureSamplesPerFrameLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureSampleRateLabel  matlab.ui.control.Label
        UPVRobotPostProcessMeasureDeviceLabel  matlab.ui.control.Label
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        LoadprojectMenu                 matlab.ui.container.Menu
        SaveprojectMenu                 matlab.ui.container.Menu
        MeasurementMenu                 matlab.ui.container.Menu
        IndividualchannelsgeneralMenu   matlab.ui.container.Menu
        ImpedancetubeMenu               matlab.ui.container.Menu
        TwomicrophoneISO105342Menu      matlab.ui.container.Menu
        OnemicrophoneISO105342Menu      matlab.ui.container.Menu
        FixedlocationsMenu              matlab.ui.container.Menu
        VariablelocationsMenu           matlab.ui.container.Menu
        FourmicrophoneASTME26119Menu    matlab.ui.container.Menu
        AnechoicchamberMenu             matlab.ui.container.Menu
        ReflectionabsorptionandimpedanceISO105342modifiedMenu  matlab.ui.container.Menu
        DeviceMenu                      matlab.ui.container.Menu
        HardwareinputoutputMenu         matlab.ui.container.Menu
        CalibrateinputsMenu             matlab.ui.container.Menu
        CalibratedevicelatencyMenu      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        % Hardware Panel Objects
        PanelHardware                   matlab.ui.container.Panel
        GridHardware                    matlab.ui.container.GridLayout
        HardwareGeneralPanel            matlab.ui.container.Panel
        GridHardwareGeneral             matlab.ui.container.GridLayout
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        SamplesperframeEditFieldLabel   matlab.ui.control.Label
        SamplesperframeEditField        matlab.ui.control.NumericEditField
        HardwareDevicePanel             matlab.ui.container.Panel
        GridHardwareDevice              matlab.ui.container.GridLayout
        DriverDropDownLabel             matlab.ui.control.Label
        DeviceDriverDropDown            matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        BitdepthDropDownLabel           matlab.ui.control.Label
        BitdepthDevice                  matlab.ui.control.DropDown
        HardwareOutputPanel             matlab.ui.container.Panel
        GridHardwareOutput              matlab.ui.container.GridLayout
        DriveOutputLabel                matlab.ui.control.Label
        DriverOutput                    matlab.ui.control.DropDown
        DeviceOutputLabel               matlab.ui.control.Label
        DeviceOutput                    matlab.ui.control.DropDown
        BitdepthOutputLabel             matlab.ui.control.Label
        BitdepthOutput                  matlab.ui.control.DropDown
        RefreshavailabledevicesButton   matlab.ui.control.Button
        
    end
    
    properties (Access = public)
        % Hardware
        SampleRate
        SamplesPerFrame
        Driver
        Device
        BitDepth
        
        % Calibrate sensibility
        Sensibility
        
        % Calibrate Latency
        Latency
        
        
        % Signal 
        TypeSignal
        OutSignal
        InputSignal
        % (each signal variable is a structure with its parameters)
        Sweep
        MLS
        WhiteNoise
        PinkNoise
        Tone
        
    end
    
    methods (Access = private)
        
        function myUpdateAppLayout(app, ~)
            
            % Between original width and new panel width
            app.onePanelWidth = 576; % Like Mobile if width < onePanelWidth
            app.twoPanelWidth = 768; % Like Tablet if twoPanelWidth > width > onePanelWidth
            
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid (Like mobile)
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid (Like tablet)
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid (normal)
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
        
        function createPanelHardware(app)
            
            % Main panel
            app.PanelHardware = uipanel(app.GridLeftMain);
            app.PanelHardware.TitlePosition = 'centertop';
            app.PanelHardware.Title = 'Hardware configuration';
            app.PanelHardware.Layout.Row = 1;
            app.PanelHardware.Layout.Column = 1;
            app.PanelHardware.BorderType = 'none';
            app.PanelHardware.FontWeight = 'bold';
            app.PanelHardware.FontSize = 14;
            app.PanelHardware.BackgroundColor = [1 1 1];
            
            % Create GridHardware
            app.GridHardware = uigridlayout(app.PanelHardware);
            app.GridHardware.ColumnWidth = {'1x'};
            app.GridHardware.RowHeight = {'fit', 'fit'};
            app.GridHardware.BackgroundColor = [1 1 1];
            
            % Create HardwareGeneralPanel
            app.HardwareGeneralPanel = uipanel(app.GridHardware);
            app.HardwareGeneralPanel.ForegroundColor = [1 1 1];
            app.HardwareGeneralPanel.BorderType = 'none';
            app.HardwareGeneralPanel.Title = 'General';
            app.HardwareGeneralPanel.BackgroundColor = [1 0.4118 0.1608];
            app.HardwareGeneralPanel.Layout.Row = 1;
            app.HardwareGeneralPanel.Layout.Column = 1;
            app.HardwareGeneralPanel.FontAngle = 'italic';
            app.HardwareGeneralPanel.FontWeight = 'bold';
            
            % Create GridHardwareGeneral
            app.GridHardwareGeneral = uigridlayout(app.HardwareGeneralPanel);
            app.GridHardwareGeneral.ColumnWidth = {'1x', '0.4x'};
            app.GridHardwareGeneral.RowHeight = {'fit', 'fit'};
            app.GridHardwareGeneral.BackgroundColor = [1 1 1];
            
            % Create SamplerateHzEditFieldLabel
            app.SamplerateHzEditFieldLabel = uilabel(app.GridHardwareGeneral);
            app.SamplerateHzEditFieldLabel.Layout.Row = 1;
            app.SamplerateHzEditFieldLabel.Layout.Column = 1;
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            
            % Create SamplerateHzEditField
            app.SamplerateHzEditField = uieditfield(app.GridHardwareGeneral, 'numeric');
            app.SamplerateHzEditField.Limits = [1 Inf];
            app.SamplerateHzEditField.RoundFractionalValues = 'on';
            app.SamplerateHzEditField.ValueDisplayFormat = '%d';
            app.SamplerateHzEditField.Layout.Row = 1;
            app.SamplerateHzEditField.Layout.Column = 2;
            if isempty(app.SampleRate)
            app.SamplerateHzEditField.Value = 48000;
            app.SampleRate = app.SamplerateHzEditField.Value;
            else
                app.SamplerateHzEditField.Value = app.SampleRate;
            end
            app.SamplerateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplerateHzEditFieldValueChanged, true);
            
            % Create SamplesperframeEditFieldLabel
            app.SamplesperframeEditFieldLabel = uilabel(app.GridHardwareGeneral);
            app.SamplesperframeEditFieldLabel.Layout.Row = 2;
            app.SamplesperframeEditFieldLabel.Layout.Column = 1;
            app.SamplesperframeEditFieldLabel.Text = 'Samples per frame';
            
            % Create SamplesperframeEditField
            app.SamplesperframeEditField = uieditfield(app.GridHardwareGeneral, 'numeric');
            app.SamplesperframeEditField.Limits = [1 Inf];
            app.SamplesperframeEditField.RoundFractionalValues = 'on';
            app.SamplesperframeEditField.ValueDisplayFormat = '%d';
            app.SamplesperframeEditField.Layout.Row = 2;
            app.SamplesperframeEditField.Layout.Column = 2;
            if isempty(app.SamplesPerFrame)
            app.SamplesperframeEditField.Value = 1024;
            app.SamplesPerFrame = app.SamplesperframeEditField.Value;
            else
                app.SamplesperframeEditField.Value = app.SamplesPerFrame;
            end
            app.SamplesperframeEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesperframeEditFieldValueChanged, true);
            
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
            
            % Create HardwareDevicePanel
            app.HardwareDevicePanel = uipanel(app.GridHardware);
            app.HardwareDevicePanel.ForegroundColor = [1 1 1];
            app.HardwareDevicePanel.BorderType = 'none';
            app.HardwareDevicePanel.Title = 'Device';
            app.HardwareDevicePanel.BackgroundColor = [0 0 0];
            app.HardwareDevicePanel.Layout.Row = 2;
            app.HardwareDevicePanel.Layout.Column = 1;
            app.HardwareDevicePanel.FontAngle = 'italic';
            app.HardwareDevicePanel.FontWeight = 'bold';
            
            % Create GridHardwareDevice
            app.GridHardwareDevice = uigridlayout(app.HardwareDevicePanel);
            app.GridHardwareDevice.RowHeight = {'fit', 'fit', 'fit','fit'};
            app.GridHardwareDevice.BackgroundColor = [1 1 1];
            
            % Create DriverDropDownLabel
            app.DriverDropDownLabel = uilabel(app.GridHardwareDevice);
            app.DriverDropDownLabel.Layout.Row = 1;
            app.DriverDropDownLabel.Layout.Column = 1;
            app.DriverDropDownLabel.Text = 'Driver';
            
            
            % Create DeviceDriverDropDown
            app.DeviceDriverDropDown = uidropdown(app.GridHardwareDevice);
            app.DeviceDriverDropDown.Layout.Row = 1;
            app.DeviceDriverDropDown.Layout.Column = 2;
            app.DeviceDriverDropDown.BackgroundColor = [1 1 1];
            app.DeviceDriverDropDown.Items = getAvailableDrivers();
            if isempty(app.Driver)
                app.Driver = app.DeviceDriverDropDown.Value;
            else
                app.DeviceDriverDropDown.Value = app.Driver;
            end
            app.DeviceDriverDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDriverDropDownValueChanged, true);
            
            % Create DeviceDropDownLabel
            app.DeviceDropDownLabel = uilabel(app.GridHardwareDevice);
            app.DeviceDropDownLabel.Layout.Row = 2;
            app.DeviceDropDownLabel.Layout.Column = 1;
            app.DeviceDropDownLabel.Text = 'Device';
            
            % Create Device
            app.DeviceDropDown = uidropdown(app.GridHardwareDevice);
            app.DeviceDropDown.Layout.Row = 2;
            app.DeviceDropDown.Layout.Column = 2;
            app.DeviceDropDown.BackgroundColor = [1 1 1];
            app.DeviceDropDown.Items = getAvailableDevices();
            if isempty(app.Device)
                app.Device = app.DeviceDropDown.Value;
            else
                app.DeviceDropDown.Value = app.Device;
            end
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            
            % Create BitdepthDropDownLabel
            app.BitdepthDropDownLabel = uilabel(app.GridHardwareDevice);
            app.BitdepthDropDownLabel.Layout.Row = 3;
            app.BitdepthDropDownLabel.Layout.Column = 1;
            app.BitdepthDropDownLabel.Text = 'Bit depth';
            
            % Create BitdepthDevice
            app.BitdepthDevice = uidropdown(app.GridHardwareDevice);
            app.BitdepthDevice.Layout.Row = 3;
            app.BitdepthDevice.Layout.Column = 2;
            app.BitdepthDevice.BackgroundColor = [1 1 1];
            app.BitdepthDevice.Items = {'24-bit integer','16-bit integer','32-bit float'};
            if isempty(app.BitDepth)
                app.BitDepth = app.BitdepthDevice.Value;
            else
                app.BitdepthDevice.Value = app.BitDepth;
            end
            app.BitdepthDevice.ValueChangedFcn = createCallbackFcn(app, @BitdepthDeviceValueChanged, true);
            
            % Create RefreshButton
            app.RefreshavailabledevicesButton = uibutton(app.GridHardwareDevice, 'push');
            app.RefreshavailabledevicesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshavailabledevicesButtonPushed, true);
            app.RefreshavailabledevicesButton.Text = 'Refresh available devices';
            app.RefreshavailabledevicesButton.BackgroundColor = [1 1 1];
            app.RefreshavailabledevicesButton.Layout.Row = 4;
            app.RefreshavailabledevicesButton.Layout.Column = [1,2];
            
            
        end
        function RefreshavailabledevicesButtonPushed(app, ~)
            app.DeviceDropDown.Items = getAvailableDevices();
            app.DeviceDriverDropDown.Items = getAvailableDrivers();
        end
        function SamplerateHzEditFieldValueChanged(app, ~)
            app.SampleRate = app.SamplerateHzEditField.Value;
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
        end
        function SamplesperframeEditFieldValueChanged(app, ~)
            app.SamplesPerFrame = app.SamplesperframeEditField.Value;
            % Estimate latency if empty
            if isempty(app.Latency)
                app.Latency = app.SampleRate*app.SamplesPerFrame*2;
            end
        end
        function DeviceDriverDropDownValueChanged(app, ~)
            app.Driver = app.DeviceDriverDropDown.Value;
        end
        function DeviceDropDownValueChanged(app,~)
            app.Device = app.DeviceDropDown.Value;
        end
        function BitdepthDeviceValueChanged(app,~)
            app.BitDepth = app.BitdepthDevice.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add include
            rootpath = fileparts(mfilename('fullpath'));
            addpath(genpath([rootpath,filesep,'include']))
            
            % Update Auto-Reflow
            %app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            
            % Set columns
            
            % Menu Bar Style
            menus = findobj(app.UIFigure,'type','uimenu');
            for ii = 1:numel(menus)
                menus(ii).Separator = 1; % Add separator between elements
            end
            
        end

        % Menu selected function: HardwareinputoutputMenu
        function HardwareinputoutputMenuSelected(app, event)
            % Prevents re-creating the panel. Control when the same menu option is pressed twice.
            if ~sum(ishandle(app.PanelHardware))
                createPanelHardware(app)
            end
        end

        % Menu selected function: CalibrateinputsMenu
        function CalibrateinputsMenuSelected(app, event)
            delete(app.PanelHardware)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {720, 720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {328, '1x', 330};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLeftMain = uigridlayout(app.LeftPanel);
            app.UPVRobotPostProcessLeftPanel = uipanel(app.GridLeftMain);
            app.UPVRobotPostProcessLeftPanel.Title = '(X,Y,Z) Robot - Postprocessing';
            app.GridUPVRobotPostProcessLeftPanel = uigridlayout(app.UPVRobotPostProcessLeftPanel);
            app.UPVRoboPostProcesstInfo = uilabel(app.GridUPVRobotPostProcessLeftPanel);
            app.UPVRoboPostProcesstInfo.Text = '· Postprocessing robot measure';
            app.UPVRobotPostProcessProcessDataPanel = uipanel(app.GridUPVRobotPostProcessLeftPanel);
            app.UPVRobotPostProcessProcessDataPanel.Title = 'Process data';
            app.UPVRobotPostProcessProcessDataGrid = uigridlayout(app.UPVRobotPostProcessProcessDataPanel);
            app.UPVRobotPostProcessProcessButton = uibutton(app.UPVRobotPostProcessProcessDataGrid, 'push');
            app.UPVRobotPostProcessProcessButton.Text = 'Process';
            app.UPVRobotPostProcessRunInfo = uilabel(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessRunInfo.Text = 'Processing...';
            app.UPVRobotPostProcessEstimatedTimeLabel = uilabel(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessEstimatedTimeLabel.Text = 'Estimated Time';
            app.UPVRobotPostProcessEstimatedTimeInfo = uilabel(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessEstimatedTimeInfo.Text = '01:01:01';
            app.UPVRobotPostProcessRemainingTimeLabel = uilabel(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessRemainingTimeLabel.Text = 'Remaining Time';
            app.UPVRobotPostProcessRemainingTimeInfo = uilabel(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessRemainingTimeInfo.Text = '01:01:01';
            app.UPVRobotPostProcessUsemullticoreparallelCheckBox = uicheckbox(app.UPVRobotPostProcessProcessDataGrid);
            app.UPVRobotPostProcessUsemullticoreparallelCheckBox.Text = 'Use mullticore / parallel';
            app.UPVRobotPostProcessFolderPanel = uipanel(app.GridUPVRobotPostProcessLeftPanel);
            app.UPVRobotPostProcessFolderPanel.Title = 'Project folder';
            app.UPVRobotPostProcessFolderGrid = uigridlayout(app.UPVRobotPostProcessFolderPanel);
            app.UPVRobotPostProcessFolderButton = uibutton(app.UPVRobotPostProcessFolderGrid, 'push');
            app.UPVRobotPostProcessFolderButton.Text = 'Select folder';
            app.UPVRobotPostProcessFolderLabel = uilabel(app.UPVRobotPostProcessFolderGrid);
            app.UPVRobotPostProcessFolderLabel.Text = 'Please select a folder';
            app.UPVRobotPostProcessShowResultsPanel = uipanel(app.GridUPVRobotPostProcessLeftPanel);
            app.UPVRobotPostProcessShowResultsPanel.Title = 'Show results for:';
            app.UPVRobotPostProcessShowResultsGrid = uigridlayout(app.UPVRobotPostProcessShowResultsPanel);
            app.UPVRobotPostProcessFrequencydomainButton = uibutton(app.UPVRobotPostProcessShowResultsGrid, 'push');
            app.UPVRobotPostProcessFrequencydomainButton.Text = 'Frequency domain';
            app.UPVRobotPostProcessTimedomainButton = uibutton(app.UPVRobotPostProcessShowResultsGrid, 'push');
            app.UPVRobotPostProcessTimedomainButton.Text = 'Time domain';
            app.UPVRobotPostProcessAScanBScanButton = uibutton(app.UPVRobotPostProcessShowResultsGrid, 'push');
            app.UPVRobotPostProcessAScanBScanButton.Text = 'A-Scan / B-Scan';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridCenterMain = uigridlayout(app.CenterPanel);
            app.GridUPVRobotPostProcessBottomCenterGridFreq3D = uigridlayout(app.GridCenterMain);
            app.UPVRobotPostProcessViewParametersPanelFreq3D = uipanel(app.GridUPVRobotPostProcessBottomCenterGridFreq3D);
            app.UPVRobotPostProcessViewParametersPanelFreq3D.Title = 'View parameters';
            app.UPVRobotPostProcessViewParametersGridFreq3D = uigridlayout(app.UPVRobotPostProcessViewParametersPanelFreq3D);
            app.FrequencyHzEditFieldLabelFreq3D = uilabel(app.UPVRobotPostProcessViewParametersGridFreq3D);
            app.FrequencyHzEditFieldLabelFreq3D.Text = 'Frequency (Hz)';
            app.UPVRobotPostProcessFrequencyHzEditFieldFreq3D = uieditfield(app.UPVRobotPostProcessViewParametersGridFreq3D, 'numeric');
            app.UPVRobotPostProcessFrequencyHzEditFieldFreq3D.Limits = [1 Inf];
            app.UPVRobotPostProcessFrequencyHzEditFieldFreq3D.ValueDisplayFormat = '%g';
            app.UPVRobotPostProcessFrequencyHzEditFieldFreq3D.Value = 500;
            app.UPVRobotPostProcessColorrangeautomaticCheckBoxFreq3D = uicheckbox(app.UPVRobotPostProcessViewParametersGridFreq3D);
            app.UPVRobotPostProcessColorrangeautomaticCheckBoxFreq3D.Text = 'Color range automatic';
            app.UPVRobotPostProcessColorrangeautomaticCheckBoxFreq3D.Value = true;
            app.MinrangeEditFieldLabelFreq3D = uilabel(app.UPVRobotPostProcessViewParametersGridFreq3D);
            app.MinrangeEditFieldLabelFreq3D.Text = 'Min range';
            app.UPVRobotPostProcessMinrangeEditFieldFreq3D = uieditfield(app.UPVRobotPostProcessViewParametersGridFreq3D, 'numeric');
            app.UPVRobotPostProcessMinrangeEditFieldFreq3D.Value = 70;
            app.MaxrangeEditFieldLabelFreq3D = uilabel(app.UPVRobotPostProcessViewParametersGridFreq3D);
            app.MaxrangeEditFieldLabelFreq3D.Text = 'Max range';
            app.UPVRobotPostProcessMaxrangeEditFieldFreq3D = uieditfield(app.UPVRobotPostProcessViewParametersGridFreq3D, 'numeric');
            app.UPVRobotPostProcessMaxrangeEditFieldFreq3D.Value = 100;
            app.UPVRobotPostProcessExportPanelFreq3D = uipanel(app.GridUPVRobotPostProcessBottomCenterGridFreq3D);
            app.UPVRobotPostProcessExportPanelFreq3D.Title = 'Export';
            app.UPVRobotPostProcessExportGridFreq3D = uigridlayout(app.UPVRobotPostProcessExportPanelFreq3D);
            app.UPVRobotPostProcessExportGraphicButtonFreq3D = uibutton(app.UPVRobotPostProcessExportGridFreq3D, 'push');
            app.UPVRobotPostProcessExportGraphicButtonFreq3D.Text = 'Graphic';
            app.UPVRobotPostProcessExportDataButtonFreq3D = uibutton(app.UPVRobotPostProcessExportGridFreq3D, 'push');
            app.UPVRobotPostProcessExportDataButtonFreq3D.Text = 'Data';
            app.UPVRobotPostProcessDParametersPanelFreq3D = uipanel(app.GridUPVRobotPostProcessBottomCenterGridFreq3D);
            app.UPVRobotPostProcessDParametersPanelFreq3D.Title = '3D Parameters';
            app.UPVRobotPostProcessDParametersPanelFreq3DGrid = uigridlayout(app.UPVRobotPostProcessDParametersPanelFreq3D);
            app.UPVRobotPostProcessDParametersPanelFreq3DTabs = uitabgroup(app.UPVRobotPostProcessDParametersPanelFreq3DGrid);
            app.UPVRobotPostProcessDParametersPanelFreq3D3SliceTab = uitab(app.UPVRobotPostProcessDParametersPanelFreq3DTabs);
            app.UPVRobotPostProcessDParametersPanelFreq3D3SliceTab.Title = '3 Slice';
            app.UPVRobotPostProcessFreq3D3SliceGrid = uigridlayout(app.UPVRobotPostProcessDParametersPanelFreq3D3SliceTab);
            app.UPVRobotPostProcessFreq3D3SliceXYSliceSliderLabel = uilabel(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SliceXYSliceSliderLabel.Text = 'XY Slice';
            app.UPVRobotPostProcessFreq3D3SliceXYSliceSlider = uislider(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SliceXYSliceSlider.Limits = [0 1];
            app.UPVRobotPostProcessFreq3D3SliceXYSliceSlider.MajorTicks = [];
            app.UPVRobotPostProcessFreq3D3SliceXYSliceSlider.MajorTickLabels = {};
            app.UPVRobotPostProcessFreq3D3SliceXYSliceSlider.MinorTicks = [];
            app.UPVRobotPostProcessFreq3D3SliceXZSliceSliderLabel = uilabel(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SliceXZSliceSliderLabel.Text = 'XZ Slice';
            app.UPVRobotPostProcessFreq3D3SliceXZSliceSlider = uislider(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SliceXZSliceSlider.Limits = [0 1];
            app.UPVRobotPostProcessFreq3D3SliceXZSliceSlider.MajorTicks = [];
            app.UPVRobotPostProcessFreq3D3SliceXZSliceSlider.MajorTickLabels = {};
            app.UPVRobotPostProcessFreq3D3SliceXZSliceSlider.MinorTicks = [];
            app.UPVRobotPostProcessFreq3D3SliceYZSliceSliderLabel = uilabel(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SliceYZSliceSliderLabel.Text = 'YZ Slice';
            app.UPVRobotPostProcessFreq3D3SliceYZSliceSlider = uislider(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SliceYZSliceSlider.Limits = [0 1];
            app.UPVRobotPostProcessFreq3D3SliceYZSliceSlider.MajorTicks = [];
            app.UPVRobotPostProcessFreq3D3SliceYZSliceSlider.MajorTickLabels = {};
            app.UPVRobotPostProcessFreq3D3SliceYZSliceSlider.MinorTicks = [];
            app.UPVRobotPostProcessFreq3D3SliceViewLabel = uilabel(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SliceViewLabel.Text = 'View:';
            app.UPVRobotPostProcessFreq3D3SliceXYSliceSliderZInfo = uilabel(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SliceXYSliceSliderZInfo.Text = 'Z = 0.5';
            app.UPVRobotPostProcessFreq3D3SliceXZSliceSliderYInfo = uilabel(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SliceXZSliceSliderYInfo.Text = 'Z = 0.5';
            app.UPVRobotPostProcessFreq3D3SliceYZSliceSliderXInfo = uilabel(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SliceYZSliceSliderXInfo.Text = 'Z = 0.5';
            app.UPVRobotPostProcessFreq3D3SliceViewOpsGrid = uigridlayout(app.UPVRobotPostProcessFreq3D3SliceGrid);
            app.UPVRobotPostProcessFreq3D3SlicePointsCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3D3SliceViewOpsGrid);
            app.UPVRobotPostProcessFreq3D3SlicePointsCheckBox.Text = 'Points';
            app.UPVRobotPostProcessFreq3D3SliceBoundaryCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3D3SliceViewOpsGrid);
            app.UPVRobotPostProcessFreq3D3SliceBoundaryCheckBox.Text = 'Bound';
            app.UPVRobotPostProcessFreq3D3SliceLightCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3D3SliceViewOpsGrid);
            app.UPVRobotPostProcessFreq3D3SliceLightCheckBox.Text = 'Light';
            app.UPVRobotPostProcessDParametersPanelFreq3DMultiTab = uitab(app.UPVRobotPostProcessDParametersPanelFreq3DTabs);
            app.UPVRobotPostProcessDParametersPanelFreq3DMultiTab.Title = 'Multi';
            app.UPVRobotPostProcessFreq3DMultiGrid = uigridlayout(app.UPVRobotPostProcessDParametersPanelFreq3DMultiTab);
            app.UPVRobotPostProcessFreq3DMultiXYSlicesEditFieldLabel = uilabel(app.UPVRobotPostProcessFreq3DMultiGrid);
            app.UPVRobotPostProcessFreq3DMultiXYSlicesEditFieldLabel.Text = 'XY Slices';
            app.UPVRobotPostProcessFreq3DMultiXYSlicesEditField = uieditfield(app.UPVRobotPostProcessFreq3DMultiGrid, 'text');
            app.UPVRobotPostProcessFreq3DMultiXYSlicesEditField.Tooltip = {'Unique number or number array in format ''[a,b,c,...]'' or empty to not make a slice'};
            app.UPVRobotPostProcessFreq3DMultiXYSlicesEditField.Value = '[0,0.2,0.7]';
            app.UPVRobotPostProcessFreq3DMultiXZSlicesEditFieldLabel = uilabel(app.UPVRobotPostProcessFreq3DMultiGrid);
            app.UPVRobotPostProcessFreq3DMultiXZSlicesEditFieldLabel.Text = 'XZ Slices';
            app.UPVRobotPostProcessFreq3DMultiXZSlicesEditField = uieditfield(app.UPVRobotPostProcessFreq3DMultiGrid, 'text');
            app.UPVRobotPostProcessFreq3DMultiXZSlicesEditField.Tooltip = {'Unique number or number array in format ''[a,b,c,...]'' or empty to not make a slice'};
            app.UPVRobotPostProcessFreq3DMultiXZSlicesEditField.Value = '[1,3]';
            app.UPVRobotPostProcessFreq3DMultiYZSlicesEditFieldLabel = uilabel(app.UPVRobotPostProcessFreq3DMultiGrid);
            app.UPVRobotPostProcessFreq3DMultiYZSlicesEditFieldLabel.Text = 'YZ Slices';
            app.UPVRobotPostProcessFreq3DMultiYZSlicesEditField = uieditfield(app.UPVRobotPostProcessFreq3DMultiGrid, 'text');
            app.UPVRobotPostProcessFreq3DMultiYZSlicesEditField.Tooltip = {'Unique number or number array in format ''[a,b,c,...]'' or empty to not make a slice'};
            app.UPVRobotPostProcessFreq3DMultiYZSlicesEditField.Value = '[0.5,1,2]';
            app.UPVRobotPostProcessFreq3DMultiViewLabel = uilabel(app.UPVRobotPostProcessFreq3DMultiGrid);
            app.UPVRobotPostProcessFreq3DMultiViewLabel.Text = 'View:';
            app.UPVRobotPostProcessFreq3DMultiViewOpsGrid = uigridlayout(app.UPVRobotPostProcessFreq3DMultiGrid);
            app.UPVRobotPostProcessFreq3DMultiPointsCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3DMultiViewOpsGrid);
            app.UPVRobotPostProcessFreq3DMultiPointsCheckBox.Text = 'Points';
            app.UPVRobotPostProcessFreq3DMultiBoundaryCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3DMultiViewOpsGrid);
            app.UPVRobotPostProcessFreq3DMultiBoundaryCheckBox.Text = 'Bound';
            app.UPVRobotPostProcessFreq3DMultiLightCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3DMultiViewOpsGrid);
            app.UPVRobotPostProcessFreq3DMultiLightCheckBox.Text = 'Light';
            app.UPVRobotPostProcessDParametersPanelFreq3DIsoTab = uitab(app.UPVRobotPostProcessDParametersPanelFreq3DTabs);
            app.UPVRobotPostProcessDParametersPanelFreq3DIsoTab.Title = 'Iso';
            app.UPVRobotPostProcessFreq3DIsoGrid = uigridlayout(app.UPVRobotPostProcessDParametersPanelFreq3DIsoTab);
            app.UPVRobotPostProcessFreq3DIsoSPLdBEditFieldLabel = uilabel(app.UPVRobotPostProcessFreq3DIsoGrid);
            app.UPVRobotPostProcessFreq3DIsoSPLdBEditFieldLabel.Text = 'SPL (dB)';
            app.UPVRobotPostProcessFreq3DIsoSPLdBEditField = uieditfield(app.UPVRobotPostProcessFreq3DIsoGrid, 'numeric');
            app.UPVRobotPostProcessFreq3DIsoSPLdBEditField.Limits = [0 Inf];
            app.UPVRobotPostProcessFreq3DIsoSPLdBEditField.ValueDisplayFormat = '%.1f';
            app.UPVRobotPostProcessFreq3DIsoSPLdBEditField.Value = 70;
            app.UPVRobotPostProcessFreq3DIsolowerCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3DIsoGrid);
            app.UPVRobotPostProcessFreq3DIsolowerCheckBox.Text = 'Lower';
            app.UPVRobotPostProcessFreq3DIsoFillLabel = uilabel(app.UPVRobotPostProcessFreq3DIsoGrid);
            app.UPVRobotPostProcessFreq3DIsoFillLabel.Text = 'Fill:';
            app.UPVRobotPostProcessFreq3DIsoViewLabel = uilabel(app.UPVRobotPostProcessFreq3DIsoGrid);
            app.UPVRobotPostProcessFreq3DIsoViewLabel.Text = 'View:';
            app.UPVRobotPostProcessFreq3DIsoPointsCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3DIsoGrid);
            app.UPVRobotPostProcessFreq3DIsoPointsCheckBox.Text = 'Points';
            app.UPVRobotPostProcessFreq3DIsoupperCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3DIsoGrid);
            app.UPVRobotPostProcessFreq3DIsoupperCheckBox.Text = 'Upper';
            app.UPVRobotPostProcessFreq3DIsoSurfaceLabel = uilabel(app.UPVRobotPostProcessFreq3DIsoGrid);
            app.UPVRobotPostProcessFreq3DIsoSurfaceLabel.Text = 'Surface:';
            app.UPVRobotPostProcessFreq3DIsoSmoothCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3DIsoGrid);
            app.UPVRobotPostProcessFreq3DIsoSmoothCheckBox.Text = 'Smooth';
            app.UPVRobotPostProcessFreq3DIsoLightCheckBox = uicheckbox(app.UPVRobotPostProcessFreq3DIsoGrid);
            app.UPVRobotPostProcessFreq3DIsoLightCheckBox.Text = 'Light';
            app.CenterMainPanelUPVRobotPostProcess = uipanel(app.GridCenterMain);
            app.CenterMainPanelUPVRobotPostProcess.Title = 'Frequency Domain';
            app.CenterMainGridUPVRobotPostProcess = uigridlayout(app.CenterMainPanelUPVRobotPostProcess);
            app.UPVRobotPostProcessFreqTabGroup = uitabgroup(app.CenterMainGridUPVRobotPostProcess);
            app.UPVRobotPostProcessFreqTab1D = uitab(app.UPVRobotPostProcessFreqTabGroup);
            app.UPVRobotPostProcessFreqTab1D.Title = '1D';
            app.UPVRobotPostProcessFreqTab2D = uitab(app.UPVRobotPostProcessFreqTabGroup);
            app.UPVRobotPostProcessFreqTab2D.Title = '2D';
            app.UPVRobotPostProcessFreqTab3D = uitab(app.UPVRobotPostProcessFreqTabGroup);
            app.UPVRobotPostProcessFreqTab3D.Title = '3D';
            app.GridCenterMainPanelUPVRobotPostProcessFreq3D = uigridlayout(app.UPVRobotPostProcessFreqTab3D);
            app.UPVRobotPostProcessUIAxesFreq3D = uiaxes(app.GridCenterMainPanelUPVRobotPostProcessFreq3D);
            app.UPVRobotPostProcessButtonAxesXYFreq3D = uibutton(app.GridCenterMainPanelUPVRobotPostProcessFreq3D, 'push');
            app.UPVRobotPostProcessButtonAxesXYFreq3D.Text = 'XY';
            app.UPVRobotPostProcessButtonAxesXZFreq3D = uibutton(app.GridCenterMainPanelUPVRobotPostProcessFreq3D, 'push');
            app.UPVRobotPostProcessButtonAxesXZFreq3D.Text = 'XZ';
            app.UPVRobotPostProcessButtonAxesYZFreq3D = uibutton(app.GridCenterMainPanelUPVRobotPostProcessFreq3D, 'push');
            app.UPVRobotPostProcessButtonAxesYZFreq3D.Text = 'YZ';
            app.UPVRobotPostProcessButtonAxes3DFreq3D = uibutton(app.GridCenterMainPanelUPVRobotPostProcessFreq3D, 'push');
            app.UPVRobotPostProcessButtonAxes3DFreq3D.Text = '3D';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridRightMain = uigridlayout(app.RightPanel);
            app.UPVRobotPostProcessMeasureinformationPanel = uipanel(app.GridRightMain);
            app.UPVRobotPostProcessMeasureinformationPanel.Title = 'Measure information';
            app.UPVRobotPostProcessMeasureinformationGrid = uigridlayout(app.UPVRobotPostProcessMeasureinformationPanel);
            app.UPVRobotPostProcessMeasureinformationHardwarePanel = uipanel(app.UPVRobotPostProcessMeasureinformationGrid);
            app.UPVRobotPostProcessMeasureinformationHardwarePanel.Title = 'Hardware';
            app.UPVRobotPostProcessMeasureinformationHardwareGrid = uigridlayout(app.UPVRobotPostProcessMeasureinformationHardwarePanel);
            app.UPVRobotPostProcessMeasureDeviceLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureDeviceLabel.Text = 'Device';
            app.UPVRobotPostProcessMeasureSampleRateLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSampleRateLabel.Text = 'Sample rate';
            app.UPVRobotPostProcessMeasureSamplesPerFrameLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSamplesPerFrameLabel.Text = 'Samples per frame';
            app.UPVRobotPostProcessMeasureInputChLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureInputChLabel.Text = 'Input Channel';
            app.UPVRobotPostProcessMeasureOutputChLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureOutputChLabel.Text = 'Output Channel';
            app.UPVRobotPostProcessMeasureDeviceInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureDeviceInfo.Text = 'Default';
            app.UPVRobotPostProcessMeasureSampleRateInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSampleRateInfo.Text = '48000 Hz';
            app.UPVRobotPostProcessMeasureSamplesPerFrameInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSamplesPerFrameInfo.Text = '1024';
            app.UPVRobotPostProcessMeasureInputChInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureInputChInfo.Text = '1';
            app.UPVRobotPostProcessMeasureOutputChInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureOutputChInfo.Text = '1';
            app.UPVRobotPostProcessMeasureBitDepthLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureBitDepthLabel.Text = 'Bit Depth';
            app.UPVRobotPostProcessMeasureBitdepthInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureBitdepthInfo.Text = '24-bit integer';
            app.UPVRobotPostProcessMeasureLatencyLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureLatencyLabel.Text = 'Latency';
            app.UPVRobotPostProcessMeasureLatencyInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureLatencyInfo.Text = '30.34 ms';
            app.UPVRobotPostProcessMeasureSensivityLabel = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSensivityLabel.Text = 'Sensivity';
            app.UPVRobotPostProcessMeasureSensivityInfo = uilabel(app.UPVRobotPostProcessMeasureinformationHardwareGrid);
            app.UPVRobotPostProcessMeasureSensivityInfo.Text = 'Not calibrated';
            app.UPVRobotPostProcessMeasureinformationSignalPanel = uipanel(app.UPVRobotPostProcessMeasureinformationGrid);
            app.UPVRobotPostProcessMeasureinformationSignalPanel.Title = 'Signal';
            app.UPVRobotPostProcessMeasureinformationSignalGrid = uigridlayout(app.UPVRobotPostProcessMeasureinformationSignalPanel);
            app.UPVRobotPostProcessMeasureTypeSignalLabel = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureTypeSignalLabel.Text = 'Type';
            app.UPVRobotPostProcessMeasureDurationLabel = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureDurationLabel.Text = 'Total duration';
            app.UPVRobotPostProcessMeasureAveragesLabel = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureAveragesLabel.Text = 'Averages';
            app.UPVRobotPostProcessMeasureTypeSignalInfo = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureTypeSignalInfo.Text = 'Sweep';
            app.UPVRobotPostProcessMeasureDurationInfo = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureDurationInfo.Text = '1.5 s';
            app.UPVRobotPostProcessMeasureAveragesInfo = uilabel(app.UPVRobotPostProcessMeasureinformationSignalGrid);
            app.UPVRobotPostProcessMeasureAveragesInfo.Text = '1';
            app.UPVRobotPostProcessMeasureinformationMeasuresPanel = uipanel(app.UPVRobotPostProcessMeasureinformationGrid);
            app.UPVRobotPostProcessMeasureinformationMeasuresPanel.Title = 'Measures';
            app.UPVRobotPostProcessMeasureinformationMeasuresGrid = uigridlayout(app.UPVRobotPostProcessMeasureinformationMeasuresPanel);
            app.UPVRobotPostProcessMeasureTotalLabel = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTotalLabel.Text = 'Total';
            app.UPVRobotPostProcessMeasureTypeCoordsLabel = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTypeCoordsLabel.Text = 'Type coords';
            app.UPVRobotPostProcessMeasureTimeLabel = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTimeLabel.Text = 'Measure time';
            app.UPVRobotPostProcessMeasureTotalInfo = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTotalInfo.Text = '2500';
            app.UPVRobotPostProcessMeasureTypeCoordsInfo = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTypeCoordsInfo.Text = 'Automatic';
            app.UPVRobotPostProcessMeasureTimeInfo = uilabel(app.UPVRobotPostProcessMeasureinformationMeasuresGrid);
            app.UPVRobotPostProcessMeasureTimeInfo.Text = '02:05:45';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.LoadprojectMenu = uimenu(app.FileMenu);
            app.LoadprojectMenu.Text = 'Load project...';
            app.SaveprojectMenu = uimenu(app.FileMenu);
            app.SaveprojectMenu.Text = 'Save project...';
            app.MeasurementMenu = uimenu(app.UIFigure);
            app.MeasurementMenu.Text = 'Measurement';
            app.IndividualchannelsgeneralMenu = uimenu(app.MeasurementMenu);
            app.IndividualchannelsgeneralMenu.Text = 'Individual channels (general)';
            app.ImpedancetubeMenu = uimenu(app.MeasurementMenu);
            app.ImpedancetubeMenu.Text = 'Impedance tube';
            app.TwomicrophoneISO105342Menu = uimenu(app.ImpedancetubeMenu);
            app.TwomicrophoneISO105342Menu.Text = 'Two-microphone (ISO 10534-2)';
            app.OnemicrophoneISO105342Menu = uimenu(app.ImpedancetubeMenu);
            app.OnemicrophoneISO105342Menu.Text = 'One-microphone (ISO 10534-2)';
            app.FixedlocationsMenu = uimenu(app.OnemicrophoneISO105342Menu);
            app.FixedlocationsMenu.Text = 'Fixed locations';
            app.VariablelocationsMenu = uimenu(app.OnemicrophoneISO105342Menu);
            app.VariablelocationsMenu.Text = 'Variable locations';
            app.FourmicrophoneASTME26119Menu = uimenu(app.ImpedancetubeMenu);
            app.FourmicrophoneASTME26119Menu.Text = 'Four-microphone (ASTM E2611-9)';
            app.AnechoicchamberMenu = uimenu(app.MeasurementMenu);
            app.AnechoicchamberMenu.Text = 'Anechoic chamber';
            app.ReflectionabsorptionandimpedanceISO105342modifiedMenu = uimenu(app.AnechoicchamberMenu);
            app.ReflectionabsorptionandimpedanceISO105342modifiedMenu.Text = 'Reflection, absorption and impedance (ISO 10534-2 modified)';
            app.DeviceMenu = uimenu(app.UIFigure);
            app.DeviceMenu.Text = 'Device';
            app.HardwareinputoutputMenu = uimenu(app.DeviceMenu);
            app.HardwareinputoutputMenu.MenuSelectedFcn = createCallbackFcn(app, @HardwareinputoutputMenuSelected, true);
            app.HardwareinputoutputMenu.Text = 'Hardware (input/output)';
            app.CalibrateinputsMenu = uimenu(app.DeviceMenu);
            app.CalibrateinputsMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrateinputsMenuSelected, true);
            app.CalibrateinputsMenu.Text = 'Calibrate inputs';
            app.CalibratedevicelatencyMenu = uimenu(app.DeviceMenu);
            app.CalibratedevicelatencyMenu.Text = 'Calibrate device latency';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UPVRobot_PostProcessFreq3D_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end